classdef kICSConfinementGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FitMobilitesandFractionsToObtainConfinementParametersPanel  matlab.ui.container.Panel
        LoadAnalysisParametersFileButton  matlab.ui.control.Button
        SaveAnalysisParametersFileButton  matlab.ui.control.Button
        BatchSeveralFilesUsingSameSettingsButton  matlab.ui.control.Button
        FitButton_2                     matlab.ui.control.Button
        ApplyAnalysisToEachCellAndExportResultsButton  matlab.ui.control.Button
        ChoosetrendfittingoptionDropDown  matlab.ui.control.DropDown
        ChoosetrendfittingoptionDropDownLabel  matlab.ui.control.Label
        maxtaufitSlider                 matlab.ui.control.Slider
        maxtaufitSliderLabel            matlab.ui.control.Label
        mintaufitSlider                 matlab.ui.control.Slider
        mintaufitSliderLabel            matlab.ui.control.Label
        ChoosewhichtrendtoshowDropDown  matlab.ui.control.DropDown
        ChoosewhichtrendtoshowLabel     matlab.ui.control.Label
        kICSCFfittingToExtractTimeDependentMobilitiesandFractionsPanel  matlab.ui.container.Panel
        PreviewFitEveryNthTauEditField  matlab.ui.control.NumericEditField
        PreviewFitEveryNthTauEditFieldLabel  matlab.ui.control.Label
        FitAllTauButton                 matlab.ui.control.Button
        FitPreviewButton                matlab.ui.control.Button
        InflectionPointk2fit0ifsingleComponentFitEditField  matlab.ui.control.NumericEditField
        InflectionPointk2fit0ifsingleComponentFitEditFieldLabel  matlab.ui.control.Label
        Maxk2FitSlider                  matlab.ui.control.Slider
        Maxk2FitSliderLabel             matlab.ui.control.Label
        Mink2FitSlider                  matlab.ui.control.Slider
        Mink2FitSliderLabel             matlab.ui.control.Label
        ChooseCFFittingOptionsDropDown  matlab.ui.control.DropDown
        ChooseCFFittingOptionsDropDownLabel  matlab.ui.control.Label
        ApplykICSAndExploreCorrelationFunctionPanel  matlab.ui.container.Panel
        MovingAverageSmoothingWindowEditField  matlab.ui.control.NumericEditField
        MovingAverageSmoothingWindowEditFieldLabel  matlab.ui.control.Label
        SmoothCFAlongk2Button           matlab.ui.control.StateButton
        PlotCFButton                    matlab.ui.control.Button
        maxGktauSlider                  matlab.ui.control.Slider
        maxGktauSliderLabel             matlab.ui.control.Label
        minGktauSlider                  matlab.ui.control.Slider
        minGktauSliderLabel             matlab.ui.control.Label
        maxk2Slider                     matlab.ui.control.Slider
        maxk2SliderLabel                matlab.ui.control.Label
        mink2Slider                     matlab.ui.control.Slider
        mink2SliderLabel                matlab.ui.control.Label
        maxtauSlider                    matlab.ui.control.Slider
        maxtauSliderLabel               matlab.ui.control.Label
        mintauSlider                    matlab.ui.control.Slider
        mintauSliderLabel               matlab.ui.control.Label
        ChooseCFrepresentationDropDown  matlab.ui.control.DropDown
        ChooseCFrepresentationDropDownLabel  matlab.ui.control.Label
        CalculatekICSCFButton           matlab.ui.control.Button
        DataFlteringAndDefineCellAreasPanel  matlab.ui.container.Panel
        AnalyseCellEditField            matlab.ui.control.NumericEditField
        AnalyseCellEditFieldLabel       matlab.ui.control.Label
        LBCellAreaSlider                matlab.ui.control.Slider
        LBCellAreaSliderLabel           matlab.ui.control.Label
        HannPowerEditField              matlab.ui.control.NumericEditField
        HannPowerEditFieldLabel         matlab.ui.control.Label
        SpatialHannWindowingButton_2    matlab.ui.control.StateButton
        TemporalImmobileFilteringButton_2  matlab.ui.control.StateButton
        ThresholdCellShapeSlider        matlab.ui.control.Slider
        ThresholdCellShapeSliderLabel   matlab.ui.control.Label
        ExtractCellContourandMeanPadIntensitiesButton  matlab.ui.control.Button
        MorphologicalOperationsButton   matlab.ui.control.Button
        MorphologicalOperationsonBinaryDropDown  matlab.ui.control.DropDown
        FramesSlider                    matlab.ui.control.Slider
        LoadDataAndDefineSpecsPanel     matlab.ui.container.Panel
        MaxTauFractionEditField         matlab.ui.control.NumericEditField
        MaxTauFractionEditFieldLabel    matlab.ui.control.Label
        maxk2EditField                  matlab.ui.control.NumericEditField
        maxk2EditFieldLabel             matlab.ui.control.Label
        TemporallyAlignSeriestoTimePoint0Button_2  matlab.ui.control.StateButton
        DisplayDataDropDown             matlab.ui.control.DropDown
        DisplayDataDropDownLabel        matlab.ui.control.Label
        LoadMultitiforOMEdatafileButton  matlab.ui.control.Button
        UBYSlider                       matlab.ui.control.Slider
        UBYSliderLabel                  matlab.ui.control.Label
        LBYSlider                       matlab.ui.control.Slider
        LBYSliderLabel                  matlab.ui.control.Label
        UBXSlider                       matlab.ui.control.Slider
        UBXSliderLabel                  matlab.ui.control.Label
        LBXSlider                       matlab.ui.control.Slider
        LBXSliderLabel                  matlab.ui.control.Label
        PixelSizemicrometerEditField    matlab.ui.control.NumericEditField
        PixelSizemicrometerEditFieldLabel  matlab.ui.control.Label
        FrametimesecondsEditField       matlab.ui.control.NumericEditField
        FrametimesecondsEditFieldLabel  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path
        file
        images
        images2
        TempAlign
        pixelSize
        timeFrame
        TotImages
        specs
        showNow
        sliderFrame
        croped
        LBX
        UBX
        LBY
        UBY
        padded
        timefilt
        hanned
        HannPow
        sliderShape
        binaryShape
        aveim
        MorphChoice
        binary2
        CellMask
        timecorrnorm
        X
        Y
        stepkx
        stepky
        CFrepresent
        mintau
        maxtau
        mink2
        maxk2
        minG
        maxG
        mintaufit
        maxtaufit
        mintaufit1
        maxtaufit1
        mintaufit2
        maxtaufit2
        mintaufit3
        maxtaufit3
        mintaufit4
        maxtaufit4
        mintaufit5
        maxtaufit5
        mintaufit6
        maxtaufit6
        mink2fit
        maxk2fit
        inflectk2
        fractau
        k2Cutoff
        toPad
        toImmFilt
        toHann
        FitCFOption
        MovWindk2Status
        MovWindk2
        CFsmooth
        FitNthTau
        FitTrendOption
        LinearTrendDisplayOption
        kBin
        CF
        kValues
        DtauF
        AmpF
        DtauS
        AmpS
        RatAmp
        Dmacro
        Deff
        MaxDispMacro
        Dmicro
        DmicroLate
        MaxDispMicro
        k2
        ratConc
        alphaAnomalous
        Danomalous
        LBcellArea
        LabelCell
        cellID
        croped2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadMultitiforOMEdatafileButton
        function LoadMultitiforOMEdatafileButtonPushed(app, event)
            [app.file,app.path,indx] = uigetfile('*.tif;*.czi;*.nd2;*lif;*.lsm','Please select a file to import data');
            extension=app.file(end-2:end);
            if strcmp(extension,'tif')
                app.images=rd_img16([app.path app.file]);
                app.pixelSize=1;
                app.timeFrame=1;
            elseif strcmp(extension,'czi') || strcmp(extension,'nd2') || strcmp(extension,'lsm') || strcmp(extension,'lif')
                [app.images,specs]=readOMEFile_forGUI(app.UIAxes2,app.path,app.file);
                app.pixelSize=specs.pixelSize;
                app.timeFrame=specs.timeFrame;
            end
            app.FrametimesecondsEditField.Value=app.timeFrame;
            app.PixelSizemicrometerEditField.Value=app.pixelSize;;
            app.TotImages=size(app.images,3);
            app.fractau=0.2;
            app.k2Cutoff=500;
            app.sliderFrame=2;
            stepkx = 1/(size(app.images,2)*app.pixelSize)*(2*pi);
            stepky = 1/(size(app.images,1)*app.pixelSize)*(2*pi);
            stepk=min(stepkx,stepky);
            app.FramesSlider.Limits = [1 app.TotImages];
            app.FramesSlider.MajorTicks = [1:round(app.TotImages/10):app.TotImages];
            app.LBXSlider.Limits = [1 size(app.images,2)];
            app.LBXSlider.MajorTicks = [1:ceil(size(app.images,2)/10):size(app.images,2)];
            app.LBXSlider.Value=1;
            app.UBXSlider.Limits = [1 size(app.images,2)];
            app.UBXSlider.MajorTicks = [1:ceil(size(app.images,2)/10):size(app.images,2)];
            app.UBXSlider.Value=size(app.images,2);
            app.LBYSlider.Limits = [1 size(app.images,1)];
            app.LBYSlider.MajorTicks = [1:ceil(size(app.images,1)/10):size(app.images,1)];
            app.LBYSlider.Value=1;
            app.UBYSlider.Limits = [1 size(app.images,1)];
            app.UBYSlider.MajorTicks = [1:ceil(size(app.images,1)/10):size(app.images,1)];
            app.UBYSlider.Value=size(app.images,1);
            app.LBX=1;
            app.UBX=size(app.images,2);
            app.LBY=1;
            app.UBY=size(app.images,1);
            app.showNow=1;
            app.images2=app.images;
            app.croped=app.images;
            app.croped2=app.images;
            app.padded=[];
            app.timefilt=[];
            app.hanned=[];
            app.HannPow=0.5;
            app.sliderShape=0.1;
            app.MorphChoice='clean';
            app.mintauSlider.Limits = [1 round(app.TotImages*app.fractau)-2];
            app.maxtauSlider.Limits = [2 round(app.TotImages*app.fractau)-1];
            app.mink2Slider.Limits = [stepk app.k2Cutoff-1];
            app.maxk2Slider.Limits = [stepk app.k2Cutoff-1];
            app.minGktauSlider.Limits = [0 1];
            app.maxGktauSlider.Limits = [0 1];
            app.mintauSlider.Value = 1;
            app.maxtauSlider.Value =round(app.TotImages*app.fractau)-1;
            app.mink2Slider.Value = stepk;
            app.maxk2Slider.Value = app.k2Cutoff-1;
            app.Mink2FitSlider.Limits = [stepk app.k2Cutoff-1];
            app.Maxk2FitSlider.Limits = [stepk app.k2Cutoff-1];
            app.Mink2FitSlider.Value=stepk;
            app.mink2fit=stepk;
            app.maxk2fit=app.k2Cutoff-1;
            app.Maxk2FitSlider.Value=app.k2Cutoff-1;
            app.mintaufitSlider.Limits = [1 round(app.TotImages*app.fractau)-3];
            app.maxtaufitSlider.Limits = [2 round(app.TotImages*app.fractau)-2];
            app.mintaufitSlider.Value=1;
            app.maxtaufitSlider.Value=round(app.TotImages*app.fractau)-2;
            app.mintaufit=1;
            app.maxtaufit=round(app.TotImages*app.fractau)-1;
            app.mintaufit1=1;
            app.maxtaufit1=round(app.TotImages*app.fractau)-1;
            app.mintaufit2=1;
            app.maxtaufit2=round(app.TotImages*app.fractau)-1;
            app.mintaufit3=1;
            app.maxtaufit3=round(app.TotImages*app.fractau)-1;
            app.mintaufit4=1;
            app.maxtaufit4=round(app.TotImages*app.fractau)-1;
            app.mintaufit5=1;
            app.maxtaufit5=round(app.TotImages*app.fractau)-1;
            app.mintaufit6=1;
            app.maxtaufit6=round(app.TotImages*app.fractau)-1;
            app.InflectionPointk2fit0ifsingleComponentFitEditField.Value=10;
            app.mintau=1;
            app.maxtau=round(app.TotImages*app.fractau)-1;
            app.mink2=stepk;
            app.maxk2=app.k2Cutoff-1;
            app.minG=0;
            app.maxG=1;
            app.toPad=0;
            app.toImmFilt=0;
            app.toHann=0;
            app.TempAlign=0;
            app.CFrepresent='2D representation';
            app.LinearTrendDisplayOption='Ratio Amplitude Macro to Micro Component vs. tau';
            app.FitCFOption='Linear Fit 1 Component';
            app.FitTrendOption,'early linear fit DmacroTau vs tau';
            app.MovWindk2Status=0;
            app.MovWindk2=10;
            app.FitNthTau=20;
            app.cellID=1;
            app.LBcellArea=0.1;
            app.Deff=0;
            app.MaxDispMacro=0;
            app.Dmicro=0;
            app.DmicroLate=0;
            app.MaxDispMicro=0;
            app.k2=0;
            app.ratConc=0;
            app.alphaAnomalous=0;
            app.Danomalous=0;
            app.LabelCell=[];
            cla(app.UIAxes)
            app.UIAxes.YDir='reverse';
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            imagesc(app.UIAxes,app.images(:,:,app.sliderFrame));
            colormap(app.UIAxes,'gray')
            hold(app.UIAxes,"on");
            text(app.UIAxes,size(app.images,2)-20,size(app.images,1)-20,num2str(1),'Color',[1 1 1])
            xlim(app.UIAxes,[1 size(app.images,2)])
            ylim(app.UIAxes,[1 size(app.images,1)])
            set(app.UIAxes,'CLim',[min(app.images(:)) max(app.images(:))])
        end

        % Value changing function: FramesSlider
        function FramesSliderValueChanging(app, event)
            changingValue = event.Value;
            app.sliderFrame=ceil(changingValue);
            if app.showNow==1
                cla(app.UIAxes)
                app.UIAxes.YDir='reverse';
                title(app.UIAxes,'')
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                imagesc(app.UIAxes,app.images(:,:,app.sliderFrame));
                colormap(app.UIAxes,'gray')
                hold(app.UIAxes,"on");
                text(app.UIAxes,size(app.images,2)-20,size(app.images,1)-20,num2str(app.sliderFrame),'Color',[1 1 1])
                xlim(app.UIAxes,[1 size(app.images,2)])
                ylim(app.UIAxes,[1 size(app.images,1)])
                set(app.UIAxes,'CLim',[min(app.images(:)) max(app.images(:))])
                app.FramesSlider.Limits = [1 app.TotImages];
                app.FramesSlider.MajorTicks = [1:round(app.TotImages/10):app.TotImages];
            elseif app.showNow==2
                cla(app.UIAxes)
                app.UIAxes.YDir='reverse';
                title(app.UIAxes,'')
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                imagesc(app.UIAxes,app.images2(:,:,app.sliderFrame));
                colormap(app.UIAxes,'gray')
                hold(app.UIAxes,"on");
                text(app.UIAxes,size(app.images,2)-20,size(app.images,1)-20,num2str(app.sliderFrame),'Color',[1 1 1])
                xlim(app.UIAxes,[1 size(app.images,2)])
                ylim(app.UIAxes,[1 size(app.images,1)])
                set(app.UIAxes,'CLim',[min(app.images2(:)) max(app.images2(:))])
                app.FramesSlider.Limits = [1 app.TotImages];
                app.FramesSlider.MajorTicks = [1:round(app.TotImages/10):app.TotImages];
            elseif app.showNow==3
                cla(app.UIAxes)
                app.UIAxes.YDir='reverse';
                title(app.UIAxes,'')
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                imagesc(app.UIAxes,app.croped(:,:,app.sliderFrame));
                colormap(app.UIAxes,'gray')
                hold(app.UIAxes,"on");
                text(app.UIAxes,size(app.croped,2)-20,size(app.croped,1)-20,num2str(app.sliderFrame),'Color',[1 1 1])
                xlim(app.UIAxes,[1 size(app.croped,2)])
                ylim(app.UIAxes,[1 size(app.croped,1)])
                set(app.UIAxes,'CLim',[min(app.croped(:)) max(app.croped(:))])
                app.FramesSlider.Limits = [1 app.TotImages];
                app.FramesSlider.MajorTicks = [1:round(app.TotImages/10):app.TotImages];
            elseif app.showNow==4
                cla(app.UIAxes)
                title(app.UIAxes,'')
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                normim1=app.aveim/max(app.aveim(:));
                app.binaryShape=normim1>app.sliderShape;
                normim1=uint8(255*normim1);
                B=labeloverlay(normim1,app.binaryShape);
                cla(app.UIAxes)
                imagesc(app.UIAxes,B)
                app.UIAxes.YDir='reverse';
                xlim(app.UIAxes,[1 size(app.binaryShape,2)])
                ylim(app.UIAxes,[1 size(app.binaryShape,1)])
                app.FramesSlider.Limits = [1 app.TotImages];
                app.FramesSlider.MajorTicks = [1:round(app.TotImages/10):app.TotImages];
            elseif app.showNow==5
                cla(app.UIAxes)
                app.UIAxes.YDir='reverse';
                title(app.UIAxes,'')
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                imagesc(app.UIAxes,app.padded(:,:,app.sliderFrame));
                colormap(app.UIAxes,'gray')
                hold(app.UIAxes,"on");
                text(app.UIAxes,size(app.padded,2)-20,size(app.padded,1)-20,num2str(app.sliderFrame),'Color',[1 1 1])
                xlim(app.UIAxes,[1 size(app.padded,2)])
                ylim(app.UIAxes,[1 size(app.padded,1)])
                set(app.UIAxes,'CLim',[min(app.padded(:)) max(app.padded(:))])
                app.FramesSlider.Limits = [1 app.TotImages];
                app.FramesSlider.MajorTicks = [1:round(app.TotImages/10):app.TotImages];
            elseif app.showNow==6
                cla(app.UIAxes)
                app.UIAxes.YDir='reverse';
                title(app.UIAxes,'')
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                imagesc(app.UIAxes,app.timefilt(:,:,app.sliderFrame));
                colormap(app.UIAxes,'gray')
                hold(app.UIAxes,"on");
                text(app.UIAxes,size(app.timefilt,2)-20,size(app.timefilt,1)-20,num2str(app.sliderFrame),'Color',[1 1 1])
                xlim(app.UIAxes,[1 size(app.timefilt,2)])
                ylim(app.UIAxes,[1 size(app.timefilt,1)])
                set(app.UIAxes,'CLim',[min(app.timefilt(:)) max(app.timefilt(:))])
                app.FramesSlider.Limits = [1 app.TotImages];
                app.FramesSlider.MajorTicks = [1:round(app.TotImages/10):app.TotImages];
            elseif app.showNow==7
                cla(app.UIAxes)
                app.UIAxes.YDir='reverse';
                title(app.UIAxes,'')
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                imagesc(app.UIAxes,app.hanned(:,:,app.sliderFrame));
                colormap(app.UIAxes,'gray')
                hold(app.UIAxes,"on");
                text(app.UIAxes,size(app.hanned,2)-20,size(app.hanned,1)-20,num2str(app.sliderFrame),'Color',[1 1 1])
                xlim(app.UIAxes,[1 size(app.hanned,2)])
                ylim(app.UIAxes,[1 size(app.hanned,1)])
                set(app.UIAxes,'CLim',[min(app.hanned(:)) max(app.hanned(:))])
                app.FramesSlider.Limits = [1 app.TotImages];
                app.FramesSlider.MajorTicks = [1:round(app.TotImages/10):app.TotImages];
            elseif app.showNow==8
                cla(app.UIAxes)
                app.UIAxes.YDir='reverse';
                title(app.UIAxes,'kICS Correlation Function')
                xlabel(app.UIAxes,'k_{x} (\mum^{-1})')
                ylabel(app.UIAxes,'k_{y} (\mum^{-1})')
                app.UIAxes.XTick = round([min(app.X(:)):10*app.stepkx:max(app.X(:))],1);
                app.UIAxes.YTick = round([min(app.Y(:)):10*app.stepky:max(app.Y(:))],1);
                app.UIAxes.XTickLabelRotation=90;
                set(app.UIAxes,'FontSize',16)
                imagesc(app.UIAxes,min(app.X(:)):10*app.stepkx:max(app.X(:)),min(app.Y(:)):10*app.stepky:max(app.Y(:)),app.timecorrnorm(:,:,app.sliderFrame));
                colormap(app.UIAxes,'gray')
                hold(app.UIAxes,"on");
                text(app.UIAxes,max(app.X(:))-5,max(app.Y(:))-5,num2str(app.sliderFrame),'Color',[1 1 1])
                xlim(app.UIAxes,[min(app.X(:)) max(app.X(:))])
                ylim(app.UIAxes,[min(app.Y(:)) max(app.Y(:))])
                set(app.UIAxes,'CLim',[0 1])
            end
        end

        % Value changed function: DisplayDataDropDown
        function DisplayDataDropDownValueChanged(app, event)
            value = app.DisplayDataDropDown.Value;
            if strcmp(value,'Raw Data')
                app.showNow=1;
            elseif strcmp(value,'De-drifted Data')
                app.showNow=2;
            elseif strcmp(value,'Croped Data')
                app.showNow=3;
            elseif strcmp(value,'Define Shape')
                app.showNow=4;
            elseif strcmp(value,'Mean Padded')
                app.showNow=5;
            elseif strcmp(value,'Immobile Filtered')
                app.showNow=6;
            elseif strcmp(value,'Hann Windowed')
                app.showNow=7;
            elseif strcmp(value,'kICS CF')
                app.showNow=8;
            end
        end

        % Value changed function: TemporallyAlignSeriestoTimePoint0Button_2
        function TemporallyAlignSeriestoTimePoint0Button_2ValueChanged(app, event)
            value = app.TemporallyAlignSeriestoTimePoint0Button_2.Value;
            app.TempAlign=value;
            if value==1
                app.images2=[];
                scale=0.1;
                [optimizer, metric] = imregconfig('multimodal');
                optimizer.InitialRadius = 0.009;
                optimizer.Epsilon = 1.5e-4;
                optimizer.GrowthFactor = 1.01;
                optimizer.MaximumIterations = 300;;
                ref=app.images(:,:,1);
                app.images2=single(zeros(size(app.images)));
                app.images2(:,:,1)=app.images(:,:,1);
                fixed=imresize(app.images2(:,:,1),scale);
                [fixed,h2]=smoothSeries(fixed,10,'g','p');
                cla(app.UIAxes2)
                ylim(app.UIAxes2,[0,1])
                xlim(app.UIAxes2,[0,1])
                ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                th = text(app.UIAxes2,1,1,'Aligning Images To t0...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                for i=2:size(app.images,3)
                    
                    moving=imresize(app.images(:,:,i),scale);
                    [moving,h2]=smoothSeries(moving,10,'g','p');
                    tform = imregtform(moving, fixed, 'translation', optimizer, metric);
                    tform.T(3,1:2)=tform.T(3,1:2)*(1/scale);
                    movingRegistered = imwarp(app.images(:,:,i),tform,'OutputView',imref2d(size(ref)));
                    app.images2(:,:,i)=movingRegistered;
                    ph.XData = [0 i/size(app.images,3)  i/size(app.images,3) 0];
                    th.String = sprintf('Aligning Images To t0...%.0f%%',round(i/size(app.images,3)*100));
                    drawnow %update graphics
                end
            else
                app.images2=app.images;
            end
            cla(app.UIAxes)
            app.UIAxes.YDir='reverse';
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            imagesc(app.UIAxes,app.images2(:,:,app.sliderFrame));
            colormap(app.UIAxes,'gray')
            hold(app.UIAxes,"on");
            text(app.UIAxes,size(app.images,2)-20,size(app.images,1)-20,num2str(app.sliderFrame),'Color',[1 1 1])
            xlim(app.UIAxes,[1 size(app.images,2)])
            ylim(app.UIAxes,[1 size(app.images,1)])
            set(app.UIAxes,'CLim',[min(app.images2(:)) max(app.images2(:))])
            app.showNow=2;
        end

        % Value changing function: LBXSlider
        function LBXSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LBX=changingValue;
            app.croped=[];
            app.croped=squeeze(app.images2(app.LBY:app.UBY,app.LBX:app.UBX,:));
            cla(app.UIAxes)
            app.UIAxes.YDir='reverse';
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            imagesc(app.UIAxes,app.croped(:,:,app.sliderFrame));
            colormap(app.UIAxes,'gray')
            hold(app.UIAxes,"on");
            text(app.UIAxes,size(app.croped,2)-20,size(app.croped,1)-20,num2str(app.sliderFrame),'Color',[1 1 1])
            xlim(app.UIAxes,[1 size(app.croped,2)])
            ylim(app.UIAxes,[1 size(app.croped,1)])
            app.showNow=3;
        end

        % Value changed function: LBXSlider
        function LBXSliderValueChanged(app, event)
            value = app.LBXSlider.Value;
            app.LBX=value;
        end

        % Value changing function: UBXSlider
        function UBXSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UBX=changingValue;
            app.croped=[];
            app.croped=squeeze(app.images2(app.LBY:app.UBY,app.LBX:app.UBX,:));
            cla(app.UIAxes)
            app.UIAxes.YDir='reverse';
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            imagesc(app.UIAxes,app.croped(:,:,app.sliderFrame));
            colormap(app.UIAxes,'gray')
            hold(app.UIAxes,"on");
            text(app.UIAxes,size(app.croped,2)-20,size(app.croped,1)-20,num2str(app.sliderFrame),'Color',[1 1 1])
            xlim(app.UIAxes,[1 size(app.croped,2)])
            ylim(app.UIAxes,[1 size(app.croped,1)])
            app.showNow=3;
        end

        % Value changed function: UBXSlider
        function UBXSliderValueChanged(app, event)
            value = app.UBXSlider.Value;
            app.UBX=value;
        end

        % Value changing function: LBYSlider
        function LBYSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LBY=changingValue;
            app.croped=[];
            app.croped=squeeze(app.images2(app.LBY:app.UBY,app.LBX:app.UBX,:));
            cla(app.UIAxes)
            app.UIAxes.YDir='reverse';
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            imagesc(app.UIAxes,app.croped(:,:,app.sliderFrame));
            colormap(app.UIAxes,'gray')
            hold(app.UIAxes,"on");
            text(app.UIAxes,size(app.croped,2)-20,size(app.croped,1)-20,num2str(app.sliderFrame),'Color',[1 1 1])
            xlim(app.UIAxes,[1 size(app.croped,2)])
            ylim(app.UIAxes,[1 size(app.croped,1)])
            app.showNow=3;
        end

        % Value changed function: LBYSlider
        function LBYSliderValueChanged(app, event)
            value = app.LBYSlider.Value;
            app.LBY=value;
        end

        % Value changing function: UBYSlider
        function UBYSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UBY=changingValue;
            app.croped=[];
            app.croped=squeeze(app.images2(app.LBY:app.UBY,app.LBX:app.UBX,:));
            cla(app.UIAxes)
            app.UIAxes.YDir='reverse';
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            imagesc(app.UIAxes,app.croped(:,:,app.sliderFrame));
            colormap(app.UIAxes,'gray')
            hold(app.UIAxes,"on");
            text(app.UIAxes,size(app.croped,2)-20,size(app.croped,1)-20,num2str(app.sliderFrame),'Color',[1 1 1])
            xlim(app.UIAxes,[1 size(app.croped,2)])
            ylim(app.UIAxes,[1 size(app.croped,1)])
            app.showNow=3;
        end

        % Value changed function: UBYSlider
        function UBYSliderValueChanged(app, event)
            value = app.UBYSlider.Value;
            app.UBY=value;
        end

        % Value changed function: FramesSlider
        function FramesSliderValueChanged(app, event)
            value = app.FramesSlider.Value;
            app.sliderFrame=ceil(value);
        end

        % Value changed function: FrametimesecondsEditField
        function FrametimesecondsEditFieldValueChanged(app, event)
            value = app.FrametimesecondsEditField.Value;
            app.timeFrame=value;
        end

        % Value changed function: PixelSizemicrometerEditField
        function PixelSizemicrometerEditFieldValueChanged(app, event)
            value = app.PixelSizemicrometerEditField.Value;
            app.pixelSize=value;
        end

        % Value changed function: MaxTauFractionEditField
        function MaxTauFractionEditFieldValueChanged(app, event)
            value = app.MaxTauFractionEditField.Value;
            app.fractau=value;
            app.mintauSlider.Limits = [1 round(app.TotImages*app.fractau)-1];
            app.maxtauSlider.Limits = [2 round(app.TotImages*app.fractau)];
            app.maxtauSlider.Value =round(app.TotImages*app.fractau);
            app.mintaufitSlider.Limits = [1 round(app.TotImages*app.fractau)-2];
            app.maxtaufitSlider.Limits = [2 round(app.TotImages*app.fractau)-1];
            app.maxtaufitSlider.Value=round(app.TotImages*app.fractau);
            app.maxtau=round(app.TotImages*app.fractau);
        end

        % Value changed function: maxk2EditField
        function maxk2EditFieldValueChanged(app, event)
            value = app.maxk2EditField.Value;
            app.k2Cutoff=value;
            app.mink2Slider.Limits = [0 app.k2Cutoff];
            app.maxk2Slider.Limits = [0 app.k2Cutoff];
            app.maxk2Slider.Value = app.k2Cutoff;
            app.maxk2=app.k2Cutoff;
        end

        % Value changing function: ThresholdCellShapeSlider
        function ThresholdCellShapeSliderValueChanging(app, event)
            changingValue = event.Value/10;
            app.sliderShape=changingValue;
            app.aveim=mean(app.croped,3);
            normim1=app.aveim/max(app.aveim(:));
            app.binaryShape=normim1>app.sliderShape;
            normim1=uint8(255*normim1);
            B=labeloverlay(normim1,app.binaryShape);
            cla(app.UIAxes)
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            imagesc(app.UIAxes,B)
            app.UIAxes.YDir='reverse';
            xlim(app.UIAxes,[1 size(app.binaryShape,2)])
            ylim(app.UIAxes,[1 size(app.binaryShape,1)])
            app.showNow=4;
        end

        % Value changed function: ThresholdCellShapeSlider
        function ThresholdCellShapeSliderValueChanged(app, event)
            value = app.ThresholdCellShapeSlider.Value;
            app.sliderShape=value/10;
        end

        % Value changed function: MorphologicalOperationsonBinaryDropDown
        function MorphologicalOperationsonBinaryDropDownValueChanged(app, event)
            value = app.MorphologicalOperationsonBinaryDropDown.Value;
            app.MorphChoice=value;
        end

        % Button pushed function: MorphologicalOperationsButton
        function MorphologicalOperationsButtonPushed(app, event)
            app.binary2=app.binaryShape;
            if strcmp(app.MorphChoice,'clean')
                app.binary2=bwmorph(app.binaryShape,'clean');
            elseif strcmp(app.MorphChoice,'clean+open')
                app.binary2=bwmorph(app.binaryShape,'clean');
                app.binary2=bwmorph(app.binary2,'open');
                app.binary2=bwmorph(app.binary2,'clean');
            elseif strcmp(app.MorphChoice,'clean+close')
                app.binary2=bwmorph(app.binaryShape,'clean');
                app.binary2=bwmorph(app.binary2,'close');
                app.binary2=bwmorph(app.binary2,'clean');
            end
            normim1=app.aveim/max(app.aveim(:));
            normim1=uint8(255*normim1);
            B=labeloverlay(normim1,app.binary2);
            cla(app.UIAxes)
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            imagesc(app.UIAxes,B)
            app.UIAxes.YDir='reverse';
            xlim(app.UIAxes,[1 size(app.aveim,2)])
            ylim(app.UIAxes,[1 size(app.aveim,1)])
            set(app.UIAxes,'CLim',[min(app.aveim(:)) max(app.aveim(:))])
        end

        % Value changing function: LBCellAreaSlider
        function LBCellAreaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LBcellArea=changingValue;
            stats=regionprops(app.binary2,'Area');
            maxArea=max([stats.Area]);
            idx = find([stats.Area]>app.LBcellArea*maxArea);
            cc = bwconncomp(app.binary2);
            BW2 = ismember(labelmatrix(cc),idx);
            normim1=app.aveim/max(app.aveim(:));
            normim1=uint8(255*normim1);
            B=labeloverlay(normim1,BW2);
            cla(app.UIAxes)
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            imagesc(app.UIAxes,B)
            app.UIAxes.YDir='reverse';
            xlim(app.UIAxes,[1 size(BW2,2)])
            ylim(app.UIAxes,[1 size(BW2,1)])
        end

        % Value changed function: LBCellAreaSlider
        function LBCellAreaSliderValueChanged(app, event)
            value = app.LBCellAreaSlider.Value;
            app.LBcellArea=value;
            stats=regionprops(app.binary2,'Area');
            maxArea=max([stats.Area]);
            idx = find([stats.Area]>app.LBcellArea*maxArea);
            cc = bwconncomp(app.binary2);
            BW = ismember(labelmatrix(cc),idx);
            app.LabelCell=bwlabel(BW);
        end

        % Value changed function: AnalyseCellEditField
        function AnalyseCellEditFieldValueChanged(app, event)
            value = app.AnalyseCellEditField.Value;
            app.cellID=uint32(value);
            app.CellMask=app.LabelCell==app.cellID;
            %[app.croped2,mask2]=cropSeriesIntoSquareWithinRangeofBinary(app.croped,app.CellMask);
            cla(app.UIAxes)
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            imagesc(app.UIAxes,app.CellMask)
            app.UIAxes.YDir='reverse';
            xlim(app.UIAxes,[1 size(app.CellMask,2)])
            ylim(app.UIAxes,[1 size(app.CellMask,1)])
            set(app.UIAxes,'CLim',[0 1])
        end

        % Button pushed function: 
        % ExtractCellContourandMeanPadIntensitiesButton
        function ExtractCellContourandMeanPadIntensitiesButtonPushed(app, event)
            % define the cell area
            %             stats=regionprops(app.binary2,'Area');
            %             idx = find([stats.Area]==max([stats.Area]));
            %             L=bwlabel(app.binary2);
            %             app.CellMask=L==idx;
            %app.CellMask=imdilate(app.CellMask,strel('disk',1));
            % now mean pad outside of cell pixels
            app.CellMask=app.LabelCell==app.cellID;
            [app.croped2,mask2]=cropSeriesIntoSquareWithinRangeofBinary(app.croped,app.CellMask);
            app.CellMask=mask2;
            masks=repmat(app.CellMask,[1 1 app.TotImages]);
            app.padded=app.croped2.*masks+mean(mean(mean(nonzeros(app.croped2.*masks)))).*(~masks);
            app.toPad=1;
            cla(app.UIAxes)
            app.UIAxes.YDir='reverse';
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            imagesc(app.UIAxes,app.padded(:,:,app.sliderFrame));
            colormap(app.UIAxes,'gray')
            hold(app.UIAxes,"on");
            text(app.UIAxes,size(app.padded,2)-20,size(app.padded,1)-20,num2str(app.sliderFrame),'Color',[1 1 1])
            xlim(app.UIAxes,[1 size(app.padded,2)])
            ylim(app.UIAxes,[1 size(app.padded,1)])
            app.showNow=5;
        end

        % Value changed function: TemporalImmobileFilteringButton_2
        function TemporalImmobileFilteringButton_2ValueChanged(app, event)
            app.toImmFilt = app.TemporalImmobileFilteringButton_2.Value;
            if app.toPad==0
                app.padded=app.croped2;
            end
            
            if app.toImmFilt==1
                % filtering immobile component
                app.timefilt = fft(double(app.padded),[],3);
                avPixInt = mean(mean(app.timefilt(:,:,1)))/size(app.timefilt,3);
                app.timefilt(:,:,1) = 0;
                app.timefilt = (real(ifft(app.timefilt,[],3))+avPixInt);
                app.timefilt=single(app.timefilt);
            elseif app.toImmFilt==0
                app.timefilt=app.padded;
            end
            cla(app.UIAxes)
            app.UIAxes.YDir='reverse';
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            imagesc(app.UIAxes,app.timefilt(:,:,app.sliderFrame));
            colormap(app.UIAxes,'gray')
            hold(app.UIAxes,"on");
            text(app.UIAxes,size(app.timefilt,2)-20,size(app.timefilt,1)-20,num2str(app.sliderFrame),'Color',[1 1 1])
            xlim(app.UIAxes,[1 size(app.timefilt,2)])
            ylim(app.UIAxes,[1 size(app.timefilt,1)])
            app.showNow=6;
        end

        % Value changed function: HannPowerEditField
        function HannPowerEditFieldValueChanged(app, event)
            value = app.HannPowerEditField.Value;
            app.HannPow=value;
        end

        % Value changed function: SpatialHannWindowingButton_2
        function SpatialHannWindowingButton_2ValueChanged(app, event)
            app.toHann = app.SpatialHannWindowingButton_2.Value;
            if app.toPad==0
                app.padded=app.croped2;
            end
            if app.toImmFilt==0
                app.timefilt=app.padded;
            end
            
            if app.toHann==1
                [app.hanned]=hann2DbetaPower(app.timefilt,app.HannPow);
            elseif app.toHann==0
                app.hanned=app.timefilt;
            end
            cla(app.UIAxes)
            app.UIAxes.YDir='reverse';
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            imagesc(app.UIAxes,app.hanned(:,:,app.sliderFrame));
            colormap(app.UIAxes,'gray')
            hold(app.UIAxes,"on");
            text(app.UIAxes,size(app.hanned,2)-20,size(app.hanned,1)-20,num2str(app.sliderFrame),'Color',[1 1 1])
            xlim(app.UIAxes,[1 size(app.hanned,2)])
            ylim(app.UIAxes,[1 size(app.hanned,1)])
            app.showNow=7;
        end

        % Button pushed function: CalculatekICSCFButton
        function CalculatekICSCFButtonPushed(app, event)
            imgser=single(app.hanned);
            sizet=size(imgser,3);
            imageDim2=size(imgser,2);
            imageDim1=size(imgser,1);
            fftimser=fftn(imgser,[size(imgser,1) size(imgser,2) size(imgser,3)]);
            % define k vectors
            maxk = (1/app.pixelSize)/2*(2*pi);
            stepkx = 1/(imageDim2*app.pixelSize)*(2*pi);
            stepky = 1/(imageDim1*app.pixelSize)*(2*pi);
            
            if ((mod(imageDim2,2)==0)&&(mod(imageDim1,2)==0))
                [X,Y] = meshgrid(-maxk:stepkx:maxk-stepkx,-maxk:stepky:maxk-stepky);
            elseif ((mod(imageDim2,2)==1)&&(mod(imageDim1,2)==1))
                [X,Y] = meshgrid(-stepkx*(imageDim2-1)/2:stepkx:stepkx*(imageDim2-1)/2,-stepky*(imageDim1-1)/2:stepky:stepky*(imageDim1-1)/2);
            elseif mod(imageDim2,2)==1
                [X,Y] = meshgrid(-stepkx*(imageDim2-1)/2:stepkx:stepkx*(imageDim2-1)/2,-maxk:stepky:maxk-stepky);
            else
                [X,Y] = meshgrid(-maxk:stepkx:maxk-stepkx,-stepky*(imageDim1-1)/2:stepky:stepky*(imageDim1-1)/2);
            end
            app.X=X;
            app.Y=Y;
            app.stepkx=stepkx;
            app.stepky=stepky;
            kSquared = (X.^2 + Y.^2);
            roundTol = 1e-11;
            kValues = consolidator(kSquared(:),[],'mean',roundTol);
            kSquaredIndices = find(kSquared(:)<app.k2Cutoff);
            kBin = zeros(sum(kValues<app.k2Cutoff),2,round(app.fractau*sizet));
            kSquaredCut = kSquared(kSquaredIndices);
            
            clear imgser corrfftslice
            app.timecorrnorm=[];
            app.kBin=[];
            corrfn=conj(fftimser).*fftimser;clear fftimser;
            timecorr=ifft(corrfn,[],3);clear corrfn
            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'Calculating kICS Correlation Function...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for i=1:round(app.fractau*sizet)
                app.timecorrnorm(:,:,i)=real(fftshift(timecorr(:,:,i)./timecorr(:,:,1) ));
                corrfftslice = app.timecorrnorm(:,:,i);
                [xg,yg] = consolidator(kSquaredCut,corrfftslice(kSquaredIndices),@mean,roundTol);
                app.kBin(:,1,i) = xg;
                app.kBin(:,2,i) = yg;
                app.kBin(:,3,i) = real(log(yg));
                clear corrfftslice
                ph.XData = [0 i/round(app.fractau*sizet) i/round(app.fractau*sizet) 0];
                th.String = sprintf('Calculating kICS Correlation Function...%.0f%%',round(i/round(app.fractau*sizet)*100));
                drawnow %update graphics
            end
            app.timecorrnorm=app.timecorrnorm(:,:,2:end);
            app.CF=squeeze(app.kBin(:,2,:));
            app.CFsmooth=app.CF;
            app.kValues=squeeze(app.kBin(:,1,2));
            %app.mink2Slider.Value = min(app.kValues);
            %app.maxk2Slider.Value = max(app.kValues);
            cla(app.UIAxes)
            app.UIAxes.YDir='reverse';
            title(app.UIAxes,'kICS Correlation Function')
            xlabel(app.UIAxes,'k_{x} (\mum^{-1})')
            ylabel(app.UIAxes,'k_{y} (\mum^{-1})')
            app.UIAxes.XTick = round([min(app.X(:)):10*app.stepkx:max(app.X(:))],1);
            app.UIAxes.YTick = round([min(app.Y(:)):10*app.stepky:max(app.Y(:))],1);
            app.UIAxes.XTickLabelRotation=90;
            set(app.UIAxes,'FontSize',16)
            imagesc(app.UIAxes,min(app.X(:)):10*app.stepkx:max(app.X(:)),min(app.Y(:)):10*app.stepky:max(app.Y(:)),app.timecorrnorm(:,:,1));
            colormap(app.UIAxes,'gray')
            hold(app.UIAxes,"on");
            text(app.UIAxes,max(app.X(:))-5,max(app.Y(:))-5,num2str(app.sliderFrame),'Color',[1 1 1])
            xlim(app.UIAxes,[min(app.X(:)) max(app.X(:))])
            ylim(app.UIAxes,[min(app.Y(:)) max(app.Y(:))])
            app.FramesSlider.Limits = [1 round(app.fractau*sizet)-1];
            app.FramesSlider.MajorTicks = [1:round(app.fractau*sizet/10):round(app.fractau*sizet)-1];
            app.CF=app.CF(:,2:end);
            app.kBin=app.kBin(:,:,2:end);
            app.showNow=8;
        end

        % Value changed function: MovingAverageSmoothingWindowEditField
        function MovingAverageSmoothingWindowEditFieldValueChanged(app, event)
            value = app.MovingAverageSmoothingWindowEditField.Value;
            app.MovWindk2=value;
        end

        % Value changed function: SmoothCFAlongk2Button
        function SmoothCFAlongk2ButtonValueChanged(app, event)
            value = app.SmoothCFAlongk2Button.Value;
            app.MovWindk2Status=value;
            app.CFsmooth=[];
            if app.MovWindk2Status==1
                for i=1:size(app.CF,2)
                    app.CFsmooth(:,i) = moving_average(squeeze(app.CF(:,i)),app.MovWindk2);
                end
            elseif app.MovWindk2Status==0
                app.CFsmooth=app.CF;
            end
        end

        % Value changed function: ChooseCFrepresentationDropDown
        function ChooseCFrepresentationDropDownValueChanged(app, event)
            value = app.ChooseCFrepresentationDropDown.Value;
            app.CFrepresent=value;
        end

        % Button pushed function: PlotCFButton
        function PlotCFButtonPushed(app, event)
            if strcmp(app.CFrepresent,'2D representation')
                cla(app.UIAxes)
                app.UIAxes.YDir='reverse';
                title(app.UIAxes,'')
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                title(app.UIAxes,'2D Representation of kICS CF')
                imagesc(app.UIAxes,(1:size(app.CFsmooth,2))*app.timeFrame,app.kValues,app.CFsmooth);
                xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                ylabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                delY=app.maxk2-app.mink2;
                delX=(app.maxtau-app.mintau);
                app.UIAxes.YTick = [app.mink2:delY/10:app.maxk2];
                app.UIAxes.XTick = round([app.mintau:delX/10:app.maxtau]*app.timeFrame,1);;
                xlim(app.UIAxes,([app.mintau app.maxtau])*app.timeFrame)
                ylim(app.UIAxes,[app.mink2 app.maxk2])
            elseif strcmp(app.CFrepresent,'G vs k^2 at given tau')
                cla(app.UIAxes)
                title(app.UIAxes,'')
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                rangeTau=app.mintau:app.maxtau;
                ind1=find(app.kValues>app.mink2);
                ind2=find(app.kValues<app.maxk2);
                indgood=intersect(ind1,ind2);
                rangek2=app.kValues(indgood);
                mycolors=jet(length(rangeTau));
                app.UIAxes.YDir='normal';
                for i=1:length(rangeTau)
                    ind=rangeTau==rangeTau(i);
                    plot(app.UIAxes,rangek2,squeeze(app.CFsmooth(indgood,ind)),'Color',mycolors(i,:))
                    hold(app.UIAxes,"on");
                end
                title(app.UIAxes,'G vs k^2 at given tau')
                xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                ylabel(app.UIAxes,'G(k^{2},\tau)','FontSize',14)
                delX=max(rangek2)-min(rangek2);
                delY=app.maxG-app.minG;
                app.UIAxes.YTick = [app.minG:delY/2:app.maxG];
                app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)],1);
                ylim(app.UIAxes,[app.minG app.maxG])
                xlim(app.UIAxes,[min(rangek2) max(rangek2)])
            elseif strcmp(app.CFrepresent,'G vs tau at given k^2')
                cla(app.UIAxes)
                title(app.UIAxes,'')
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                rangeTau=app.mintau:app.maxtau;
                ind1=find(app.kValues>app.mink2);
                ind2=find(app.kValues<app.maxk2);
                indgood=intersect(ind1,ind2);
                rangek2=app.kValues(indgood);
                mycolors=jet(length(rangek2));
                for i=1:length(rangek2)
                    ind=rangek2==rangek2(i);
                    plot(app.UIAxes,rangeTau*app.timeFrame,app.CFsmooth(ind,rangeTau),'Color',mycolors(i,:))
                    hold(app.UIAxes,"on");
                end
                title(app.UIAxes,'G vs tau at given k^2')
                xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                ylabel(app.UIAxes,'G(k^{2},\tau)','FontSize',14)
                delX=(app.maxtau-app.mintau)*app.timeFrame;
                delY=app.maxG-app.minG;
                app.UIAxes.YTick = round([app.minG:delY/2:app.maxG],1);
                app.UIAxes.XTick = round([app.mintau:20:app.maxtau]*app.timeFrame,1);
                ylim(app.UIAxes,[app.minG app.maxG])
                xlim(app.UIAxes,[app.mintau app.maxtau]*app.timeFrame)
            elseif strcmp(app.CFrepresent,'log(G) vs k^2 at given tau')
                cla(app.UIAxes)
                title(app.UIAxes,'')
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                CF=squeeze(log(app.CFsmooth));
                rangeTau=app.mintau:app.maxtau;
                ind1=find(app.kValues>app.mink2);
                ind2=find(app.kValues<app.maxk2);
                indgood=intersect(ind1,ind2);
                rangek2=app.kValues(indgood);
                mycolors=jet(length(rangeTau));
                for i=1:length(rangeTau)
                    ind=rangeTau==rangeTau(i);
                    plot(app.UIAxes,rangek2,squeeze(CF(indgood,ind)),'Color',mycolors(i,:))
                    hold(app.UIAxes,"on");
                end
                title(app.UIAxes,'log(G) vs k^2 at given tau')
                xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                ylabel(app.UIAxes,'log G(k^{2},\tau)','FontSize',14)
                delX=max(rangek2)-min(rangek2);
                delY=log(app.maxG-app.minG);
                app.UIAxes.YTick = log([app.minG:delY/2:app.maxG]);
                app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)]);
                ylim(app.UIAxes,log([app.minG app.maxG]))
                xlim(app.UIAxes,[min(rangek2) max(rangek2)])
            end
        end

        % Value changing function: mintauSlider
        function mintauSliderValueChanging(app, event)
            changingValue = event.Value;
            app.mintau=changingValue;
            PlotCFButtonPushed(app)
        end

        % Value changed function: mintauSlider
        function mintauSliderValueChanged(app, event)
            value = app.mintauSlider.Value;
            app.mintau=value;
        end

        % Value changing function: maxtauSlider
        function maxtauSliderValueChanging(app, event)
            changingValue = event.Value;
            app.maxtau=changingValue;
            PlotCFButtonPushed(app)
        end

        % Value changed function: maxtauSlider
        function maxtauSliderValueChanged(app, event)
            value = app.maxtauSlider.Value;
            app.maxtau=value;
        end

        % Value changing function: mink2Slider
        function mink2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.mink2=changingValue;
            PlotCFButtonPushed(app)
        end

        % Value changed function: mink2Slider
        function mink2SliderValueChanged(app, event)
            value = app.mink2Slider.Value;
            app.mink2=value;
        end

        % Value changing function: maxk2Slider
        function maxk2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.maxk2=changingValue;
            PlotCFButtonPushed(app)
        end

        % Value changed function: maxk2Slider
        function maxk2SliderValueChanged(app, event)
            value = app.maxk2Slider.Value;
            app.maxk2=value;
        end

        % Value changing function: minGktauSlider
        function minGktauSliderValueChanging(app, event)
            changingValue = event.Value;
            app.minG=changingValue;
            PlotCFButtonPushed(app)
        end

        % Value changed function: minGktauSlider
        function minGktauSliderValueChanged(app, event)
            value = app.minGktauSlider.Value;
            app.minG=value;
        end

        % Value changing function: maxGktauSlider
        function maxGktauSliderValueChanging(app, event)
            changingValue = event.Value;
            app.maxG=changingValue;
            PlotCFButtonPushed(app)
        end

        % Value changed function: maxGktauSlider
        function maxGktauSliderValueChanged(app, event)
            value = app.maxGktauSlider.Value;
            app.maxG=value;
        end

        % Value changed function: PreviewFitEveryNthTauEditField
        function PreviewFitEveryNthTauEditFieldValueChanged(app, event)
            value = app.PreviewFitEveryNthTauEditField.Value;
            app.FitNthTau=value;
        end

        % Value changed function: ChooseCFFittingOptionsDropDown
        function ChooseCFFittingOptionsDropDownValueChanged(app, event)
            value = app.ChooseCFFittingOptionsDropDown.Value;
            app.FitCFOption=value;
        end

        % Value changing function: Mink2FitSlider
        function Mink2FitSliderValueChanging(app, event)
            changingValue = event.Value;
            app.mink2fit=changingValue;
        end

        % Value changed function: Mink2FitSlider
        function Mink2FitSliderValueChanged(app, event)
            value = app.Mink2FitSlider.Value;
            app.mink2fit=value;
        end

        % Value changing function: Maxk2FitSlider
        function Maxk2FitSliderValueChanging(app, event)
            changingValue = event.Value;
            app.maxk2fit=changingValue;
        end

        % Value changed function: Maxk2FitSlider
        function Maxk2FitSliderValueChanged(app, event)
            value = app.Maxk2FitSlider.Value;
            app.maxk2fit=value;
        end

        % Value changed function: 
        % InflectionPointk2fit0ifsingleComponentFitEditField
        function InflectionPointk2fit0ifsingleComponentFitEditFieldValueChanged(app, event)
            value = app.InflectionPointk2fit0ifsingleComponentFitEditField.Value;
            app.inflectk2=value;
        end

        % Button pushed function: FitPreviewButton
        function FitPreviewButtonPushed(app, event)
            if strcmp(app.FitCFOption,'Linear Fit 1 Component')
                cla(app.UIAxes)
                title(app.UIAxes,'');
                xlabel(app.UIAxes,'');
                ylabel(app.UIAxes,'');
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                ind1=find(app.kValues>app.mink2fit);
                ind2=find(app.kValues<app.maxk2fit);
                indgood=intersect(ind1,ind2);
                rangek2=app.kValues(indgood);
                CF=app.CFsmooth(indgood,1:app.FitNthTau:end);
                CF=CF+abs(min(CF))+10^-12;
                CF=squeeze(log(CF));
                %CF=squeeze(log(app.CFsmooth(indgood,1:app.FitNthTau:end)));
                mycolors=jet(size(CF,2));
                app.DtauF=[];
                app.AmpF=[];
                app.DtauS=[];
                app.AmpS=[];
                app.RatAmp=[];
                cla(app.UIAxes2)
                ylim(app.UIAxes2,[0,1])
                xlim(app.UIAxes2,[0,1])
                ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                th = text(app.UIAxes2,1,1,'Preview Fit for kICS CF...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                for intau=1:size(CF,2)
                    CFtouse=CF(:,intau);
                    plot(app.UIAxes,rangek2,CFtouse,'.','Color',mycolors(intau,:))
                    hold(app.UIAxes,"on");
                    linfit=polyfit(rangek2,CFtouse,1);
                    plot(app.UIAxes,rangek2,linfit(1)*rangek2+linfit(2),'--','Color',mycolors(intau,:))
                    app.DtauF(intau)=-linfit(1);
                    app.AmpF(intau)=exp(linfit(2));
                    ph.XData = [0 intau/size(CF,2) intau/size(CF,2) 0];
                    th.String = sprintf('Preview Fit for kICS CF...%.0f%%',round(intau/size(CF,2)*100));
                    drawnow %update graphics
                end
                title(app.UIAxes,'log(G) vs k^2 at given tau')
                xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                ylabel(app.UIAxes,'log G(k^{2},\tau)','FontSize',14)
                delX=max(rangek2)-min(rangek2);
                delY=max(CF(:))-min(CF(:));
                app.UIAxes.YTick = ([min(CF(:)):delY/10:max(CF(:))]);
                app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)]);
                ylim(app.UIAxes,[min(CF(:)) max(CF(:))])
                xlim(app.UIAxes,[min(rangek2) max(rangek2)])
            elseif strcmp(app.FitCFOption,'Linear Fit 2 Components')
                cla(app.UIAxes)
                title(app.UIAxes,'');
                xlabel(app.UIAxes,'');
                ylabel(app.UIAxes,'');
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                minK=min(find(app.kValues>app.mink2fit));
                maxK=min(find(app.kValues>app.maxk2fit));
                inflectK=min(find(app.kValues>app.inflectk2));
                CF=app.CFsmooth(:,1:app.FitNthTau:end);
                CF=CF+abs(min(CF))+10^-12;
                CF=squeeze(log(CF));
                %CF=squeeze(log(app.CFsmooth(:,1:app.FitNthTau:end)));
                mycolors=jet(size(CF,2));
                app.DtauF=[];
                app.AmpF=[];
                app.DtauS=[];
                app.AmpS=[];
                app.RatAmp=[];
                cla(app.UIAxes2)
                ylim(app.UIAxes2,[0,1])
                xlim(app.UIAxes2,[0,1])
                ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                th = text(app.UIAxes2,1,1,'Preview Fit for kICS CF...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                for intau=1:size(CF,2)
                    plot(app.UIAxes,app.kValues(minK:inflectK),squeeze(CF(minK:inflectK,intau)),'.','Color',mycolors(intau,:))
                    hold(app.UIAxes,"on");
                    linfit=polyfit(app.kValues(minK:inflectK),squeeze(CF(minK:inflectK,intau)),1);
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,app.kValues(minK:inflectK),linfit(1)*app.kValues(minK:inflectK)+linfit(2),'--','Color',mycolors(intau,:))
                    app.DtauF(intau)=-linfit(1);
                    app.AmpF(intau)=exp(linfit(2));
                    % slow component
                    plot(app.UIAxes,app.kValues(inflectK:maxK),squeeze(CF(inflectK:maxK,intau)),'.','Color',mycolors(intau,:))
                    hold(app.UIAxes,"on");
                    linfit=polyfit(app.kValues(inflectK:maxK),squeeze(CF(inflectK:maxK,intau)),1);
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,app.kValues(inflectK:maxK),linfit(1)*app.kValues(inflectK:maxK)+linfit(2),'--','Color',mycolors(intau,:))
                    app.DtauS(intau)=-linfit(1);
                    app.AmpS(intau)=exp(linfit(2));
                    app.RatAmp(intau)=app.AmpF(intau)./app.AmpS(intau);
                    ph.XData = [0 intau/size(CF,2) intau/size(CF,2) 0];
                    th.String = sprintf('Preview Fit for kICS CF...%.0f%%',round(intau/size(CF,2)*100));
                    drawnow %update graphics
                end
                title(app.UIAxes,'log(G) vs k^2 at given tau')
                xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                ylabel(app.UIAxes,'log G(k^{2},\tau)','FontSize',14)
                rangek2=app.kValues(minK:maxK);
                delX=max(rangek2)-min(rangek2);
                delY=max(CF(:))-min(CF(:));
                app.UIAxes.YTick = ([min(CF(:)):delY/10:max(CF(:))]);
                app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)]);
                ylim(app.UIAxes,[min(CF(:)) max(CF(:))])
                xlim(app.UIAxes,[min(app.kValues(minK:maxK)) max(app.kValues(minK:maxK))])
            elseif strcmp(app.FitCFOption,'Non-Linear Fit 1 Component')
                cla(app.UIAxes)
                title(app.UIAxes,'');
                xlabel(app.UIAxes,'');
                ylabel(app.UIAxes,'');
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                ind1=find(app.kValues>app.mink2fit);
                ind2=find(app.kValues<app.maxk2fit);
                indgood=intersect(ind1,ind2);
                rangek2=app.kValues(indgood);
                title(app.UIAxes,'G vs k^2 at given tau')
                xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                ylabel(app.UIAxes,'G(k^{2},\tau)','FontSize',14)
                delX=max(rangek2)-min(rangek2);
                delY=app.maxG-app.minG;
                app.UIAxes.YTick = [app.minG:delY/2:app.maxG];
                app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)],1);
                ylim(app.UIAxes,[app.minG app.maxG])
                xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                [results,fitted]=DiffusionLawBySingleGaussianFitvsKsqForGUI(app.UIAxes,app.UIAxes2,squeeze((app.CFsmooth(indgood,:))),(1:app.FitNthTau:size(app.CF,2)),rangek2,app.timeFrame);
                cfit=results{:,1};
                app.DtauF=cfit.b;
                app.AmpF=cfit.a;
                app.DtauS=[];
                app.AmpS=[];
                app.RatAmp=[];
            elseif strcmp(app.FitCFOption,'Non-Linear Fit 2 Components')
                cla(app.UIAxes)
                title(app.UIAxes,'');
                xlabel(app.UIAxes,'');
                ylabel(app.UIAxes,'');
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                ind1=find(app.kValues>app.mink2fit);
                ind2=find(app.kValues<app.maxk2fit);
                indgood=intersect(ind1,ind2);
                rangek2=app.kValues(indgood);
                title(app.UIAxes,'G vs k^2 at given tau')
                xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                ylabel(app.UIAxes,'G(k^{2},\tau)','FontSize',14)
                delX=max(rangek2)-min(rangek2);
                delY=app.maxG-app.minG;
                app.UIAxes.YTick = [app.minG:delY/2:app.maxG];
                app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)],1);
                ylim(app.UIAxes,[app.minG app.maxG])
                xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                [results,fitted]=DiffusionLawByDoubleGaussianFitvsKsqForGUI(app.UIAxes,app.UIAxes2,squeeze((app.CFsmooth(indgood,:))),(1:app.FitNthTau:size(app.CF,2)),rangek2,app.timeFrame);
                cfit=results{:,1};
                app.DtauF=cfit.b;
                app.AmpF=cfit.a;
                app.DtauS=cfit.d;
                app.AmpS=cfit.c;
                app.RatAmp=app.AmpF./app.AmpS;
            end
        end

        % Button pushed function: FitAllTauButton
        function FitAllTauButtonPushed(app, event)
            if strcmp(app.FitCFOption,'Linear Fit 1 Component')
                cla(app.UIAxes)
                title(app.UIAxes,'');
                xlabel(app.UIAxes,'');
                ylabel(app.UIAxes,'');
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                ind1=find(app.kValues>app.mink2fit);
                ind2=find(app.kValues<app.maxk2fit);
                indgood=intersect(ind1,ind2);
                rangek2=app.kValues(indgood);
                 ylim(app.UIAxes,log([app.minG app.maxG]))
                xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                CF=app.CFsmooth(indgood,:);
                CF=CF+abs(min(CF))+10^-12;
                CF=squeeze(log(CF));
                %CF=squeeze(log(app.CFsmooth(indgood,1:end)));
                mycolors=jet(size(CF,2));
                app.DtauF=[];
                app.AmpF=[];
                app.DtauS=[];
                app.AmpS=[];
                app.RatAmp=[];
                cla(app.UIAxes2)
                ylim(app.UIAxes2,[0,1])
                xlim(app.UIAxes2,[0,1])
                ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                th = text(app.UIAxes2,1,1,'Linear Fit 1 Component of kICS CF...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                for intau=1:size(CF,2)
                    CFtouse=CF(:,intau);
                    plot(app.UIAxes,rangek2,CFtouse,'.','Color',mycolors(intau,:))
                    hold(app.UIAxes,"on");
                    linfit=polyfit(rangek2,CFtouse,1);
                    plot(app.UIAxes,rangek2,linfit(1)*rangek2+linfit(2),'--','Color',mycolors(intau,:))
                    app.DtauF(intau)=-linfit(1);
                    app.AmpF(intau)=exp(linfit(2));
                    ph.XData = [0 intau/size(CF,2) intau/size(CF,2) 0];
                    th.String = sprintf('Linear Fit 1 Component of kICS CF...%.0f%%',round(intau/size(CF,2)*100));
                    drawnow %update graphics
                end
                title(app.UIAxes,'log(G) vs k^2 at given tau')
                xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                ylabel(app.UIAxes,'log G(k^{2},\tau)','FontSize',14)
                delX=max(rangek2)-min(rangek2);
                delY=log(app.maxG-app.minG);
                app.UIAxes.YTick = log([app.minG:delY/2:app.maxG]);
                app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)]);
               
            elseif strcmp(app.FitCFOption,'Linear Fit 2 Components')
                cla(app.UIAxes)
                title(app.UIAxes,'');
                xlabel(app.UIAxes,'');
                ylabel(app.UIAxes,'');
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                minK=min(find(app.kValues>app.mink2fit));
                maxK=min(find(app.kValues>app.maxk2fit));
                inflectK=min(find(app.kValues>app.inflectk2));
                ylim(app.UIAxes,log([app.minG app.maxG]))
                xlim(app.UIAxes,[min(app.kValues(minK:maxK)) max(app.kValues(minK:maxK))])
                CF=app.CFsmooth;
                CF=CF+abs(min(CF))+10^-12;
                CF=squeeze(log(CF));
                %CF=squeeze(log(app.CFsmooth(:,1:end)));
                mycolors=jet(size(CF,2));
                app.DtauF=[];
                app.AmpF=[];
                app.DtauS=[];
                app.AmpS=[];
                app.RatAmp=[];
                cla(app.UIAxes2)
                ylim(app.UIAxes2,[0,1])
                xlim(app.UIAxes2,[0,1])
                ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                th = text(app.UIAxes2,1,1,'Linear Fit 2 Components of kICS CF...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                for intau=1:size(CF,2)
                    plot(app.UIAxes,app.kValues(minK:inflectK),squeeze(CF(minK:inflectK,intau)),'.','Color',mycolors(intau,:))
                    hold(app.UIAxes,"on");
                    linfit=polyfit(app.kValues(minK:inflectK),squeeze(CF(minK:inflectK,intau)),1);
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,app.kValues(minK:inflectK),linfit(1)*app.kValues(minK:inflectK)+linfit(2),'--','Color',mycolors(intau,:))
                    app.DtauF(intau)=-linfit(1);
                    app.AmpF(intau)=exp(linfit(2));
                    % slow component
                    plot(app.UIAxes,app.kValues(inflectK:maxK),squeeze(CF(inflectK:maxK,intau)),'.','Color',mycolors(intau,:))
                    hold(app.UIAxes,"on");
                    linfit=polyfit(app.kValues(inflectK:maxK),squeeze(CF(inflectK:maxK,intau)),1);
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,app.kValues(inflectK:maxK),linfit(1)*app.kValues(inflectK:maxK)+linfit(2),'--','Color',mycolors(intau,:))
                    app.DtauS(intau)=-linfit(1);
                    app.AmpS(intau)=exp(linfit(2));
                    app.RatAmp(intau)=app.AmpF(intau)./app.AmpS(intau);
                    ph.XData = [0 intau/size(CF,2) intau/size(CF,2) 0];
                    th.String = sprintf('Linear Fit 2 Components of kICS CF...%.0f%%',round(intau/size(CF,2)*100));
                    drawnow %update graphics
                end
                title(app.UIAxes,'log(G) vs k^2 at given tau')
                xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                ylabel(app.UIAxes,'log G(k^{2},\tau)','FontSize',14)
                delX=max(app.kValues(minK:maxK))-min(app.kValues(minK:maxK));
                delY=log(app.maxG-app.minG);
                app.UIAxes.YTick = log([app.minG:delY/2:app.maxG]);
                app.UIAxes.XTick = round([min(app.kValues(minK:maxK)):delX/10:max(app.kValues(minK:maxK))]);
                
            elseif strcmp(app.FitCFOption,'Non-Linear Fit 1 Component')
                cla(app.UIAxes)
                title(app.UIAxes,'');
                xlabel(app.UIAxes,'');
                ylabel(app.UIAxes,'');
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                ind1=find(app.kValues>app.mink2fit);
                ind2=find(app.kValues<app.maxk2fit);
                indgood=intersect(ind1,ind2);
                rangek2=app.kValues(indgood);
                title(app.UIAxes,'G vs k^2 at given tau')
                xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                ylabel(app.UIAxes,'G(k^{2},\tau)','FontSize',14)
                delX=max(rangek2)-min(rangek2);
                delY=app.maxG-app.minG;
                app.UIAxes.YTick = [app.minG:delY/2:app.maxG];
                app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)],1);
                ylim(app.UIAxes,[app.minG app.maxG])
                xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                [results,fitted]=DiffusionLawBySingleGaussianFitvsKsqForGUI(app.UIAxes,app.UIAxes2,squeeze((app.CFsmooth(indgood,:))),(1:size(app.CF,2)),rangek2,app.timeFrame);
                cfit=results{:,1};
                app.DtauF=cfit.b;
                app.AmpF=cfit.a;
                app.DtauS=[];
                app.AmpS=[];
                app.RatAmp=[];
            elseif strcmp(app.FitCFOption,'Non-Linear Fit 2 Components')
                cla(app.UIAxes)
                title(app.UIAxes,'');
                xlabel(app.UIAxes,'');
                ylabel(app.UIAxes,'');
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                ind1=find(app.kValues>app.mink2fit);
                ind2=find(app.kValues<app.maxk2fit);
                indgood=intersect(ind1,ind2);
                rangek2=app.kValues(indgood);
                ylim(app.UIAxes,[app.minG app.maxG])
                xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                title(app.UIAxes,'G vs k^2 at given tau')
                xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                ylabel(app.UIAxes,'G(k^{2},\tau)','FontSize',14)
                delX=max(rangek2)-min(rangek2);
                delY=app.maxG-app.minG;
                app.UIAxes.YTick = [app.minG:delY/2:app.maxG];
                app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)],1);
                [results,fitted]=DiffusionLawByDoubleGaussianFitvsKsqForGUI(app.UIAxes,app.UIAxes2,squeeze((app.CFsmooth(indgood,:))),(1:size(app.CF,2)),rangek2,app.timeFrame);
                cfit=results{:,1};
                app.DtauF=cfit.b;
                app.AmpF=cfit.a;
                app.DtauS=cfit.d;
                app.AmpS=cfit.c;
                app.RatAmp=app.AmpF./app.AmpS;
            end
            exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_FittingCF.png'])
        end

        % Value changed function: ChoosewhichtrendtoshowDropDown
        function ChoosewhichtrendtoshowDropDownValueChanged(app, event)
            value = app.ChoosewhichtrendtoshowDropDown.Value;
            app.LinearTrendDisplayOption=value;
            if strcmp(app.LinearTrendDisplayOption,'Ratio Amplitude Macro to Micro Component vs. tau')
                cla(app.UIAxes)
                title(app.UIAxes,'');
                xlabel(app.UIAxes,'');
                ylabel(app.UIAxes,'');
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                if ~isempty(app.RatAmp)
                    rangeY=app.RatAmp(app.mintaufit:app.maxtaufit);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=(app.mintaufit:app.maxtaufit)*app.timeFrame;
                    rangetau=rangetau(indgood);
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    title(app.UIAxes,'Ratio Amplitude Macro to Micro')
                    xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'G(k^{2},\tau)','FontSize',14)
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/20:max(rangeY)],3);
                    app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                end
            elseif strcmp(app.LinearTrendDisplayOption,'Dtau Macro vs tau')
                cla(app.UIAxes)
                title(app.UIAxes,'');
                xlabel(app.UIAxes,'');
                ylabel(app.UIAxes,'');
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                if ~isempty(app.DtauF)
                    plot(app.UIAxes,(app.mintaufit:app.maxtaufit)*app.timeFrame,app.DtauF(app.mintaufit:app.maxtaufit),'o')
                    title(app.UIAxes,'Dtau Macro vs tau')
                    xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'G(k^{2},\tau)','FontSize',14)
                    rangetau=(app.mintaufit:app.maxtaufit)*app.timeFrame;
                    rangeY=app.DtauF(app.mintaufit:app.maxtaufit);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                    app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                end
            elseif strcmp(app.LinearTrendDisplayOption,'Dtau Micro vs tau')
                cla(app.UIAxes)
                title(app.UIAxes,'');
                xlabel(app.UIAxes,'');
                ylabel(app.UIAxes,'');
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                if ~isempty(app.DtauS)
                    plot(app.UIAxes,(app.mintaufit:app.maxtaufit)*app.timeFrame,app.DtauS(app.mintaufit:app.maxtaufit),'o')
                    title(app.UIAxes,'Dtau Micro vs tau')
                    xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'G(k^{2},\tau)','FontSize',14)
                    rangetau=(app.mintaufit:app.maxtaufit)*app.timeFrame;
                    rangeY=app.DtauS(app.mintaufit:app.maxtaufit);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/20:max(rangeY)],5);
                    app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                end
            end
        end

        % Value changing function: mintaufitSlider
        function mintaufitSliderValueChanging(app, event)
            changingValue = event.Value;
            app.mintaufit=changingValue;
            ChoosewhichtrendtoshowDropDownValueChanged(app);
        end

        % Value changed function: mintaufitSlider
        function mintaufitSliderValueChanged(app, event)
            value = app.mintaufitSlider.Value;
            app.mintaufit=value;
            ChoosewhichtrendtoshowDropDownValueChanged(app);
        end

        % Value changing function: maxtaufitSlider
        function maxtaufitSliderValueChanging(app, event)
            changingValue = event.Value;
            app.maxtaufit=changingValue;
            ChoosewhichtrendtoshowDropDownValueChanged(app);
        end

        % Value changed function: maxtaufitSlider
        function maxtaufitSliderValueChanged(app, event)
            value = app.maxtaufitSlider.Value;
            app.maxtaufit=value;
            ChoosewhichtrendtoshowDropDownValueChanged(app);
        end

        % Value changed function: ChoosetrendfittingoptionDropDown
        function ChoosetrendfittingoptionDropDownValueChanged(app, event)
            value = app.ChoosetrendfittingoptionDropDown.Value;
            app.FitTrendOption=value;
        end

        % Button pushed function: FitButton_2
        function FitButton_2Pushed(app, event)
            if strcmp(app.FitTrendOption,'early linear fit DmacroTau vs tau')
                rangetau=(app.mintaufit:app.maxtaufit)*app.timeFrame;
                rangeY=app.DtauF(app.mintaufit:app.maxtaufit);
                indgood=zeros(size(rangeY));
                for i=1:length(rangeY)
                    if isreal(rangeY(i))
                        indgood(i)=1;
                    end
                end
                indgood=find(indgood);
                rangeY=rangeY(indgood);
                rangetau=rangetau(indgood);
                fitlin=polyfit(rangetau,rangeY,1);
                app.Dmacro=fitlin(1);
                cla(app.UIAxes)
                title(app.UIAxes,'');
                xlabel(app.UIAxes,'');
                ylabel(app.UIAxes,'');
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                plot(app.UIAxes,rangetau,rangeY,'o')
                hold(app.UIAxes,"on");
                plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                text(app.UIAxes,rangetau(1),0.95*max(rangeY),['D_{macro}=' num2str(round(app.Dmacro,3)) ' \mum^{2}s^{-1}'])
                title(app.UIAxes,'Dtau Macro vs tau')
                xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                ylabel(app.UIAxes,'D_{macro}\tau (\mum^{2})','FontSize',14)
                delY=max(rangeY)-min(rangeY);
                app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                app.mintaufit1=app.mintaufit;
                app.maxtaufit1=app.maxtaufit;
                exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_EarlyLinearFitDmacroTau.png'])
            elseif strcmp(app.FitTrendOption,'late linear fit DmacroTau vs tau')
                rangetau=(app.mintaufit:app.maxtaufit)*app.timeFrame;
                rangeY=app.DtauF(app.mintaufit:app.maxtaufit);
                indgood=zeros(size(rangeY));
                for i=1:length(rangeY)
                    if isreal(rangeY(i))
                        indgood(i)=1;
                    end
                end
                indgood=find(indgood);
                rangeY=rangeY(indgood);
                rangetau=rangetau(indgood);
                fitlin=polyfit(rangetau,rangeY,1);
                app.Deff=fitlin(1);
                app.MaxDispMacro=fitlin(2)^0.5;
                cla(app.UIAxes)
                title(app.UIAxes,'');
                xlabel(app.UIAxes,'');
                ylabel(app.UIAxes,'');
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                plot(app.UIAxes,rangetau,rangeY,'o')
                hold(app.UIAxes,"on");
                plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                text(app.UIAxes,rangetau(1),0.85*max(rangeY),['D_{eff}=' num2str(round(app.Deff,3)) ' \mum^{2}s^{-1}'])
                text(app.UIAxes,rangetau(1),0.95*max(rangeY),['Max. Displacement Macro=' num2str(round(app.MaxDispMacro,2)) ' \mum'])
                title(app.UIAxes,'Dtau Macro vs tau')
                xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                ylabel(app.UIAxes,'D_{macro}\tau (\mum^{2})','FontSize',14)
                delY=max(rangeY)-min(rangeY);
                app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                app.mintaufit2=app.mintaufit;
                app.maxtaufit2=app.maxtaufit;
                exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_LateLinearFitDmacroTau.png'])
            elseif strcmp(app.FitTrendOption,'early linear fit DmicroTau vs tau')
                rangetau=(app.mintaufit:app.maxtaufit)*app.timeFrame;
                rangeY=app.DtauS(app.mintaufit:app.maxtaufit);
                indgood=zeros(size(rangeY));
                for i=1:length(rangeY)
                    if isreal(rangeY(i))
                        indgood(i)=1;
                    end
                end
                indgood=find(indgood);
                rangeY=rangeY(indgood);
                rangetau=rangetau(indgood);
                fitlin=polyfit(rangetau,rangeY,1);
                app.Dmicro=fitlin(1);
                cla(app.UIAxes)
                title(app.UIAxes,'');
                xlabel(app.UIAxes,'');
                ylabel(app.UIAxes,'');
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                plot(app.UIAxes,rangetau,rangeY,'o')
                hold(app.UIAxes,"on");
                plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                text(app.UIAxes,rangetau(1),0.95*max(rangeY),['D_{micro}=' num2str(round(app.Dmicro,4)) ' \mum^{2}s^{-1}'])
                title(app.UIAxes,'Dtau Micro vs tau')
                xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                ylabel(app.UIAxes,'D_{micro}\tau (\mum^{2})','FontSize',14)
                delY=max(rangeY)-min(rangeY);
                app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],5);
                app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                app.mintaufit3=app.mintaufit;
                app.maxtaufit3=app.maxtaufit;
                exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_EarlyLinearFitDmicroTau.png'])
            elseif strcmp(app.FitTrendOption,'late linear fit DmicroTau vs tau')
                rangetau=(app.mintaufit:app.maxtaufit)*app.timeFrame;
                rangeY=app.DtauS(app.mintaufit:app.maxtaufit);
                indgood=zeros(size(rangeY));
                for i=1:length(rangeY)
                    if isreal(rangeY(i))
                        indgood(i)=1;
                    end
                end
                indgood=find(indgood);
                rangeY=rangeY(indgood);
                rangetau=rangetau(indgood);
                fitlin=polyfit(rangetau,rangeY,1);
                app.DmicroLate=fitlin(1);
                app.MaxDispMicro=fitlin(2)^0.5;
                cla(app.UIAxes)
                title(app.UIAxes,'');
                xlabel(app.UIAxes,'');
                ylabel(app.UIAxes,'');
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                delY=max(rangeY)-min(rangeY);
                app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],5);
                app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                plot(app.UIAxes,rangetau,rangeY,'o')
                hold(app.UIAxes,"on");
                plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                text(app.UIAxes,rangetau(1),0.85*max(rangeY),['D_{microLate}=' num2str(round(app.DmicroLate,4)) ' \mum^{2}s^{-1}'])
                text(app.UIAxes,rangetau(1),0.95*max(rangeY),['Max. Displacement Micro=' num2str(round(app.MaxDispMicro,3)) ' \mum'])
                title(app.UIAxes,'Dtau Micro vs tau')
                xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                ylabel(app.UIAxes,'D_{micro}\tau (\mum^{2})','FontSize',14)
                app.mintaufit4=app.mintaufit;
                app.maxtaufit4=app.maxtaufit;
                exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_LateLinearFitDmicroTau.png'])
            elseif strcmp(app.FitTrendOption,'late linear fit AmpMacro/AmpMicro vs tau')
                rangetau=(app.mintaufit:app.maxtaufit)*app.timeFrame;
                rangeY=log(app.RatAmp(app.mintaufit:app.maxtaufit));
                indgood=zeros(size(rangeY));
                for i=1:length(rangeY)
                    if isreal(rangeY(i))
                        indgood(i)=1;
                    end
                end
                indgood=find(indgood);
                rangeY=rangeY(indgood);
                rangetau=rangetau(indgood);
                fitlin=polyfit(rangetau,rangeY,1);
                app.k2=fitlin(1);
                app.ratConc=exp(fitlin(1)*app.maxtaufit*app.timeFrame+fitlin(2));
                cla(app.UIAxes)
                title(app.UIAxes,'');
                xlabel(app.UIAxes,'');
                ylabel(app.UIAxes,'');
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                plot(app.UIAxes,rangetau,rangeY,'o')
                hold(app.UIAxes,"on");
                plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                text(app.UIAxes,rangetau(1),0.85*max(rangeY),['k_{2}=' num2str(round(app.k2,3)) ' s^{-1}'])
                text(app.UIAxes,rangetau(1),0.95*max(rangeY),['N_{macro}/N_{micro}=' num2str(round(app.ratConc,3)) ])
                title(app.UIAxes,'N_{macro}/N_{micro} vs tau')
                xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                ylabel(app.UIAxes,'N_{macro}/N_{micro}','FontSize',14)
                delY=max(rangeY)-min(rangeY);
                app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                app.mintaufit5=app.mintaufit;
                app.maxtaufit5=app.maxtaufit;
                exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_LateLinearFitRatioAmp.png'])
            elseif strcmp(app.FitTrendOption,'Anomalous Fit To DmacroTau vs tau')
                rangetau=(app.mintaufit:app.maxtaufit)*app.timeFrame;
                rangeY=app.DtauF(app.mintaufit:app.maxtaufit);
                indgood=zeros(size(rangeY));
                for i=1:length(rangeY)
                    if isreal(rangeY(i))
                        indgood(i)=1;
                    end
                end
                indgood=find(indgood);
                rangeY=rangeY(indgood);
                rangetau=rangetau(indgood);
                rangetau=log(rangetau);
                rangeY=log(rangeY);
                fitlin=polyfit(rangetau,rangeY,1);
                app.Danomalous=exp(fitlin(2));
                app.alphaAnomalous=fitlin(1);
                cla(app.UIAxes)
                title(app.UIAxes,'');
                xlabel(app.UIAxes,'');
                ylabel(app.UIAxes,'');
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                app.UIAxes.YDir='normal';
                plot(app.UIAxes,rangetau,rangeY,'o')
                hold(app.UIAxes,"on");
                plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                text(app.UIAxes,rangetau(1),1.15*max(rangeY),['D_{anomalous}=' num2str(round(app.Danomalous,3)) ' \mum^{2}s^{-1}'])
                text(app.UIAxes,rangetau(1),1.25*max(rangeY),['\alpha_{anomalous}=' num2str(round(app.alphaAnomalous,3))])
                title(app.UIAxes,'Anomalous Fit of log(Dtau Macro) vs log(tau)')
                xlabel(app.UIAxes,'log\tau (s)','FontSize',14)
                ylabel(app.UIAxes,'logD_{macro}\tau (\mum^{2})','FontSize',14)
                delY=max(rangeY)-min(rangeY);
                app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                app.UIAxes.XTick = round([min(rangetau):log(3*app.timeFrame):max(rangetau)],3);
                ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                app.mintaufit6=app.mintaufit;
                app.maxtaufit6=app.maxtaufit;
                exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_AnomalousFit.png'])
            end
            
        end

        % Button pushed function: 
        % ApplyAnalysisToEachCellAndExportResultsButton
        function ApplyAnalysisToEachCellAndExportResultsButtonPushed(app, event)
            if ~isempty(app.LabelCell)
            % Output cell mask
                cla(app.UIAxes)
                title(app.UIAxes,'')
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                stats=regionprops(app.LabelCell,'Centroid');
                rgb=label2rgb(app.LabelCell,'jet','k','shuffle');
                xy=[stats.Centroid];
                imagesc(app.UIAxes,rgb)
                hold(app.UIAxes,"on");
                for cel=1:size(stats,1)
                    text(app.UIAxes,xy(1+(cel-1)*2),xy(2*cel),num2str(cel),'Color',[1 1 1])
                    hold(app.UIAxes,"on");
                end
                app.UIAxes.YDir='reverse';
                xlim(app.UIAxes,[1 size(app.LabelCell,2)])
                ylim(app.UIAxes,[1 size(app.LabelCell,1)])
                set(app.UIAxes,'CLim',[0 1])
                exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_CellMaskCell.png'])
                %%%%%%
            for cells=1:max(app.LabelCell(:))
                % make padded image around a cell
                app.CellMask=app.LabelCell==cells;
                [app.croped2,mask2]=cropSeriesIntoSquareWithinRangeofBinary(app.croped,app.CellMask);
                app.CellMask=mask2;
                masks=repmat(app.CellMask,[1 1 app.TotImages]);
                app.padded=app.croped2.*masks+mean(mean(mean(nonzeros(app.croped2.*masks)))).*(~masks);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % do temporal filtering if checked
                if app.toImmFilt==1
                    % filtering immobile component
                    app.timefilt = fft(double(app.padded),[],3);
                    avPixInt = mean(mean(app.timefilt(:,:,1)))/size(app.timefilt,3);
                    app.timefilt(:,:,1) = 0;
                    app.timefilt = (real(ifft(app.timefilt,[],3))+avPixInt);
                    app.timefilt=single(app.timefilt);
                elseif app.toImmFilt==0
                    app.timefilt=app.padded;
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Hann windowing
                if app.toHann==1
                    [app.hanned]=hann2DbetaPower(app.timefilt,app.HannPow);
                elseif app.toHann==0
                    app.hanned=app.timefilt;
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % compute kICS CF
                imgser=single(app.hanned);
                sizet=size(imgser,3);
                imageDim2=size(imgser,2);
                imageDim1=size(imgser,1);
                fftimser=fftn(imgser,[size(imgser,1) size(imgser,2) size(imgser,3)]);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % define k vectors
                maxk = (1/app.pixelSize)/2*(2*pi);
                stepkx = 1/(imageDim2*app.pixelSize)*(2*pi);
                stepky = 1/(imageDim1*app.pixelSize)*(2*pi);
                
                if ((mod(imageDim2,2)==0)&&(mod(imageDim1,2)==0))
                    [X,Y] = meshgrid(-maxk:stepkx:maxk-stepkx,-maxk:stepky:maxk-stepky);
                elseif ((mod(imageDim2,2)==1)&&(mod(imageDim1,2)==1))
                    [X,Y] = meshgrid(-stepkx*(imageDim2-1)/2:stepkx:stepkx*(imageDim2-1)/2,-stepky*(imageDim1-1)/2:stepky:stepky*(imageDim1-1)/2);
                elseif mod(imageDim2,2)==1
                    [X,Y] = meshgrid(-stepkx*(imageDim2-1)/2:stepkx:stepkx*(imageDim2-1)/2,-maxk:stepky:maxk-stepky);
                else
                    [X,Y] = meshgrid(-maxk:stepkx:maxk-stepkx,-stepky*(imageDim1-1)/2:stepky:stepky*(imageDim1-1)/2);
                end
                app.X=X;
                app.Y=Y;
                app.stepkx=stepkx;
                app.stepky=stepky;
                kSquared = (X.^2 + Y.^2);
                roundTol = 1e-11;
                kValues = consolidator(kSquared(:),[],'mean',roundTol);
                kSquaredIndices = find(kSquared(:)<app.k2Cutoff);
                kBin = zeros(sum(kValues<app.k2Cutoff),2,round(app.fractau*sizet));
                kSquaredCut = kSquared(kSquaredIndices);
                clear imgser corrfftslice
                app.timecorrnorm=[];
                app.kBin=[];
                corrfn=conj(fftimser).*fftimser;clear fftimser;
                timecorr=ifft(corrfn,[],3);clear corrfn
                cla(app.UIAxes2)
                ylim(app.UIAxes2,[0,1])
                xlim(app.UIAxes2,[0,1])
                ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                th = text(app.UIAxes2,1,1,'Calculating kICS Correlation Function...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                for i=1:round(app.fractau*sizet)
                    app.timecorrnorm(:,:,i)=real(fftshift(timecorr(:,:,i)./timecorr(:,:,1) ));
                    corrfftslice = app.timecorrnorm(:,:,i);
                    [xg,yg] = consolidator(kSquaredCut,corrfftslice(kSquaredIndices),@mean,roundTol);
                    app.kBin(:,1,i) = xg;
                    app.kBin(:,2,i) = yg;
                    app.kBin(:,3,i) = real(log(yg));
                    clear corrfftslice
                    ph.XData = [0 i/round(app.fractau*sizet) i/round(app.fractau*sizet) 0];
                    th.String = sprintf('Calculating kICS Correlation Function...%.0f%%',round(i/round(app.fractau*sizet)*100));
                    drawnow %update graphics
                end
                app.timecorrnorm=app.timecorrnorm(:,:,2:end);
                app.CF=squeeze(app.kBin(:,2,:));
                app.kValues=squeeze(app.kBin(:,1,2));
                app.CF=app.CF(:,2:end);
                app.kBin=app.kBin(:,:,2:end);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % smooth CF if needed
                app.CFsmooth=[];
                if app.MovWindk2Status==1
                    for i=1:size(app.CF,2)
                        app.CFsmooth(:,i) = moving_average(squeeze(app.CF(:,i)),app.MovWindk2);
                    end
                elseif app.MovWindk2Status==0
                    app.CFsmooth=app.CF;
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % fit CF
                if strcmp(app.FitCFOption,'Linear Fit 1 Component')
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    ind1=find(app.kValues>app.mink2fit);
                    ind2=find(app.kValues<app.maxk2fit);
                    indgood=intersect(ind1,ind2);
                    rangek2=app.kValues(indgood);
                    CF=squeeze(log(app.CFsmooth(indgood,1:end)));
                    mycolors=jet(size(CF,2));
                    app.DtauF=[];
                    app.AmpF=[];
                    app.DtauS=[];
                    app.AmpS=[];
                    app.RatAmp=[];
                    cla(app.UIAxes2)
                    ylim(app.UIAxes2,[0,1])
                    xlim(app.UIAxes2,[0,1])
                    ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                    th = text(app.UIAxes2,1,1,'Linear Fit 1 Component of kICS CF...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                    for intau=1:size(CF,2)
                        CFtouse=CF(:,intau);
                        plot(app.UIAxes,rangek2,CFtouse,'.','Color',mycolors(intau,:))
                        hold(app.UIAxes,"on");
                        linfit=polyfit(rangek2,CFtouse,1);
                        plot(app.UIAxes,rangek2,linfit(1)*rangek2+linfit(2),'--','Color',mycolors(intau,:))
                        app.DtauF(intau)=-linfit(1);
                        app.AmpF(intau)=exp(linfit(2));
                        ph.XData = [0 intau/size(CF,2) intau/size(CF,2) 0];
                        th.String = sprintf('Linear Fit 1 Component of kICS CF...%.0f%%',round(intau/size(CF,2)*100));
                        drawnow %update graphics
                    end
                    title(app.UIAxes,'log(G) vs k^2 at given tau')
                    xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                    ylabel(app.UIAxes,'log G(k^{2},\tau)','FontSize',14)
                    delX=max(rangek2)-min(rangek2);
                    delY=log(app.maxG-app.minG);
                    app.UIAxes.YTick = log([app.minG:delY/2:app.maxG]);
                    app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)]);
                    ylim(app.UIAxes,log([app.minG app.maxG]))
                    xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                elseif strcmp(app.FitCFOption,'Linear Fit 2 Components')
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    minK=min(find(app.kValues>app.mink2fit));
                    maxK=min(find(app.kValues>app.maxk2fit));
                    inflectK=min(find(app.kValues>app.inflectk2));
                    CF=squeeze(log(app.CFsmooth(:,1:end)));
                    mycolors=jet(size(CF,2));
                    app.DtauF=[];
                    app.AmpF=[];
                    app.DtauS=[];
                    app.AmpS=[];
                    app.RatAmp=[];
                    cla(app.UIAxes2)
                    ylim(app.UIAxes2,[0,1])
                    xlim(app.UIAxes2,[0,1])
                    ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                    th = text(app.UIAxes2,1,1,'Linear Fit 2 Components of kICS CF...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                    for intau=1:size(CF,2)
                        plot(app.UIAxes,app.kValues(minK:inflectK),squeeze(CF(minK:inflectK,intau)),'.','Color',mycolors(intau,:))
                        hold(app.UIAxes,"on");
                        linfit=polyfit(app.kValues(minK:inflectK),squeeze(CF(minK:inflectK,intau)),1);
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,app.kValues(minK:inflectK),linfit(1)*app.kValues(minK:inflectK)+linfit(2),'--','Color',mycolors(intau,:))
                        app.DtauF(intau)=-linfit(1);
                        app.AmpF(intau)=exp(linfit(2));
                        % slow component
                        plot(app.UIAxes,app.kValues(inflectK:maxK),squeeze(CF(inflectK:maxK,intau)),'.','Color',mycolors(intau,:))
                        hold(app.UIAxes,"on");
                        linfit=polyfit(app.kValues(inflectK:maxK),squeeze(CF(inflectK:maxK,intau)),1);
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,app.kValues(inflectK:maxK),linfit(1)*app.kValues(inflectK:maxK)+linfit(2),'--','Color',mycolors(intau,:))
                        app.DtauS(intau)=-linfit(1);
                        app.AmpS(intau)=exp(linfit(2));
                        app.RatAmp(intau)=app.AmpF(intau)./app.AmpS(intau);
                        ph.XData = [0 intau/size(CF,2) intau/size(CF,2) 0];
                        th.String = sprintf('Linear Fit 2 Components of kICS CF...%.0f%%',round(intau/size(CF,2)*100));
                        drawnow %update graphics
                    end
                    title(app.UIAxes,'log(G) vs k^2 at given tau')
                    xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                    ylabel(app.UIAxes,'log G(k^{2},\tau)','FontSize',14)
                    delX=max(app.kValues(minK:maxK))-min(app.kValues(minK:maxK));
                    delY=log(app.maxG-app.minG);
                    app.UIAxes.YTick = log([app.minG:delY/2:app.maxG]);
                    app.UIAxes.XTick = round([min(app.kValues(minK:maxK)):delX/10:max(app.kValues(minK:maxK))]);
                    ylim(app.UIAxes,log([app.minG app.maxG]))
                    xlim(app.UIAxes,[min(app.kValues(minK:maxK)) max(app.kValues(minK:maxK))])
                elseif strcmp(app.FitCFOption,'Non-Linear Fit 1 Component')
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    ind1=find(app.kValues>app.mink2fit);
                    ind2=find(app.kValues<app.maxk2fit);
                    indgood=intersect(ind1,ind2);
                    rangek2=app.kValues(indgood);
                    title(app.UIAxes,'G vs k^2 at given tau')
                    xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                    ylabel(app.UIAxes,'G(k^{2},\tau)','FontSize',14)
                    delX=max(rangek2)-min(rangek2);
                    delY=app.maxG-app.minG;
                    app.UIAxes.YTick = [app.minG:delY/2:app.maxG];
                    app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)],1);
                    ylim(app.UIAxes,[app.minG app.maxG])
                    xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                    [results,fitted]=DiffusionLawBySingleGaussianFitvsKsqForGUI(app.UIAxes,app.UIAxes2,squeeze((app.CFsmooth(indgood,:))),(1:size(app.CF,2)),rangek2,app.timeFrame);
                    cfit=results{:,1};
                    app.DtauF=cfit.b;
                    app.AmpF=cfit.a;
                    app.DtauS=[];
                    app.AmpS=[];
                    app.RatAmp=[];
                elseif strcmp(app.FitCFOption,'Non-Linear Fit 2 Components')
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    ind1=find(app.kValues>app.mink2fit);
                    ind2=find(app.kValues<app.maxk2fit);
                    indgood=intersect(ind1,ind2);
                    rangek2=app.kValues(indgood);
                    title(app.UIAxes,'G vs k^2 at given tau')
                    xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                    ylabel(app.UIAxes,'G(k^{2},\tau)','FontSize',14)
                    delX=max(rangek2)-min(rangek2);
                    delY=app.maxG-app.minG;
                    app.UIAxes.YTick = [app.minG:delY/2:app.maxG];
                    app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)],1);
                    ylim(app.UIAxes,[app.minG app.maxG])
                    xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                    [results,fitted]=DiffusionLawByDoubleGaussianFitvsKsqForGUI(app.UIAxes,app.UIAxes2,squeeze((app.CFsmooth(indgood,:))),(1:size(app.CF,2)),rangek2,app.timeFrame);
                    cfit=results{:,1};
                    app.DtauF=cfit.b;
                    app.AmpF=cfit.a;
                    app.DtauS=cfit.d;
                    app.AmpS=cfit.c;
                    app.RatAmp=app.AmpF./app.AmpS;
                end %end fitting CF
                exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_FittingCFCell'  num2str(cells) '.png'])
                %%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%% linearly fit trends extract confinement parameters
                if strcmp(app.FitCFOption,'Linear Fit 2 Components') || strcmp(app.FitCFOption,'Non-Linear Fit 2 Components')
                    % 'early linear fit DmicroTau vs tau'
                    rangetau=(app.mintaufit3:app.maxtaufit3)*app.timeFrame;
                    rangeY=app.DtauS(app.mintaufit3:app.maxtaufit3);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    fitlin=polyfit(rangetau,rangeY,1);
                    app.Dmicro=fitlin(1);
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                    text(app.UIAxes,rangetau(1),0.95*max(rangeY),['D_{micro}=' num2str(round(app.Dmicro,4)) ' \mum^{2}s^{-1}'])
                    title(app.UIAxes,'Dtau Micro vs tau')
                    xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'D_{micro}\tau (\mum^{2})','FontSize',14)
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],5);
                    app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                    exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_EarlyLinearFitDmicroTauCell'  num2str(cells) '.png'])
                    % 'late linear fit DmicroTau vs tau'
                    rangetau=(app.mintaufit4:app.maxtaufit4)*app.timeFrame;
                    rangeY=app.DtauS(app.mintaufit4:app.maxtaufit4);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    fitlin=polyfit(rangetau,rangeY,1);
                    app.DmicroLate=fitlin(1);
                    app.MaxDispMicro=fitlin(2)^0.5;
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],5);
                    app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                    text(app.UIAxes,rangetau(1),0.85*max(rangeY),['D_{microLate}=' num2str(round(app.DmicroLate,4)) ' \mum^{2}s^{-1}'])
                    text(app.UIAxes,rangetau(1),0.95*max(rangeY),['Max. Displacement Micro=' num2str(round(app.MaxDispMicro,3)) ' \mum'])
                    title(app.UIAxes,'Dtau Micro vs tau')
                    xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'D_{micro}\tau (\mum^{2})','FontSize',14)
                    exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_LateLinearFitDmicroTauCell'  num2str(cells) '.png'])
                    % 'late linear fit AmpMacro/AmpMicro vs tau'
                    rangetau=(app.mintaufit5:app.maxtaufit5)*app.timeFrame;
                    rangeY=log(app.RatAmp(app.mintaufit5:app.maxtaufit5));
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    fitlin=polyfit(rangetau,rangeY,1);
                    app.k2=fitlin(1);
                    app.ratConc=exp(fitlin(1)*app.maxtaufit*app.timeFrame+fitlin(2));
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                    text(app.UIAxes,rangetau(1),0.85*max(rangeY),['k_{2}=' num2str(round(app.k2,3)) ' s^{-1}'])
                    text(app.UIAxes,rangetau(1),0.95*max(rangeY),['N_{macro}/N_{micro}=' num2str(round(app.ratConc,3)) ])
                    title(app.UIAxes,'N_{macro}/N_{micro} vs tau')
                    xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'N_{macro}/N_{micro}','FontSize',14)
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                    app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                    exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_LateLinearFitRatioAmpCell'  num2str(cells) '.png'])
                     % 'early linear fit DmacroTau vs tau'
                    rangetau=(app.mintaufit1:app.maxtaufit1)*app.timeFrame;
                    rangeY=app.DtauF(app.mintaufit1:app.maxtaufit1);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    fitlin=polyfit(rangetau,rangeY,1);
                    app.Dmacro=fitlin(1);
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                    text(app.UIAxes,rangetau(1),0.95*max(rangeY),['D_{macro}=' num2str(round(app.Dmacro,3)) ' \mum^{2}s^{-1}'])
                    title(app.UIAxes,'Dtau Macro vs tau')
                    xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'D_{macro}\tau (\mum^{2})','FontSize',14)
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/2:max(rangeY)],3);
                    app.UIAxes.XTick = round([min(rangetau):app.timeFrame:max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                    exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_EarlyLinearFitDmacroTauCell'  num2str(cells) '.png'])
                    % 'late linear fit DmacroTau vs tau'
                    rangetau=(app.mintaufit2:app.maxtaufit2)*app.timeFrame;
                    rangeY=app.DtauF(app.mintaufit2:app.maxtaufit2);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    fitlin=polyfit(rangetau,rangeY,1);
                    app.Deff=fitlin(1);
                    app.MaxDispMacro=fitlin(2)^0.5;
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                    text(app.UIAxes,rangetau(1),0.85*max(rangeY),['D_{eff}=' num2str(round(app.Deff,3)) ' \mum^{2}s^{-1}'])
                    text(app.UIAxes,rangetau(1),0.95*max(rangeY),['Max. Displacement Macro=' num2str(round(app.MaxDispMacro,2)) ' \mum'])
                    title(app.UIAxes,'Dtau Macro vs tau')
                    xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'D_{macro}\tau (\mum^{2})','FontSize',14)
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                    app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                    exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_LateLinearFitDmacroTauCell'  num2str(cells) '.png'])
                    % 'Anomalous Fit To DmacroTau vs tau'
                    rangetau=(app.mintaufit6:app.maxtaufit6)*app.timeFrame;
                    rangeY=app.DtauF(app.mintaufit6:app.maxtaufit6);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    rangetau=log(rangetau);
                    rangeY=log(rangeY);
                    fitlin=polyfit(rangetau,rangeY,1);
                    app.Danomalous=exp(fitlin(2));
                    app.alphaAnomalous=fitlin(1);
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                    text(app.UIAxes,rangetau(1),1.15*max(rangeY),['D_{anomalous}=' num2str(round(app.Danomalous,3)) ' \mum^{2}s^{-1}'])
                    text(app.UIAxes,rangetau(1),1.25*max(rangeY),['\alpha_{anomalous}=' num2str(round(app.alphaAnomalous,3))])
                    title(app.UIAxes,'Anomalous Fit of log(Dtau Macro) vs log(tau)')
                    xlabel(app.UIAxes,'log\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'logD_{macro}\tau (\mum^{2})','FontSize',14)
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                    app.UIAxes.XTick = round([min(rangetau):log(3*app.timeFrame):max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                    exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_AnomalousFitCell'  num2str(cells) '.png'])
                    [app.Dmacro app.Dmicro]
                else % if only single component was fitted to CF...
                    app.Dmicro=NaN;
                    app.DmicroLate=NaN;
                    app.MaxDispMicro=NaN;
                    app.k2=NaN;
                    app.ratConc=NaN;
                    % 'early linear fit DmacroTau vs tau'
                    rangetau=(app.mintaufit1:app.maxtaufit1)*app.timeFrame;
                    rangeY=app.DtauF(app.mintaufit1:app.maxtaufit1);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    fitlin=polyfit(rangetau,rangeY,1);
                    app.Dmacro=fitlin(1);
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                    text(app.UIAxes,rangetau(1),0.95*max(rangeY),['D_{macro}=' num2str(round(app.Dmacro,3)) ' \mum^{2}s^{-1}'])
                    title(app.UIAxes,'Dtau Macro vs tau')
                    xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'D_{macro}\tau (\mum^{2})','FontSize',14)
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                    app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                    exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_EarlyLinearFitDmacroTauCell'  num2str(cells) '.png'])
                    % 'late linear fit DmacroTau vs tau'
                    rangetau=(app.mintaufit2:app.maxtaufit2)*app.timeFrame;
                    rangeY=app.DtauF(app.mintaufit2:app.maxtaufit2);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    fitlin=polyfit(rangetau,rangeY,1);
                    app.Deff=fitlin(1);
                    app.MaxDispMacro=fitlin(2)^0.5;
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                    text(app.UIAxes,rangetau(1),0.85*max(rangeY),['D_{eff}=' num2str(round(app.Deff,3)) ' \mum^{2}s^{-1}'])
                    text(app.UIAxes,rangetau(1),0.95*max(rangeY),['Max. Displacement Macro=' num2str(round(app.MaxDispMacro,2)) ' \mum'])
                    title(app.UIAxes,'Dtau Macro vs tau')
                    xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'D_{macro}\tau (\mum^{2})','FontSize',14)
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                    app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                    exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_LateLinearFitDmacroTauCell'  num2str(cells) '.png'])
                    % 'Anomalous Fit To DmacroTau vs tau'
                    rangetau=(app.mintaufit6:app.maxtaufit6)*app.timeFrame;
                    rangeY=app.DtauF(app.mintaufit6:app.maxtaufit6);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    rangetau=log(rangetau);
                    rangeY=log(rangeY);
                    fitlin=polyfit(rangetau,rangeY,1);
                    app.Danomalous=exp(fitlin(2));
                    app.alphaAnomalous=fitlin(1);
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                    text(app.UIAxes,rangetau(1),1.15*max(rangeY),['D_{anomalous}=' num2str(round(app.Danomalous,3)) ' \mum^{2}s^{-1}'])
                    text(app.UIAxes,rangetau(1),1.25*max(rangeY),['\alpha_{anomalous}=' num2str(round(app.alphaAnomalous,3))])
                    title(app.UIAxes,'Anomalous Fit of log(Dtau Macro) vs log(tau)')
                    xlabel(app.UIAxes,'log\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'logD_{macro}\tau (\mum^{2})','FontSize',14)
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                    app.UIAxes.XTick = round([min(rangetau):log(3*app.timeFrame):max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                    exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_AnomalousFitCell'  num2str(cells) '.png'])
                end % fit if 2 components were present...
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Export results
                pixelSize=app.pixelSize;
                timeFrame=app.timeFrame;
                kBin=app.kBin;
                CF=app.CF;
                kValues=app.kValues;
                DtauF=app.DtauF;
                AmpF=app.AmpF;
                DtauS=app.DtauS;
                AmpS=app.AmpS;
                RatAmp=app.RatAmp;
                Dmacro=app.Dmacro;
                Deff=app.Deff;
                MaxDispMacro=app.MaxDispMacro;
                Dmicro=app.Dmicro;
                DmicroLate=app.DmicroLate;
                MaxDispMicro=app.MaxDispMicro;
                k2=app.k2;
                ratConc=app.ratConc;
                alphaAnomalous=app.alphaAnomalous;
                Danomalous=app.Danomalous;
                save([app.path app.file(1:end-4) '_OutputsCell' num2str(cells) '.mat'],'pixelSize','timeFrame','kBin','CF','kValues','DtauF','AmpF','DtauS',"AmpS","RatAmp","Dmacro","Deff","MaxDispMacro","Dmicro","DmicroLate","MaxDispMicro","k2","ratConc","alphaAnomalous","Danomalous")
                T=table(((1:length(DtauF))*app.timeFrame)',DtauF',AmpF',DtauS',AmpS',RatAmp');
                T.Properties.VariableNames ={'tau','DtauF','AmpF','DtauS','AmpS','RatAmp'};
                writetable(T,[app.path app.file(1:end-4) '_ConfinementResults.xlsx'],'Sheet',['MobAndAmpCell'  num2str(cells)]);
                T=table(Dmacro',Deff',MaxDispMacro',Dmicro',DmicroLate',MaxDispMicro',k2',ratConc',alphaAnomalous',Danomalous');
                T.Properties.VariableNames ={'Dmacro','Deff','MaxDispMacro','Dmicro','DmicroLate','MaxDispMicro','k2','ratConc','alphaAnomalous','Danomalous'};
                writetable(T,[app.path app.file(1:end-4) '_ConfinementResults.xlsx'],'Sheet',['ConfParamsCell' num2str(cells)]);
            end %end over all cells

            else %if no cells
            % make padded image around a cell
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % do temporal filtering if checked
                if app.toImmFilt==1
                    % filtering immobile component
                    app.timefilt = fft(double(app.croped2),[],3);
                    avPixInt = mean(mean(app.timefilt(:,:,1)))/size(app.timefilt,3);
                    app.timefilt(:,:,1) = 0;
                    app.timefilt = (real(ifft(app.timefilt,[],3))+avPixInt);
                    app.timefilt=single(app.timefilt);
                elseif app.toImmFilt==0
                    app.timefilt=app.croped2;
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Hann windowing
                if app.toHann==1
                    [app.hanned]=hann2DbetaPower(app.timefilt,app.HannPow);
                elseif app.toHann==0
                    app.hanned=app.timefilt;
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % compute kICS CF
                imgser=single(app.hanned);
                sizet=size(imgser,3);
                imageDim2=size(imgser,2);
                imageDim1=size(imgser,1);
                fftimser=fftn(imgser,[size(imgser,1) size(imgser,2) size(imgser,3)]);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % define k vectors
                maxk = (1/app.pixelSize)/2*(2*pi);
                stepkx = 1/(imageDim2*app.pixelSize)*(2*pi);
                stepky = 1/(imageDim1*app.pixelSize)*(2*pi);
                
                if ((mod(imageDim2,2)==0)&&(mod(imageDim1,2)==0))
                    [X,Y] = meshgrid(-maxk:stepkx:maxk-stepkx,-maxk:stepky:maxk-stepky);
                elseif ((mod(imageDim2,2)==1)&&(mod(imageDim1,2)==1))
                    [X,Y] = meshgrid(-stepkx*(imageDim2-1)/2:stepkx:stepkx*(imageDim2-1)/2,-stepky*(imageDim1-1)/2:stepky:stepky*(imageDim1-1)/2);
                elseif mod(imageDim2,2)==1
                    [X,Y] = meshgrid(-stepkx*(imageDim2-1)/2:stepkx:stepkx*(imageDim2-1)/2,-maxk:stepky:maxk-stepky);
                else
                    [X,Y] = meshgrid(-maxk:stepkx:maxk-stepkx,-stepky*(imageDim1-1)/2:stepky:stepky*(imageDim1-1)/2);
                end
                app.X=X;
                app.Y=Y;
                app.stepkx=stepkx;
                app.stepky=stepky;
                kSquared = (X.^2 + Y.^2);
                roundTol = 1e-11;
                kValues = consolidator(kSquared(:),[],'mean',roundTol);
                kSquaredIndices = find(kSquared(:)<app.k2Cutoff);
                kBin = zeros(sum(kValues<app.k2Cutoff),2,round(app.fractau*sizet));
                kSquaredCut = kSquared(kSquaredIndices);
                clear imgser corrfftslice
                app.timecorrnorm=[];
                app.kBin=[];
                corrfn=conj(fftimser).*fftimser;clear fftimser;
                timecorr=ifft(corrfn,[],3);clear corrfn
                cla(app.UIAxes2)
                ylim(app.UIAxes2,[0,1])
                xlim(app.UIAxes2,[0,1])
                ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                th = text(app.UIAxes2,1,1,'Calculating kICS Correlation Function...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                for i=1:round(app.fractau*sizet)
                    app.timecorrnorm(:,:,i)=real(fftshift(timecorr(:,:,i)./timecorr(:,:,1) ));
                    corrfftslice = app.timecorrnorm(:,:,i);
                    [xg,yg] = consolidator(kSquaredCut,corrfftslice(kSquaredIndices),@mean,roundTol);
                    app.kBin(:,1,i) = xg;
                    app.kBin(:,2,i) = yg;
                    app.kBin(:,3,i) = real(log(yg));
                    clear corrfftslice
                    ph.XData = [0 i/round(app.fractau*sizet) i/round(app.fractau*sizet) 0];
                    th.String = sprintf('Calculating kICS Correlation Function...%.0f%%',round(i/round(app.fractau*sizet)*100));
                    drawnow %update graphics
                end
                app.timecorrnorm=app.timecorrnorm(:,:,2:end);
                app.CF=squeeze(app.kBin(:,2,:));
                app.kValues=squeeze(app.kBin(:,1,2));
                app.CF=app.CF(:,2:end);
                app.kBin=app.kBin(:,:,2:end);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % smooth CF if needed
                app.CFsmooth=[];
                if app.MovWindk2Status==1
                    for i=1:size(app.CF,2)
                        app.CFsmooth(:,i) = moving_average(squeeze(app.CF(:,i)),app.MovWindk2);
                    end
                elseif app.MovWindk2Status==0
                    app.CFsmooth=app.CF;
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % fit CF
                if strcmp(app.FitCFOption,'Linear Fit 1 Component')
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    ind1=find(app.kValues>app.mink2fit);
                    ind2=find(app.kValues<app.maxk2fit);
                    indgood=intersect(ind1,ind2);
                    rangek2=app.kValues(indgood);
                    CF=squeeze(log(app.CFsmooth(indgood,1:end)));
                    mycolors=jet(size(CF,2));
                    app.DtauF=[];
                    app.AmpF=[];
                    app.DtauS=[];
                    app.AmpS=[];
                    app.RatAmp=[];
                    cla(app.UIAxes2)
                    ylim(app.UIAxes2,[0,1])
                    xlim(app.UIAxes2,[0,1])
                    ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                    th = text(app.UIAxes2,1,1,'Linear Fit 1 Component of kICS CF...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                    for intau=1:size(CF,2)
                        CFtouse=CF(:,intau);
                        plot(app.UIAxes,rangek2,CFtouse,'.','Color',mycolors(intau,:))
                        hold(app.UIAxes,"on");
                        linfit=polyfit(rangek2,CFtouse,1);
                        plot(app.UIAxes,rangek2,linfit(1)*rangek2+linfit(2),'--','Color',mycolors(intau,:))
                        app.DtauF(intau)=-linfit(1);
                        app.AmpF(intau)=exp(linfit(2));
                        ph.XData = [0 intau/size(CF,2) intau/size(CF,2) 0];
                        th.String = sprintf('Linear Fit 1 Component of kICS CF...%.0f%%',round(intau/size(CF,2)*100));
                        drawnow %update graphics
                    end
                    title(app.UIAxes,'log(G) vs k^2 at given tau')
                    xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                    ylabel(app.UIAxes,'log G(k^{2},\tau)','FontSize',14)
                    delX=max(rangek2)-min(rangek2);
                    delY=log(app.maxG-app.minG);
                    app.UIAxes.YTick = log([app.minG:delY/2:app.maxG]);
                    app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)]);
                    ylim(app.UIAxes,log([app.minG app.maxG]))
                    xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                elseif strcmp(app.FitCFOption,'Linear Fit 2 Components')
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    minK=min(find(app.kValues>app.mink2fit));
                    maxK=min(find(app.kValues>app.maxk2fit));
                    inflectK=min(find(app.kValues>app.inflectk2));
                    CF=squeeze(log(app.CFsmooth(:,1:end)));
                    mycolors=jet(size(CF,2));
                    app.DtauF=[];
                    app.AmpF=[];
                    app.DtauS=[];
                    app.AmpS=[];
                    app.RatAmp=[];
                    cla(app.UIAxes2)
                    ylim(app.UIAxes2,[0,1])
                    xlim(app.UIAxes2,[0,1])
                    ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                    th = text(app.UIAxes2,1,1,'Linear Fit 2 Components of kICS CF...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                    for intau=1:size(CF,2)
                        plot(app.UIAxes,app.kValues(minK:inflectK),squeeze(CF(minK:inflectK,intau)),'.','Color',mycolors(intau,:))
                        hold(app.UIAxes,"on");
                        linfit=polyfit(app.kValues(minK:inflectK),squeeze(CF(minK:inflectK,intau)),1);
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,app.kValues(minK:inflectK),linfit(1)*app.kValues(minK:inflectK)+linfit(2),'--','Color',mycolors(intau,:))
                        app.DtauF(intau)=-linfit(1);
                        app.AmpF(intau)=exp(linfit(2));
                        % slow component
                        plot(app.UIAxes,app.kValues(inflectK:maxK),squeeze(CF(inflectK:maxK,intau)),'.','Color',mycolors(intau,:))
                        hold(app.UIAxes,"on");
                        linfit=polyfit(app.kValues(inflectK:maxK),squeeze(CF(inflectK:maxK,intau)),1);
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,app.kValues(inflectK:maxK),linfit(1)*app.kValues(inflectK:maxK)+linfit(2),'--','Color',mycolors(intau,:))
                        app.DtauS(intau)=-linfit(1);
                        app.AmpS(intau)=exp(linfit(2));
                        app.RatAmp(intau)=app.AmpF(intau)./app.AmpS(intau);
                        ph.XData = [0 intau/size(CF,2) intau/size(CF,2) 0];
                        th.String = sprintf('Linear Fit 2 Components of kICS CF...%.0f%%',round(intau/size(CF,2)*100));
                        drawnow %update graphics
                    end
                    title(app.UIAxes,'log(G) vs k^2 at given tau')
                    xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                    ylabel(app.UIAxes,'log G(k^{2},\tau)','FontSize',14)
                    delX=max(app.kValues(minK:maxK))-min(app.kValues(minK:maxK));
                    delY=log(app.maxG-app.minG);
                    app.UIAxes.YTick = log([app.minG:delY/2:app.maxG]);
                    app.UIAxes.XTick = round([min(app.kValues(minK:maxK)):delX/10:max(app.kValues(minK:maxK))]);
                    ylim(app.UIAxes,log([app.minG app.maxG]))
                    xlim(app.UIAxes,[min(app.kValues(minK:maxK)) max(app.kValues(minK:maxK))])
                elseif strcmp(app.FitCFOption,'Non-Linear Fit 1 Component')
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    ind1=find(app.kValues>app.mink2fit);
                    ind2=find(app.kValues<app.maxk2fit);
                    indgood=intersect(ind1,ind2);
                    rangek2=app.kValues(indgood);
                    title(app.UIAxes,'G vs k^2 at given tau')
                    xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                    ylabel(app.UIAxes,'G(k^{2},\tau)','FontSize',14)
                    delX=max(rangek2)-min(rangek2);
                    delY=app.maxG-app.minG;
                    app.UIAxes.YTick = [app.minG:delY/2:app.maxG];
                    app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)],1);
                    ylim(app.UIAxes,[app.minG app.maxG])
                    xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                    [results,fitted]=DiffusionLawBySingleGaussianFitvsKsqForGUI(app.UIAxes,app.UIAxes2,squeeze((app.CFsmooth(indgood,:))),(1:size(app.CF,2)),rangek2,app.timeFrame);
                    cfit=results{:,1};
                    app.DtauF=cfit.b;
                    app.AmpF=cfit.a;
                    app.DtauS=[];
                    app.AmpS=[];
                    app.RatAmp=[];
                elseif strcmp(app.FitCFOption,'Non-Linear Fit 2 Components')
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    ind1=find(app.kValues>app.mink2fit);
                    ind2=find(app.kValues<app.maxk2fit);
                    indgood=intersect(ind1,ind2);
                    rangek2=app.kValues(indgood);
                    title(app.UIAxes,'G vs k^2 at given tau')
                    xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                    ylabel(app.UIAxes,'G(k^{2},\tau)','FontSize',14)
                    delX=max(rangek2)-min(rangek2);
                    delY=app.maxG-app.minG;
                    app.UIAxes.YTick = [app.minG:delY/2:app.maxG];
                    app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)],1);
                    ylim(app.UIAxes,[app.minG app.maxG])
                    xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                    [results,fitted]=DiffusionLawByDoubleGaussianFitvsKsqForGUI(app.UIAxes,app.UIAxes2,squeeze((app.CFsmooth(indgood,:))),(1:size(app.CF,2)),rangek2,app.timeFrame);
                    cfit=results{:,1};
                    app.DtauF=cfit.b;
                    app.AmpF=cfit.a;
                    app.DtauS=cfit.d;
                    app.AmpS=cfit.c;
                    app.RatAmp=app.AmpF./app.AmpS;
                end %end fitting CF
                exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_FittingCF.png'])
                %%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%% linearly fit trends extract confinement parameters
                if strcmp(app.FitCFOption,'Linear Fit 2 Components') || strcmp(app.FitCFOption,'Non-Linear Fit 2 Components')
                    % 'early linear fit DmicroTau vs tau'
                    rangetau=(app.mintaufit3:app.maxtaufit3)*app.timeFrame;
                    rangeY=app.DtauS(app.mintaufit3:app.maxtaufit3);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    fitlin=polyfit(rangetau,rangeY,1);
                    app.Dmicro=fitlin(1);
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                    text(app.UIAxes,rangetau(1),0.95*max(rangeY),['D_{micro}=' num2str(round(app.Dmicro,4)) ' \mum^{2}s^{-1}'])
                    title(app.UIAxes,'Dtau Micro vs tau')
                    xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'D_{micro}\tau (\mum^{2})','FontSize',14)
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],5);
                    app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                    exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_EarlyLinearFitDmicroTauC.png'])
                    % 'late linear fit DmicroTau vs tau'
                    rangetau=(app.mintaufit4:app.maxtaufit4)*app.timeFrame;
                    rangeY=app.DtauS(app.mintaufit4:app.maxtaufit4);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    fitlin=polyfit(rangetau,rangeY,1);
                    app.DmicroLate=fitlin(1);
                    app.MaxDispMicro=fitlin(2)^0.5;
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],5);
                    app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                    text(app.UIAxes,rangetau(1),0.85*max(rangeY),['D_{microLate}=' num2str(round(app.DmicroLate,4)) ' \mum^{2}s^{-1}'])
                    text(app.UIAxes,rangetau(1),0.95*max(rangeY),['Max. Displacement Micro=' num2str(round(app.MaxDispMicro,3)) ' \mum'])
                    title(app.UIAxes,'Dtau Micro vs tau')
                    xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'D_{micro}\tau (\mum^{2})','FontSize',14)
                    exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_LateLinearFitDmicroTauCell.png'])
                    % 'late linear fit AmpMacro/AmpMicro vs tau'
                    rangetau=(app.mintaufit5:app.maxtaufit5)*app.timeFrame;
                    rangeY=log(app.RatAmp(app.mintaufit5:app.maxtaufit5));
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    fitlin=polyfit(rangetau,rangeY,1);
                    app.k2=fitlin(1);
                    app.ratConc=exp(fitlin(1)*app.maxtaufit*app.timeFrame+fitlin(2));
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                    text(app.UIAxes,rangetau(1),0.85*max(rangeY),['k_{2}=' num2str(round(app.k2,3)) ' s^{-1}'])
                    text(app.UIAxes,rangetau(1),0.95*max(rangeY),['N_{macro}/N_{micro}=' num2str(round(app.ratConc,3)) ])
                    title(app.UIAxes,'N_{macro}/N_{micro} vs tau')
                    xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'N_{macro}/N_{micro}','FontSize',14)
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                    app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                    exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_LateLinearFitRatioAmpCell.png'])
                     % 'early linear fit DmacroTau vs tau'
                    rangetau=(app.mintaufit1:app.maxtaufit1)*app.timeFrame;
                    rangeY=app.DtauF(app.mintaufit1:app.maxtaufit1);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    fitlin=polyfit(rangetau,rangeY,1);
                    app.Dmacro=fitlin(1);
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                    text(app.UIAxes,rangetau(1),0.95*max(rangeY),['D_{macro}=' num2str(round(app.Dmacro,3)) ' \mum^{2}s^{-1}'])
                    title(app.UIAxes,'Dtau Macro vs tau')
                    xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'D_{macro}\tau (\mum^{2})','FontSize',14)
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/2:max(rangeY)],3);
                    app.UIAxes.XTick = round([min(rangetau):app.timeFrame:max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                    exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_EarlyLinearFitDmacroTauCell.png'])
                    % 'late linear fit DmacroTau vs tau'
                    rangetau=(app.mintaufit2:app.maxtaufit2)*app.timeFrame;
                    rangeY=app.DtauF(app.mintaufit2:app.maxtaufit2);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    fitlin=polyfit(rangetau,rangeY,1);
                    app.Deff=fitlin(1);
                    app.MaxDispMacro=fitlin(2)^0.5;
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                    text(app.UIAxes,rangetau(1),0.85*max(rangeY),['D_{eff}=' num2str(round(app.Deff,3)) ' \mum^{2}s^{-1}'])
                    text(app.UIAxes,rangetau(1),0.95*max(rangeY),['Max. Displacement Macro=' num2str(round(app.MaxDispMacro,2)) ' \mum'])
                    title(app.UIAxes,'Dtau Macro vs tau')
                    xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'D_{macro}\tau (\mum^{2})','FontSize',14)
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                    app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                    exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_LateLinearFitDmacroTauCell.png'])
                    % 'Anomalous Fit To DmacroTau vs tau'
                    rangetau=(app.mintaufit6:app.maxtaufit6)*app.timeFrame;
                    rangeY=app.DtauF(app.mintaufit6:app.maxtaufit6);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    rangetau=log(rangetau);
                    rangeY=log(rangeY);
                    fitlin=polyfit(rangetau,rangeY,1);
                    app.Danomalous=exp(fitlin(2));
                    app.alphaAnomalous=fitlin(1);
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                    text(app.UIAxes,rangetau(1),1.15*max(rangeY),['D_{anomalous}=' num2str(round(app.Danomalous,3)) ' \mum^{2}s^{-1}'])
                    text(app.UIAxes,rangetau(1),1.25*max(rangeY),['\alpha_{anomalous}=' num2str(round(app.alphaAnomalous,3))])
                    title(app.UIAxes,'Anomalous Fit of log(Dtau Macro) vs log(tau)')
                    xlabel(app.UIAxes,'log\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'logD_{macro}\tau (\mum^{2})','FontSize',14)
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                    app.UIAxes.XTick = round([min(rangetau):log(3*app.timeFrame):max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                    exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_AnomalousFitCell.png'])
                    %[app.Dmacro app.Dmicro]
                else % if only single component was fitted to CF...
                    app.Dmicro=NaN;
                    app.DmicroLate=NaN;
                    app.MaxDispMicro=NaN;
                    app.k2=NaN;
                    app.ratConc=NaN;
                    % 'early linear fit DmacroTau vs tau'
                    rangetau=(app.mintaufit1:app.maxtaufit1)*app.timeFrame;
                    rangeY=app.DtauF(app.mintaufit1:app.maxtaufit1);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    fitlin=polyfit(rangetau,rangeY,1);
                    app.Dmacro=fitlin(1);
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                    text(app.UIAxes,rangetau(1),0.95*max(rangeY),['D_{macro}=' num2str(round(app.Dmacro,3)) ' \mum^{2}s^{-1}'])
                    title(app.UIAxes,'Dtau Macro vs tau')
                    xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'D_{macro}\tau (\mum^{2})','FontSize',14)
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                    app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                    exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_EarlyLinearFitDmacroTauCell.png'])
                    % 'late linear fit DmacroTau vs tau'
                    rangetau=(app.mintaufit2:app.maxtaufit2)*app.timeFrame;
                    rangeY=app.DtauF(app.mintaufit2:app.maxtaufit2);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    fitlin=polyfit(rangetau,rangeY,1);
                    app.Deff=fitlin(1);
                    app.MaxDispMacro=fitlin(2)^0.5;
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                    text(app.UIAxes,rangetau(1),0.85*max(rangeY),['D_{eff}=' num2str(round(app.Deff,3)) ' \mum^{2}s^{-1}'])
                    text(app.UIAxes,rangetau(1),0.95*max(rangeY),['Max. Displacement Macro=' num2str(round(app.MaxDispMacro,2)) ' \mum'])
                    title(app.UIAxes,'Dtau Macro vs tau')
                    xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'D_{macro}\tau (\mum^{2})','FontSize',14)
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                    app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                    exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_LateLinearFitDmacroTauCell.png'])
                    % 'Anomalous Fit To DmacroTau vs tau'
                    rangetau=(app.mintaufit6:app.maxtaufit6)*app.timeFrame;
                    rangeY=app.DtauF(app.mintaufit6:app.maxtaufit6);
                    indgood=zeros(size(rangeY));
                    for i=1:length(rangeY)
                        if isreal(rangeY(i))
                            indgood(i)=1;
                        end
                    end
                    indgood=find(indgood);
                    rangeY=rangeY(indgood);
                    rangetau=rangetau(indgood);
                    rangetau=log(rangetau);
                    rangeY=log(rangeY);
                    fitlin=polyfit(rangetau,rangeY,1);
                    app.Danomalous=exp(fitlin(2));
                    app.alphaAnomalous=fitlin(1);
                    cla(app.UIAxes)
                    title(app.UIAxes,'');
                    xlabel(app.UIAxes,'');
                    ylabel(app.UIAxes,'');
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    app.UIAxes.YDir='normal';
                    plot(app.UIAxes,rangetau,rangeY,'o')
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                    text(app.UIAxes,rangetau(1),1.15*max(rangeY),['D_{anomalous}=' num2str(round(app.Danomalous,3)) ' \mum^{2}s^{-1}'])
                    text(app.UIAxes,rangetau(1),1.25*max(rangeY),['\alpha_{anomalous}=' num2str(round(app.alphaAnomalous,3))])
                    title(app.UIAxes,'Anomalous Fit of log(Dtau Macro) vs log(tau)')
                    xlabel(app.UIAxes,'log\tau (s)','FontSize',14)
                    ylabel(app.UIAxes,'logD_{macro}\tau (\mum^{2})','FontSize',14)
                    delY=max(rangeY)-min(rangeY);
                    app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                    app.UIAxes.XTick = round([min(rangetau):log(3*app.timeFrame):max(rangetau)],3);
                    ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                    xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                    exportgraphics(app.UIAxes,[app.path app.file(1:end-4) '_AnomalousFitCell.png'])
                end % fit if 2 components were present...
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Export results
                pixelSize=app.pixelSize;
                timeFrame=app.timeFrame;
                kBin=app.kBin;
                CF=app.CF;
                kValues=app.kValues;
                DtauF=app.DtauF;
                AmpF=app.AmpF;
                DtauS=app.DtauS;
                AmpS=app.AmpS;
                RatAmp=app.RatAmp;
                Dmacro=app.Dmacro;
                Deff=app.Deff;
                MaxDispMacro=app.MaxDispMacro;
                Dmicro=app.Dmicro;
                DmicroLate=app.DmicroLate;
                MaxDispMicro=app.MaxDispMicro;
                k2=app.k2;
                ratConc=app.ratConc;
                alphaAnomalous=app.alphaAnomalous;
                Danomalous=app.Danomalous;
                save([app.path app.file(1:end-4) '_Outputs.mat'],'pixelSize','timeFrame','kBin','CF','kValues','DtauF','AmpF','DtauS',"AmpS","RatAmp","Dmacro","Deff","MaxDispMacro","Dmicro","DmicroLate","MaxDispMicro","k2","ratConc","alphaAnomalous","Danomalous")
                T=table(((1:length(DtauF))*app.timeFrame)',DtauF',AmpF',DtauS',AmpS',RatAmp');
                T.Properties.VariableNames ={'tau','DtauF','AmpF','DtauS','AmpS','RatAmp'};
                writetable(T,[app.path app.file(1:end-4) '_ConfinementResults.xlsx'],'Sheet',['MobAndAmp']);
                T=table(Dmacro',Deff',MaxDispMacro',Dmicro',DmicroLate',MaxDispMicro',k2',ratConc',alphaAnomalous',Danomalous');
                T.Properties.VariableNames ={'Dmacro','Deff','MaxDispMacro','Dmicro','DmicroLate','MaxDispMicro','k2','ratConc','alphaAnomalous','Danomalous'};
                writetable(T,[app.path app.file(1:end-4) '_ConfinementResults.xlsx'],'Sheet',['ConfParams']);
            
            end
            
        end

        % Button pushed function: SaveAnalysisParametersFileButton
        function SaveAnalysisParametersFileButtonPushed(app, event)
            pixelSize=app.pixelSize;
            timeFrame=app.timeFrame;
            fractau=app.fractau;
            k2Cutoff=app.k2Cutoff;
            sliderShape=app.sliderShape;
            MorphChoice=app.MorphChoice;
            LBcellArea=app.LBcellArea;
            toPad=app.toPad;
            toImmFilt=app.toImmFilt;
            HannPow=app.HannPow;
            toHann=app.toHann;
            MovWindk2=app.MovWindk2;
            MovWindk2Status=app.MovWindk2Status;
            FitCFOption=app.FitCFOption;
            mink2fit=app.mink2fit;
            maxk2fit=app.maxk2fit;
            inflectk2=app.inflectk2;
            mintaufit1=app.mintaufit1;
            maxtaufit1=app.maxtaufit1;
            mintaufit2=app.mintaufit2;
            maxtaufit2=app.maxtaufit2;
            mintaufit3=app.mintaufit3;
            maxtaufit3=app.maxtaufit3;
            mintaufit4=app.mintaufit4;
            maxtaufit4=app.maxtaufit4;
            mintaufit5=app.mintaufit5;
            maxtaufit5=app.maxtaufit5;
            mintaufit6=app.mintaufit6;
            maxtaufit6=app.maxtaufit6;
            TempAlign=app.TempAlign;
            save([app.path app.file(1:end-4) '_ParameterFile.mat'],'pixelSize','timeFrame','fractau','k2Cutoff','sliderShape','MorphChoice','LBcellArea','toPad','toImmFilt','HannPow','toHann','MovWindk2','MovWindk2Status','FitCFOption','mink2fit','maxk2fit','inflectk2','mintaufit1','mintaufit2','mintaufit3','mintaufit4','mintaufit5','mintaufit6','maxtaufit1','maxtaufit2','maxtaufit3','maxtaufit4','maxtaufit5','maxtaufit6','TempAlign')
        end

        % Button pushed function: LoadAnalysisParametersFileButton
        function LoadAnalysisParametersFileButtonPushed(app, event)
            filterspec = {'*.mat','Select Parameter file to load'};
            [f, p] = uigetfile(filterspec);
            load([p f]);
            app.MaxTauFractionEditField.Value=fractau;
            app.fractau=fractau;
            app.maxk2EditField.Value=k2Cutoff;
            app.k2Cutoff=k2Cutoff;
            app.ThresholdCellShapeSlider.Value=sliderShape*10;
            app.sliderShape=sliderShape;
            app.MorphologicalOperationsonBinaryDropDown.Value=MorphChoice;
            app.MorphChoice=MorphChoice;
            app.LBCellAreaSlider.Value=LBcellArea;
            app.LBcellArea=LBcellArea;
            app.toPad=toPad;
            app.TemporalImmobileFilteringButton_2.Value=toImmFilt;
            app.toImmFilt=toImmFilt;
            app.HannPowerEditField.Value=HannPow;
            app.HannPow=HannPow;
            app.SpatialHannWindowingButton_2.Value=toHann;
            app.toHann=toHann;
            app.MovingAverageSmoothingWindowEditField.Value=MovWindk2;
            app.MovWindk2=MovWindk2;
            app.SmoothCFAlongk2Button.Value=MovWindk2Status;
            app.MovWindk2Status=MovWindk2Status;
            app.ChooseCFFittingOptionsDropDown.Value=FitCFOption;
            app.FitCFOption=FitCFOption;
            app.Mink2FitSlider.Limits = [0 500];
            app.Maxk2FitSlider.Limits = [0 500];
            app.Mink2FitSlider.Value=mink2fit;
            app.mink2fit=mink2fit;
            app.Maxk2FitSlider.Value=maxk2fit;
            app.maxk2fit=maxk2fit;
            app.InflectionPointk2fit0ifsingleComponentFitEditField.Value=inflectk2;
            app.inflectk2=inflectk2;
            app.mintaufit1=mintaufit1;
            app.mintaufit2=mintaufit2;
            app.mintaufit3=mintaufit3;
            app.mintaufit4=mintaufit4;
            app.mintaufit5=mintaufit5;
            app.mintaufit6=mintaufit6;
            app.maxtaufit1=maxtaufit1;
            app.maxtaufit2=maxtaufit2;
            app.maxtaufit3=maxtaufit3;
            app.maxtaufit4=maxtaufit4;
            app.maxtaufit5=maxtaufit5;
            app.maxtaufit6=maxtaufit6;
            app.TemporallyAlignSeriestoTimePoint0Button_2.Value=TempAlign;
            app.TempAlign=TempAlign;
        end

        % Button pushed function: BatchSeveralFilesUsingSameSettingsButton
        function BatchSeveralFilesUsingSameSettingsButtonPushed(app, event)
            [files, app.path] = uigetfile('*.czi;*.nd2;*.oir;*.oif;*.lif','Select all OME files to process','MultiSelect','on');
            if ~isempty(app.LabelCell)
            % process files one by one
            for f=1:length(files)
                %load file
                [app.images,specs]=readOMEFile_forGUI(app.UIAxes2,app.path,files{f});
                app.pixelSize=specs.pixelSize;
                app.timeFrame=specs.timeFrame;
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % temporally align data if needed
                if app.TempAlign==1
                    app.croped=[];
                    scale=0.1;
                    [optimizer, metric] = imregconfig('multimodal');
                    optimizer.InitialRadius = 0.009;
                    optimizer.Epsilon = 1.5e-4;
                    optimizer.GrowthFactor = 1.01;
                    optimizer.MaximumIterations = 300;;
                    ref=app.croped(:,:,1);
                    app.croped=single(zeros(size(app.images)));
                    app.croped(:,:,1)=app.images(:,:,1);
                    fixed=imresize(app.croped(:,:,1),scale);
                    [fixed,h2]=smoothSeries(fixed,10,'g','p');
                    cla(app.UIAxes2)
                    ylim(app.UIAxes2,[0,1])
                    xlim(app.UIAxes2,[0,1])
                    ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                    th = text(app.UIAxes2,1,1,'Aligning Images To t0...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                    for i=2:size(app.images,3)
                        
                        moving=imresize(app.images(:,:,i),scale);
                        [moving,h2]=smoothSeries(moving,10,'g','p');
                        tform = imregtform(moving, fixed, 'translation', optimizer, metric);
                        tform.T(3,1:2)=tform.T(3,1:2)*(1/scale);
                        movingRegistered = imwarp(app.images(:,:,i),tform,'OutputView',imref2d(size(ref)));
                        app.croped(:,:,i)=movingRegistered;
                        ph.XData = [0 i/size(app.images,3)  i/size(app.images,3) 0];
                        th.String = sprintf('Aligning Images To t0...%.0f%%',round(i/size(app.images,3)*100));
                        drawnow %update graphics
                    end
                else
                    app.croped=app.images;
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % find binary of cells
                app.aveim=mean(app.croped,3);
                normim1=app.aveim/max(app.aveim(:));
                app.binaryShape=normim1>app.sliderShape;
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % clean binary
                if strcmp(app.MorphChoice,'clean')
                    app.binary2=bwmorph(app.binaryShape,'clean');
                elseif strcmp(app.MorphChoice,'clean+open')
                    app.binary2=bwmorph(app.binaryShape,'clean');
                    app.binary2=bwmorph(app.binary2,'open');
                    app.binary2=bwmorph(app.binary2,'clean');
                elseif strcmp(app.MorphChoice,'clean+close')
                    app.binary2=bwmorph(app.binaryShape,'clean');
                    app.binary2=bwmorph(app.binary2,'close');
                    app.binary2=bwmorph(app.binary2,'clean');
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % threshold cell area by size
                stats=regionprops(app.binary2,'Area');
                maxArea=max([stats.Area]);
                idx = find([stats.Area]>app.LBcellArea*maxArea);
                cc = bwconncomp(app.binary2);
                BW = ismember(labelmatrix(cc),idx);
                app.LabelCell=bwlabel(BW);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Output cell mask
                cla(app.UIAxes)
                title(app.UIAxes,'')
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                stats=regionprops(app.LabelCell,'Centroid');
                rgb=label2rgb(app.LabelCell,'jet','k','shuffle');
                xy=[stats.Centroid];
                imagesc(app.UIAxes,rgb)
                hold(app.UIAxes,"on");
                for cel=1:size(stats,1)
                    text(app.UIAxes,xy(1+(cel-1)*2),xy(2*cel),num2str(cel),'Color',[1 1 1])
                    hold(app.UIAxes,"on");
                end
                app.UIAxes.YDir='reverse';
                xlim(app.UIAxes,[1 size(app.LabelCell,2)])
                ylim(app.UIAxes,[1 size(app.LabelCell,1)])
                set(app.UIAxes,'CLim',[0 1])
                exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_CellMaskCell.png'])
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%% now for every cell in the image proceed with
                %%% analysis...
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                for cells=1:max(app.LabelCell(:))
                    % make padded image around a cell
                    app.CellMask=app.LabelCell==cells;
                    [app.croped2,mask2]=cropSeriesIntoSquareWithinRangeofBinary(app.croped,app.CellMask);
                    app.CellMask=mask2;
                    masks=repmat(app.CellMask,[1 1 app.TotImages]);
                    app.padded=app.croped2.*masks+mean(mean(mean(nonzeros(app.croped2.*masks)))).*(~masks);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % do temporal filtering if checked
                    if app.toImmFilt==1
                        % filtering immobile component
                        app.timefilt = fft(double(app.padded),[],3);
                        avPixInt = mean(mean(app.timefilt(:,:,1)))/size(app.timefilt,3);
                        app.timefilt(:,:,1) = 0;
                        app.timefilt = (real(ifft(app.timefilt,[],3))+avPixInt);
                        app.timefilt=single(app.timefilt);
                    elseif app.toImmFilt==0
                        app.timefilt=app.padded;
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Hann windowing
                    if app.toHann==1
                        [app.hanned]=hann2DbetaPower(app.timefilt,app.HannPow);
                    elseif app.toHann==0
                        app.hanned=app.timefilt;
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % compute kICS CF
                    imgser=single(app.hanned);
                    sizet=size(imgser,3);
                    imageDim2=size(imgser,2);
                    imageDim1=size(imgser,1);
                    fftimser=fftn(imgser,[size(imgser,1) size(imgser,2) size(imgser,3)]);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % define k vectors
                    maxk = (1/app.pixelSize)/2*(2*pi);
                    stepkx = 1/(imageDim2*app.pixelSize)*(2*pi);
                    stepky = 1/(imageDim1*app.pixelSize)*(2*pi);
                    
                    if ((mod(imageDim2,2)==0)&&(mod(imageDim1,2)==0))
                        [X,Y] = meshgrid(-maxk:stepkx:maxk-stepkx,-maxk:stepky:maxk-stepky);
                    elseif ((mod(imageDim2,2)==1)&&(mod(imageDim1,2)==1))
                        [X,Y] = meshgrid(-stepkx*(imageDim2-1)/2:stepkx:stepkx*(imageDim2-1)/2,-stepky*(imageDim1-1)/2:stepky:stepky*(imageDim1-1)/2);
                    elseif mod(imageDim2,2)==1
                        [X,Y] = meshgrid(-stepkx*(imageDim2-1)/2:stepkx:stepkx*(imageDim2-1)/2,-maxk:stepky:maxk-stepky);
                    else
                        [X,Y] = meshgrid(-maxk:stepkx:maxk-stepkx,-stepky*(imageDim1-1)/2:stepky:stepky*(imageDim1-1)/2);
                    end
                    app.X=X;
                    app.Y=Y;
                    app.stepkx=stepkx;
                    app.stepky=stepky;
                    kSquared = (X.^2 + Y.^2);
                    roundTol = 1e-11;
                    kValues = consolidator(kSquared(:),[],'mean',roundTol);
                    kSquaredIndices = find(kSquared(:)<app.k2Cutoff);
                    kBin = zeros(sum(kValues<app.k2Cutoff),2,round(app.fractau*sizet));
                    kSquaredCut = kSquared(kSquaredIndices);
                    clear imgser corrfftslice
                    app.timecorrnorm=[];
                    app.kBin=[];
                    corrfn=conj(fftimser).*fftimser;clear fftimser;
                    timecorr=ifft(corrfn,[],3);clear corrfn
                    cla(app.UIAxes2)
                    ylim(app.UIAxes2,[0,1])
                    xlim(app.UIAxes2,[0,1])
                    ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                    th = text(app.UIAxes2,1,1,'Calculating kICS Correlation Function...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                    for i=1:round(app.fractau*sizet)
                        app.timecorrnorm(:,:,i)=real(fftshift(timecorr(:,:,i)./timecorr(:,:,1) ));
                        corrfftslice = app.timecorrnorm(:,:,i);
                        [xg,yg] = consolidator(kSquaredCut,corrfftslice(kSquaredIndices),@mean,roundTol);
                        app.kBin(:,1,i) = xg;
                        app.kBin(:,2,i) = yg;
                        app.kBin(:,3,i) = real(log(yg));
                        clear corrfftslice
                        ph.XData = [0 i/round(app.fractau*sizet) i/round(app.fractau*sizet) 0];
                        th.String = sprintf('Calculating kICS Correlation Function...%.0f%%',round(i/round(app.fractau*sizet)*100));
                        drawnow %update graphics
                    end
                    app.timecorrnorm=app.timecorrnorm(:,:,2:end);
                    app.CF=squeeze(app.kBin(:,2,:));
                    app.kValues=squeeze(app.kBin(:,1,2));
                    app.CF=app.CF(:,2:end);
                    app.kBin=app.kBin(:,:,2:end);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % smooth CF if needed
                    app.CFsmooth=[];
                    if app.MovWindk2Status==1
                        for i=1:size(app.CF,2)
                            app.CFsmooth(:,i) = moving_average(squeeze(app.CF(:,i)),app.MovWindk2);
                        end
                    elseif app.MovWindk2Status==0
                        app.CFsmooth=app.CF;
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % fit CF
                    if strcmp(app.FitCFOption,'Linear Fit 1 Component')
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        ind1=find(app.kValues>app.mink2fit);
                        ind2=find(app.kValues<app.maxk2fit);
                        indgood=intersect(ind1,ind2);
                        rangek2=app.kValues(indgood);
                        CF=app.CFsmooth(indgood,:);
                        CF=CF+abs(min(CF))+10^-12;
                        CF=squeeze(log(CF));
                        mycolors=jet(size(CF,2));
                        app.DtauF=[];
                        app.AmpF=[];
                        app.DtauS=[];
                        app.AmpS=[];
                        app.RatAmp=[];
                        cla(app.UIAxes2)
                        ylim(app.UIAxes2,[0,1])
                        xlim(app.UIAxes2,[0,1])
                        ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                        th = text(app.UIAxes2,1,1,'Linear Fit 1 Component of kICS CF...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                        for intau=1:size(CF,2)
                            CFtouse=CF(:,intau);
                            plot(app.UIAxes,rangek2,CFtouse,'.','Color',mycolors(intau,:))
                            hold(app.UIAxes,"on");
                            linfit=polyfit(rangek2,CFtouse,1);
                            plot(app.UIAxes,rangek2,linfit(1)*rangek2+linfit(2),'--','Color',mycolors(intau,:))
                            app.DtauF(intau)=-linfit(1);
                            app.AmpF(intau)=exp(linfit(2));
                            ph.XData = [0 intau/size(CF,2) intau/size(CF,2) 0];
                            th.String = sprintf('Linear Fit 1 Component of kICS CF...%.0f%%',round(intau/size(CF,2)*100));
                            drawnow %update graphics
                        end
                        title(app.UIAxes,'log(G) vs k^2 at given tau')
                        xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                        ylabel(app.UIAxes,'log G(k^{2},\tau)','FontSize',14)
                        delX=max(rangek2)-min(rangek2);
                        delY=max(CF(:))-min(CF(:));
                        app.UIAxes.YTick = ([min(CF(:)):delY/10:max(CF(:))]);
                        app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)]);
                        ylim(app.UIAxes,[min(CF(:)) max(CF(:))])
                        xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                    elseif strcmp(app.FitCFOption,'Linear Fit 2 Components')
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        minK=min(find(app.kValues>app.mink2fit));
                        maxK=min(find(app.kValues>app.maxk2fit));
                        inflectK=min(find(app.kValues>app.inflectk2));
                        CF=app.CFsmooth+abs(min(app.CFsmooth(:)))+10^-12;
                        CF=squeeze(log(CF));
                        mycolors=jet(size(CF,2));
                        app.DtauF=[];
                        app.AmpF=[];
                        app.DtauS=[];
                        app.AmpS=[];
                        app.RatAmp=[];
                        cla(app.UIAxes2)
                        ylim(app.UIAxes2,[0,1])
                        xlim(app.UIAxes2,[0,1])
                        ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                        th = text(app.UIAxes2,1,1,'Linear Fit 2 Components of kICS CF...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                        for intau=1:size(CF,2)
                            plot(app.UIAxes,app.kValues(minK:inflectK),squeeze(CF(minK:inflectK,intau)),'.','Color',mycolors(intau,:))
                            hold(app.UIAxes,"on");
                            linfit=polyfit(app.kValues(minK:inflectK),squeeze(CF(minK:inflectK,intau)),1);
                            hold(app.UIAxes,"on");
                            plot(app.UIAxes,app.kValues(minK:inflectK),linfit(1)*app.kValues(minK:inflectK)+linfit(2),'--','Color',mycolors(intau,:))
                            app.DtauF(intau)=-linfit(1);
                            app.AmpF(intau)=exp(linfit(2));
                            % slow component
                            plot(app.UIAxes,app.kValues(inflectK:maxK),squeeze(CF(inflectK:maxK,intau)),'.','Color',mycolors(intau,:))
                            hold(app.UIAxes,"on");
                            linfit=polyfit(app.kValues(inflectK:maxK),squeeze(CF(inflectK:maxK,intau)),1);
                            hold(app.UIAxes,"on");
                            plot(app.UIAxes,app.kValues(inflectK:maxK),linfit(1)*app.kValues(inflectK:maxK)+linfit(2),'--','Color',mycolors(intau,:))
                            app.DtauS(intau)=-linfit(1);
                            app.AmpS(intau)=exp(linfit(2));
                            app.RatAmp(intau)=app.AmpF(intau)./app.AmpS(intau);
                            ph.XData = [0 intau/size(CF,2) intau/size(CF,2) 0];
                            th.String = sprintf('Linear Fit 2 Components of kICS CF...%.0f%%',round(intau/size(CF,2)*100));
                            drawnow %update graphics
                        end
                        title(app.UIAxes,'log(G) vs k^2 at given tau')
                        xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                        ylabel(app.UIAxes,'log G(k^{2},\tau)','FontSize',14)
                        rangek2=app.kValues(minK:maxK);
                        delX=max(rangek2)-min(rangek2);
                        delY=max(CF(:))-min(CF(:))
                        app.UIAxes.YTick = ([min(CF(:)):delY/10:max(CF(:))])
                        app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)]);
                        ylim(app.UIAxes,[min(CF(:)) max(CF(:))])
                        xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                    elseif strcmp(app.FitCFOption,'Non-Linear Fit 1 Component')
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        ind1=find(app.kValues>app.mink2fit);
                        ind2=find(app.kValues<app.maxk2fit);
                        indgood=intersect(ind1,ind2);
                        rangek2=app.kValues(indgood);
                        title(app.UIAxes,'G vs k^2 at given tau')
                        xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                        ylabel(app.UIAxes,'G(k^{2},\tau)','FontSize',14)
                        delX=max(rangek2)-min(rangek2);
                        delY=max(app.CFsmooth(:))-min(app.CFsmooth(:));
                        app.UIAxes.YTick = [min(app.CFsmooth(:)):delY/10:max(app.CFsmooth(:))];
                        app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)],1);
                        ylim(app.UIAxes,[min(app.CFsmooth(:)) max(app.CFsmooth(:))])
                        xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                        [results,fitted]=DiffusionLawBySingleGaussianFitvsKsqForGUI(app.UIAxes,app.UIAxes2,squeeze((app.CFsmooth(indgood,:))),(1:size(app.CF,2)),rangek2,app.timeFrame);
                        cfit=results{:,1};
                        app.DtauF=cfit.b;
                        app.AmpF=cfit.a;
                        app.DtauS=[];
                        app.AmpS=[];
                        app.RatAmp=[];
                    elseif strcmp(app.FitCFOption,'Non-Linear Fit 2 Components')
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        ind1=find(app.kValues>app.mink2fit);
                        ind2=find(app.kValues<app.maxk2fit);
                        indgood=intersect(ind1,ind2);
                        rangek2=app.kValues(indgood);
                        title(app.UIAxes,'G vs k^2 at given tau')
                        xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                        ylabel(app.UIAxes,'G(k^{2},\tau)','FontSize',14)
                        delX=max(rangek2)-min(rangek2);
                        delY=max(app.CFsmooth(:))-min(app.CFsmooth(:));
                        app.UIAxes.YTick = [min(app.CFsmooth(:)):delY/10:max(app.CFsmooth(:))];
                        app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)],1);
                        ylim(app.UIAxes,[min(app.CFsmooth(:)) max(app.CFsmooth(:))])
                        xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                        [results,fitted]=DiffusionLawByDoubleGaussianFitvsKsqForGUI(app.UIAxes,app.UIAxes2,squeeze((app.CFsmooth(indgood,:))),(1:size(app.CF,2)),rangek2,app.timeFrame);
                        cfit=results{:,1};
                        app.DtauF=cfit.b;
                        app.AmpF=cfit.a;
                        app.DtauS=cfit.d;
                        app.AmpS=cfit.c;
                        app.RatAmp=app.AmpF./app.AmpS;
                    end %end fitting CF
                    exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_FittingCFCell'  num2str(cells) '.png'])
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%% linearly fit trends extract confinement parameters
                    if strcmp(app.FitCFOption,'Linear Fit 2 Components') || strcmp(app.FitCFOption,'Non-Linear Fit 2 Components')
                        % 'early linear fit DmacroTau vs tau'
                        rangetau=(app.mintaufit1:app.maxtaufit1)*app.timeFrame;
                        rangeY=app.DtauF(app.mintaufit1:app.maxtaufit1);
                        indgood=zeros(size(rangeY));
                        for i=1:length(rangeY)
                            if isreal(rangeY(i))
                                indgood(i)=1;
                            end
                        end
                        indgood=find(indgood);
                        rangeY=rangeY(indgood);
                        rangetau=rangetau(indgood);
                        fitlin=polyfit(rangetau,rangeY,1);
                        app.Dmacro=fitlin(1);
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        plot(app.UIAxes,rangetau,rangeY,'o')
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                        text(app.UIAxes,rangetau(1),0.95*max(rangeY),['D_{macro}=' num2str(round(app.Dmacro,3)) ' \mum^{2}s^{-1}'])
                        title(app.UIAxes,'Dtau Macro vs tau')
                        xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                        ylabel(app.UIAxes,'D_{macro}\tau (\mum^{2})','FontSize',14)
                        delY=max(rangeY)-min(rangeY);
                        app.UIAxes.YTick = round([min(rangeY):delY/3:max(rangeY)],3);
                        app.UIAxes.XTick = round([min(rangetau):app.timeFrame:max(rangetau)],3);
                        ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                        xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                        exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_EarlyLinearFitDmacroTauCell'  num2str(cells) '.png'])
                        % 'late linear fit DmacroTau vs tau'
                        rangetau=(app.mintaufit2:app.maxtaufit2)*app.timeFrame;
                        rangeY=app.DtauF(app.mintaufit2:app.maxtaufit2);
                        indgood=zeros(size(rangeY));
                        for i=1:length(rangeY)
                            if isreal(rangeY(i))
                                indgood(i)=1;
                            end
                        end
                        indgood=find(indgood);
                        rangeY=rangeY(indgood);
                        rangetau=rangetau(indgood);
                        fitlin=polyfit(rangetau,rangeY,1);
                        app.Deff=fitlin(1);
                        app.MaxDispMacro=fitlin(2)^0.5;
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        plot(app.UIAxes,rangetau,rangeY,'o')
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                        text(app.UIAxes,rangetau(1),0.85*max(rangeY),['D_{eff}=' num2str(round(app.Deff,3)) ' \mum^{2}s^{-1}'])
                        text(app.UIAxes,rangetau(1),0.95*max(rangeY),['Max. Displacement Macro=' num2str(round(app.MaxDispMacro,2)) ' \mum'])
                        title(app.UIAxes,'Dtau Macro vs tau')
                        xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                        ylabel(app.UIAxes,'D_{macro}\tau (\mum^{2})','FontSize',14)
                        delY=max(rangeY)-min(rangeY);
                        app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                        app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                        ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                        xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                        exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_LateLinearFitDmacroTauCell'  num2str(cells) '.png'])
                        % 'Anomalous Fit To DmacroTau vs tau'
                        rangetau=(app.mintaufit6:app.maxtaufit6)*app.timeFrame;
                        rangeY=app.DtauF(app.mintaufit6:app.maxtaufit6);
                        indgood=zeros(size(rangeY));
                        for i=1:length(rangeY)
                            if isreal(rangeY(i))
                                indgood(i)=1;
                            end
                        end
                        indgood=find(indgood);
                        rangeY=rangeY(indgood);
                        rangetau=rangetau(indgood);
                        rangetau=log(rangetau);
                        rangeY=log(rangeY);
                        fitlin=polyfit(rangetau,rangeY,1);
                        app.Danomalous=exp(fitlin(2));
                        app.alphaAnomalous=fitlin(1);
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        plot(app.UIAxes,rangetau,rangeY,'o')
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                        text(app.UIAxes,rangetau(1),1.15*max(rangeY),['D_{anomalous}=' num2str(round(app.Danomalous,3)) ' \mum^{2}s^{-1}'])
                        text(app.UIAxes,rangetau(1),1.25*max(rangeY),['\alpha_{anomalous}=' num2str(round(app.alphaAnomalous,3))])
                        title(app.UIAxes,'Anomalous Fit of log(Dtau Macro) vs log(tau)')
                        xlabel(app.UIAxes,'log\tau (s)','FontSize',14)
                        ylabel(app.UIAxes,'logD_{macro}\tau (\mum^{2})','FontSize',14)
                        delY=max(rangeY)-min(rangeY);
                        app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                        app.UIAxes.XTick = round([min(rangetau):log(3*app.timeFrame):max(rangetau)],3);
                        ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                        xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                        exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_AnomalousFitCell'  num2str(cells) '.png'])
                        % 'early linear fit DmicroTau vs tau'
                        rangetau=(app.mintaufit3:app.maxtaufit3)*app.timeFrame;
                        rangeY=app.DtauS(app.mintaufit3:app.maxtaufit3);
                        indgood=zeros(size(rangeY));
                        for i=1:length(rangeY)
                            if isreal(rangeY(i))
                                indgood(i)=1;
                            end
                        end
                        indgood=find(indgood);
                        rangeY=rangeY(indgood);
                        rangetau=rangetau(indgood);
                        fitlin=polyfit(rangetau,rangeY,1);
                        app.Dmicro=fitlin(1);
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        plot(app.UIAxes,rangetau,rangeY,'o')
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                        text(app.UIAxes,rangetau(1),0.95*max(rangeY),['D_{micro}=' num2str(round(app.Dmicro,4)) ' \mum^{2}s^{-1}'])
                        title(app.UIAxes,'Dtau Micro vs tau')
                        xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                        ylabel(app.UIAxes,'D_{micro}\tau (\mum^{2})','FontSize',14)
                        delY=max(rangeY)-min(rangeY);
                        app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],5);
                        app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                        ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                        xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                        exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_EarlyLinearFitDmicroTauCell'  num2str(cells) '.png'])
                        % 'late linear fit DmicroTau vs tau'
                        rangetau=(app.mintaufit4:app.maxtaufit4)*app.timeFrame;
                        rangeY=app.DtauS(app.mintaufit4:app.maxtaufit4);
                        indgood=zeros(size(rangeY));
                        for i=1:length(rangeY)
                            if isreal(rangeY(i))
                                indgood(i)=1;
                            end
                        end
                        indgood=find(indgood);
                        rangeY=rangeY(indgood);
                        rangetau=rangetau(indgood);
                        fitlin=polyfit(rangetau,rangeY,1);
                        app.DmicroLate=fitlin(1);
                        app.MaxDispMicro=fitlin(2)^0.5;
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        delY=max(rangeY)-min(rangeY);
                        app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],5);
                        app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                        ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                        xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                        plot(app.UIAxes,rangetau,rangeY,'o')
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                        text(app.UIAxes,rangetau(1),0.85*max(rangeY),['D_{microLate}=' num2str(round(app.DmicroLate,4)) ' \mum^{2}s^{-1}'])
                        text(app.UIAxes,rangetau(1),0.95*max(rangeY),['Max. Displacement Macro=' num2str(round(app.MaxDispMicro,3)) ' \mum'])
                        title(app.UIAxes,'Dtau Micro vs tau')
                        xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                        ylabel(app.UIAxes,'D_{micro}\tau (\mum^{2})','FontSize',14)
                        exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_LateLinearFitDmicroTauCell'  num2str(cells) '.png'])
                        % 'late linear fit AmpMacro/AmpMicro vs tau'
                        rangetau=(app.mintaufit5:app.maxtaufit5)*app.timeFrame;
                        rangeY=log(app.RatAmp(app.mintaufit5:app.maxtaufit5));
                        indgood=zeros(size(rangeY));
                        for i=1:length(rangeY)
                            if isreal(rangeY(i))
                                indgood(i)=1;
                            end
                        end
                        indgood=find(indgood);
                        rangeY=rangeY(indgood);
                        rangetau=rangetau(indgood);
                        fitlin=polyfit(rangetau,rangeY,1);
                        app.k2=fitlin(1);
                        app.ratConc=exp(fitlin(1)*app.maxtaufit5*app.timeFrame+fitlin(2));
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        plot(app.UIAxes,rangetau,rangeY,'o')
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                        text(app.UIAxes,rangetau(1),0.85*max(rangeY),['k_{2}=' num2str(round(app.k2,3)) ' s^{-1}'])
                        text(app.UIAxes,rangetau(1),0.95*max(rangeY),['N_{macro}/N_{micro}=' num2str(round(app.ratConc,3)) ])
                        title(app.UIAxes,'N_{macro}/N_{micro} vs tau')
                        xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                        ylabel(app.UIAxes,'N_{macro}/N_{micro}','FontSize',14)
                        delY=max(rangeY)-min(rangeY);
                        app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                        app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                        ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                        xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                        exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_LateLinearFitRatioAmpCell'  num2str(cells) '.png'])
                    else % if only single component was fitted to CF...
                        app.Dmicro=NaN;
                        app.DmicroLate=NaN;
                        app.MaxDispMicro=NaN;
                        app.k2=NaN;
                        app.ratConc=NaN;
                        % 'early linear fit DmacroTau vs tau'
                        rangetau=(app.mintaufit1:app.maxtaufit1)*app.timeFrame;
                        rangeY=app.DtauF(app.mintaufit1:app.maxtaufit1);
                        indgood=zeros(size(rangeY));
                        for i=1:length(rangeY)
                            if isreal(rangeY(i))
                                indgood(i)=1;
                            end
                        end
                        indgood=find(indgood);
                        rangeY=rangeY(indgood);
                        rangetau=rangetau(indgood);
                        fitlin=polyfit(rangetau,rangeY,1);
                        app.Dmacro=fitlin(1);
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        plot(app.UIAxes,rangetau,rangeY,'o')
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                        text(app.UIAxes,rangetau(1),0.95*max(rangeY),['D_{macro}=' num2str(round(app.Dmacro,3)) ' \mum^{2}s^{-1}'])
                        title(app.UIAxes,'Dtau Macro vs tau')
                        xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                        ylabel(app.UIAxes,'D_{macro}\tau (\mum^{2})','FontSize',14)
                        delY=max(rangeY)-min(rangeY);
                        app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                        app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                        ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                        xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                        exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_EarlyLinearFitDmacroTauCell'  num2str(cells) '.png'])
                        % 'late linear fit DmacroTau vs tau'
                        rangetau=(app.mintaufit2:app.maxtaufit2)*app.timeFrame;
                        rangeY=app.DtauF(app.mintaufit2:app.maxtaufit2);
                        indgood=zeros(size(rangeY));
                        for i=1:length(rangeY)
                            if isreal(rangeY(i))
                                indgood(i)=1;
                            end
                        end
                        indgood=find(indgood);
                        rangeY=rangeY(indgood);
                        rangetau=rangetau(indgood);
                        fitlin=polyfit(rangetau,rangeY,1);
                        app.Deff=fitlin(1);
                        app.MaxDispMacro=fitlin(2)^0.5;
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        plot(app.UIAxes,rangetau,rangeY,'o')
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                        text(app.UIAxes,rangetau(1),0.85*max(rangeY),['D_{eff}=' num2str(round(app.Deff,3)) ' \mum^{2}s^{-1}'])
                        text(app.UIAxes,rangetau(1),0.95*max(rangeY),['Max. Displacement Macro=' num2str(round(app.MaxDispMacro,2)) ' \mum'])
                        title(app.UIAxes,'Dtau Macro vs tau')
                        xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                        ylabel(app.UIAxes,'D_{macro}\tau (\mum^{2})','FontSize',14)
                        delY=max(rangeY)-min(rangeY);
                        app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                        app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                        ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                        xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                        exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_LateLinearFitDmacroTauCell'  num2str(cells) '.png'])
                        % 'Anomalous Fit To DmacroTau vs tau'
                        rangetau=(app.mintaufit6:app.maxtaufit6)*app.timeFrame;
                        rangeY=app.DtauF(app.mintaufit6:app.maxtaufit6);
                        indgood=zeros(size(rangeY));
                        for i=1:length(rangeY)
                            if isreal(rangeY(i))
                                indgood(i)=1;
                            end
                        end
                        indgood=find(indgood);
                        rangeY=rangeY(indgood);
                        rangetau=rangetau(indgood);
                        rangetau=log(rangetau);
                        rangeY=log(rangeY);
                        fitlin=polyfit(rangetau,rangeY,1);
                        app.Danomalous=exp(fitlin(2));
                        app.alphaAnomalous=fitlin(1);
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        plot(app.UIAxes,rangetau,rangeY,'o')
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                        text(app.UIAxes,rangetau(1),1.15*max(rangeY),['D_{anomalous}=' num2str(round(app.Danomalous,3)) ' \mum^{2}s^{-1}'])
                        text(app.UIAxes,rangetau(1),1.25*max(rangeY),['\alpha_{anomalous}=' num2str(round(app.alphaAnomalous,3))])
                        title(app.UIAxes,'Anomalous Fit of log(Dtau Macro) vs log(tau)')
                        xlabel(app.UIAxes,'log\tau (s)','FontSize',14)
                        ylabel(app.UIAxes,'logD_{macro}\tau (\mum^{2})','FontSize',14)
                        delY=max(rangeY)-min(rangeY);
                        app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                        app.UIAxes.XTick = round([min(rangetau):log(3*app.timeFrame):max(rangetau)],3);
                        ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                        xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                        exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_AnomalousFitCell'  num2str(cells) '.png'])
                    end % fit if 2 components were present...
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Export results
                    pixelSize=app.pixelSize;
                    timeFrame=app.timeFrame;
                    kBin=app.kBin;
                    CF=app.CF;
                    kValues=app.kValues;
                    DtauF=app.DtauF;
                    AmpF=app.AmpF;
                    DtauS=app.DtauS;
                    AmpS=app.AmpS;
                    RatAmp=app.RatAmp;
                    Dmacro=app.Dmacro;
                    Deff=app.Deff;
                    MaxDispMacro=app.MaxDispMacro;
                    Dmicro=app.Dmicro;
                    DmicroLate=app.DmicroLate;
                    MaxDispMicro=app.MaxDispMicro;
                    k2=app.k2;
                    ratConc=app.ratConc;
                    alphaAnomalous=app.alphaAnomalous;
                    Danomalous=app.Danomalous;
                    save([app.path files{f}(1:end-4) '_OutputsCell' num2str(cells) '.mat'],'pixelSize','timeFrame','kBin','CF','kValues','DtauF','AmpF','DtauS',"AmpS","RatAmp","Dmacro","Deff","MaxDispMacro","Dmicro","DmicroLate","MaxDispMicro","k2","ratConc","alphaAnomalous","Danomalous")
                    T=table(((1:length(DtauF))*app.timeFrame)',DtauF',AmpF',DtauS',AmpS',RatAmp');
                    T.Properties.VariableNames ={'tau','DtauF','AmpF','DtauS','AmpS','RatAmp'};
                    writetable(T,[app.path files{f}(1:end-4) '_ConfinementResults.xlsx'],'Sheet',['MobAndAmpCell'  num2str(cells)]);
                    T=table(Dmacro',Deff',MaxDispMacro',Dmicro',DmicroLate',MaxDispMicro',k2',ratConc',alphaAnomalous',Danomalous');
                    T.Properties.VariableNames ={'Dmacro','Deff','MaxDispMacro','Dmicro','DmicroLate','MaxDispMicro','k2','ratConc','alphaAnomalous','Danomalous'};
                    writetable(T,[app.path files{f}(1:end-4) '_ConfinementResults.xlsx'],'Sheet',['ConfParamsCell' num2str(cells)]);
                end %end over all cells
            end % end over all files

            else %if first file selected does not involve finding cell masks...
                % process files one by one
            for f=1:length(files)
                %load file
                [app.images,specs]=readOMEFile_forGUI(app.UIAxes2,app.path,files{f});
                app.pixelSize=specs.pixelSize;
                app.timeFrame=specs.timeFrame;
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % temporally align data if needed
                if app.TempAlign==1
                    app.croped=[];
                    scale=0.1;
                    [optimizer, metric] = imregconfig('multimodal');
                    optimizer.InitialRadius = 0.009;
                    optimizer.Epsilon = 1.5e-4;
                    optimizer.GrowthFactor = 1.01;
                    optimizer.MaximumIterations = 300;;
                    ref=app.croped(:,:,1);
                    app.croped=single(zeros(size(app.images)));
                    app.croped(:,:,1)=app.images(:,:,1);
                    fixed=imresize(app.croped(:,:,1),scale);
                    [fixed,h2]=smoothSeries(fixed,10,'g','p');
                    cla(app.UIAxes2)
                    ylim(app.UIAxes2,[0,1])
                    xlim(app.UIAxes2,[0,1])
                    ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                    th = text(app.UIAxes2,1,1,'Aligning Images To t0...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                    for i=2:size(app.images,3)
                        
                        moving=imresize(app.images(:,:,i),scale);
                        [moving,h2]=smoothSeries(moving,10,'g','p');
                        tform = imregtform(moving, fixed, 'translation', optimizer, metric);
                        tform.T(3,1:2)=tform.T(3,1:2)*(1/scale);
                        movingRegistered = imwarp(app.images(:,:,i),tform,'OutputView',imref2d(size(ref)));
                        app.croped(:,:,i)=movingRegistered;
                        ph.XData = [0 i/size(app.images,3)  i/size(app.images,3) 0];
                        th.String = sprintf('Aligning Images To t0...%.0f%%',round(i/size(app.images,3)*100));
                        drawnow %update graphics
                    end
                else
                    app.croped=app.images;
                end
                
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % do temporal filtering if checked
                    if app.toImmFilt==1
                        % filtering immobile component
                        app.timefilt = fft(double(app.croped),[],3);
                        avPixInt = mean(mean(app.timefilt(:,:,1)))/size(app.timefilt,3);
                        app.timefilt(:,:,1) = 0;
                        app.timefilt = (real(ifft(app.timefilt,[],3))+avPixInt);
                        app.timefilt=single(app.timefilt);
                    elseif app.toImmFilt==0
                        app.timefilt=app.croped;
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Hann windowing
                    if app.toHann==1
                        [app.hanned]=hann2DbetaPower(app.timefilt,app.HannPow);
                    elseif app.toHann==0
                        app.hanned=app.timefilt;
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % compute kICS CF
                    imgser=single(app.hanned);
                    sizet=size(imgser,3);
                    imageDim2=size(imgser,2);
                    imageDim1=size(imgser,1);
                    fftimser=fftn(imgser,[size(imgser,1) size(imgser,2) size(imgser,3)]);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % define k vectors
                    maxk = (1/app.pixelSize)/2*(2*pi);
                    stepkx = 1/(imageDim2*app.pixelSize)*(2*pi);
                    stepky = 1/(imageDim1*app.pixelSize)*(2*pi);
                    
                    if ((mod(imageDim2,2)==0)&&(mod(imageDim1,2)==0))
                        [X,Y] = meshgrid(-maxk:stepkx:maxk-stepkx,-maxk:stepky:maxk-stepky);
                    elseif ((mod(imageDim2,2)==1)&&(mod(imageDim1,2)==1))
                        [X,Y] = meshgrid(-stepkx*(imageDim2-1)/2:stepkx:stepkx*(imageDim2-1)/2,-stepky*(imageDim1-1)/2:stepky:stepky*(imageDim1-1)/2);
                    elseif mod(imageDim2,2)==1
                        [X,Y] = meshgrid(-stepkx*(imageDim2-1)/2:stepkx:stepkx*(imageDim2-1)/2,-maxk:stepky:maxk-stepky);
                    else
                        [X,Y] = meshgrid(-maxk:stepkx:maxk-stepkx,-stepky*(imageDim1-1)/2:stepky:stepky*(imageDim1-1)/2);
                    end
                    app.X=X;
                    app.Y=Y;
                    app.stepkx=stepkx;
                    app.stepky=stepky;
                    kSquared = (X.^2 + Y.^2);
                    roundTol = 1e-11;
                    kValues = consolidator(kSquared(:),[],'mean',roundTol);
                    kSquaredIndices = find(kSquared(:)<app.k2Cutoff);
                    kBin = zeros(sum(kValues<app.k2Cutoff),2,round(app.fractau*sizet));
                    kSquaredCut = kSquared(kSquaredIndices);
                    clear imgser corrfftslice
                    app.timecorrnorm=[];
                    app.kBin=[];
                    corrfn=conj(fftimser).*fftimser;clear fftimser;
                    timecorr=ifft(corrfn,[],3);clear corrfn
                    cla(app.UIAxes2)
                    ylim(app.UIAxes2,[0,1])
                    xlim(app.UIAxes2,[0,1])
                    ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                    th = text(app.UIAxes2,1,1,'Calculating kICS Correlation Function...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                    for i=1:round(app.fractau*sizet)
                        app.timecorrnorm(:,:,i)=real(fftshift(timecorr(:,:,i)./timecorr(:,:,1) ));
                        corrfftslice = app.timecorrnorm(:,:,i);
                        [xg,yg] = consolidator(kSquaredCut,corrfftslice(kSquaredIndices),@mean,roundTol);
                        app.kBin(:,1,i) = xg;
                        app.kBin(:,2,i) = yg;
                        app.kBin(:,3,i) = real(log(yg));
                        clear corrfftslice
                        ph.XData = [0 i/round(app.fractau*sizet) i/round(app.fractau*sizet) 0];
                        th.String = sprintf('Calculating kICS Correlation Function...%.0f%%',round(i/round(app.fractau*sizet)*100));
                        drawnow %update graphics
                    end
                    app.timecorrnorm=app.timecorrnorm(:,:,2:end);
                    app.CF=squeeze(app.kBin(:,2,:));
                    app.kValues=squeeze(app.kBin(:,1,2));
                    app.CF=app.CF(:,2:end);
                    app.kBin=app.kBin(:,:,2:end);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % smooth CF if needed
                    app.CFsmooth=[];
                    if app.MovWindk2Status==1
                        for i=1:size(app.CF,2)
                            app.CFsmooth(:,i) = moving_average(squeeze(app.CF(:,i)),app.MovWindk2);
                        end
                    elseif app.MovWindk2Status==0
                        app.CFsmooth=app.CF;
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % fit CF
                    if strcmp(app.FitCFOption,'Linear Fit 1 Component')
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        ind1=find(app.kValues>app.mink2fit);
                        ind2=find(app.kValues<app.maxk2fit);
                        indgood=intersect(ind1,ind2);
                        rangek2=app.kValues(indgood);
                        CF=app.CFsmooth(indgood,:);
                        CF=CF+abs(min(CF))+10^-12;
                        CF=squeeze(log(CF));
                        mycolors=jet(size(CF,2));
                        app.DtauF=[];
                        app.AmpF=[];
                        app.DtauS=[];
                        app.AmpS=[];
                        app.RatAmp=[];
                        cla(app.UIAxes2)
                        ylim(app.UIAxes2,[0,1])
                        xlim(app.UIAxes2,[0,1])
                        ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                        th = text(app.UIAxes2,1,1,'Linear Fit 1 Component of kICS CF...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                        for intau=1:size(CF,2)
                            CFtouse=CF(:,intau);
                            plot(app.UIAxes,rangek2,CFtouse,'.','Color',mycolors(intau,:))
                            hold(app.UIAxes,"on");
                            linfit=polyfit(rangek2,CFtouse,1);
                            plot(app.UIAxes,rangek2,linfit(1)*rangek2+linfit(2),'--','Color',mycolors(intau,:))
                            app.DtauF(intau)=-linfit(1);
                            app.AmpF(intau)=exp(linfit(2));
                            ph.XData = [0 intau/size(CF,2) intau/size(CF,2) 0];
                            th.String = sprintf('Linear Fit 1 Component of kICS CF...%.0f%%',round(intau/size(CF,2)*100));
                            drawnow %update graphics
                        end
                        title(app.UIAxes,'log(G) vs k^2 at given tau')
                        xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                        ylabel(app.UIAxes,'log G(k^{2},\tau)','FontSize',14)
                        delX=max(rangek2)-min(rangek2);
                        delY=max(CF(:))-min(CF(:));
                        app.UIAxes.YTick = ([min(CF(:)):delY/10:max(CF(:))]);
                        app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)]);
                        ylim(app.UIAxes,[min(CF(:)) max(CF(:))])
                        xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                    elseif strcmp(app.FitCFOption,'Linear Fit 2 Components')
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        minK=min(find(app.kValues>app.mink2fit));
                        maxK=min(find(app.kValues>app.maxk2fit));
                        inflectK=min(find(app.kValues>app.inflectk2));
                        CF=app.CFsmooth+abs(min(app.CFsmooth(:)))+10^-12;
                        CF=squeeze(log(CF));
                        mycolors=jet(size(CF,2));
                        app.DtauF=[];
                        app.AmpF=[];
                        app.DtauS=[];
                        app.AmpS=[];
                        app.RatAmp=[];
                        cla(app.UIAxes2)
                        ylim(app.UIAxes2,[0,1])
                        xlim(app.UIAxes2,[0,1])
                        ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                        th = text(app.UIAxes2,1,1,'Linear Fit 2 Components of kICS CF...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                        for intau=1:size(CF,2)
                            plot(app.UIAxes,app.kValues(minK:inflectK),squeeze(CF(minK:inflectK,intau)),'.','Color',mycolors(intau,:))
                            hold(app.UIAxes,"on");
                            linfit=polyfit(app.kValues(minK:inflectK),squeeze(CF(minK:inflectK,intau)),1);
                            hold(app.UIAxes,"on");
                            plot(app.UIAxes,app.kValues(minK:inflectK),linfit(1)*app.kValues(minK:inflectK)+linfit(2),'--','Color',mycolors(intau,:))
                            app.DtauF(intau)=-linfit(1);
                            app.AmpF(intau)=exp(linfit(2));
                            % slow component
                            plot(app.UIAxes,app.kValues(inflectK:maxK),squeeze(CF(inflectK:maxK,intau)),'.','Color',mycolors(intau,:))
                            hold(app.UIAxes,"on");
                            linfit=polyfit(app.kValues(inflectK:maxK),squeeze(CF(inflectK:maxK,intau)),1);
                            hold(app.UIAxes,"on");
                            plot(app.UIAxes,app.kValues(inflectK:maxK),linfit(1)*app.kValues(inflectK:maxK)+linfit(2),'--','Color',mycolors(intau,:))
                            app.DtauS(intau)=-linfit(1);
                            app.AmpS(intau)=exp(linfit(2));
                            app.RatAmp(intau)=app.AmpF(intau)./app.AmpS(intau);
                            ph.XData = [0 intau/size(CF,2) intau/size(CF,2) 0];
                            th.String = sprintf('Linear Fit 2 Components of kICS CF...%.0f%%',round(intau/size(CF,2)*100));
                            drawnow %update graphics
                        end
                        title(app.UIAxes,'log(G) vs k^2 at given tau')
                        xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                        ylabel(app.UIAxes,'log G(k^{2},\tau)','FontSize',14)
                        rangek2=app.kValues(minK:maxK);
                        delX=max(rangek2)-min(rangek2);
                        delY=max(CF(:))-min(CF(:))
                        app.UIAxes.YTick = ([min(CF(:)):delY/10:max(CF(:))])
                        app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)]);
                        ylim(app.UIAxes,[min(CF(:)) max(CF(:))])
                        xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                    elseif strcmp(app.FitCFOption,'Non-Linear Fit 1 Component')
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        ind1=find(app.kValues>app.mink2fit);
                        ind2=find(app.kValues<app.maxk2fit);
                        indgood=intersect(ind1,ind2);
                        rangek2=app.kValues(indgood);
                        title(app.UIAxes,'G vs k^2 at given tau')
                        xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                        ylabel(app.UIAxes,'G(k^{2},\tau)','FontSize',14)
                        delX=max(rangek2)-min(rangek2);
                        delY=max(app.CFsmooth(:))-min(app.CFsmooth(:));
                        app.UIAxes.YTick = [min(app.CFsmooth(:)):delY/10:max(app.CFsmooth(:))];
                        app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)],1);
                        ylim(app.UIAxes,[min(app.CFsmooth(:)) max(app.CFsmooth(:))])
                        xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                        [results,fitted]=DiffusionLawBySingleGaussianFitvsKsqForGUI(app.UIAxes,app.UIAxes2,squeeze((app.CFsmooth(indgood,:))),(1:size(app.CF,2)),rangek2,app.timeFrame);
                        cfit=results{:,1};
                        app.DtauF=cfit.b;
                        app.AmpF=cfit.a;
                        app.DtauS=[];
                        app.AmpS=[];
                        app.RatAmp=[];
                    elseif strcmp(app.FitCFOption,'Non-Linear Fit 2 Components')
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        ind1=find(app.kValues>app.mink2fit);
                        ind2=find(app.kValues<app.maxk2fit);
                        indgood=intersect(ind1,ind2);
                        rangek2=app.kValues(indgood);
                        title(app.UIAxes,'G vs k^2 at given tau')
                        xlabel(app.UIAxes,'k^{2} (\mum^{-2})','FontSize',14)
                        ylabel(app.UIAxes,'G(k^{2},\tau)','FontSize',14)
                        delX=max(rangek2)-min(rangek2);
                        delY=max(app.CFsmooth(:))-min(app.CFsmooth(:));
                        app.UIAxes.YTick = [min(app.CFsmooth(:)):delY/10:max(app.CFsmooth(:))];
                        app.UIAxes.XTick = round([min(rangek2):delX/10:max(rangek2)],1);
                        ylim(app.UIAxes,[min(app.CFsmooth(:)) max(app.CFsmooth(:))])
                        xlim(app.UIAxes,[min(rangek2) max(rangek2)])
                        [results,fitted]=DiffusionLawByDoubleGaussianFitvsKsqForGUI(app.UIAxes,app.UIAxes2,squeeze((app.CFsmooth(indgood,:))),(1:size(app.CF,2)),rangek2,app.timeFrame);
                        cfit=results{:,1};
                        app.DtauF=cfit.b;
                        app.AmpF=cfit.a;
                        app.DtauS=cfit.d;
                        app.AmpS=cfit.c;
                        app.RatAmp=app.AmpF./app.AmpS;
                    end %end fitting CF
                    exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_FittingCF.png'])
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%% linearly fit trends extract confinement parameters
                    if strcmp(app.FitCFOption,'Linear Fit 2 Components') || strcmp(app.FitCFOption,'Non-Linear Fit 2 Components')
                        % 'early linear fit DmacroTau vs tau'
                        rangetau=(app.mintaufit1:app.maxtaufit1)*app.timeFrame;
                        rangeY=app.DtauF(app.mintaufit1:app.maxtaufit1);
                        indgood=zeros(size(rangeY));
                        for i=1:length(rangeY)
                            if isreal(rangeY(i))
                                indgood(i)=1;
                            end
                        end
                        indgood=find(indgood);
                        rangeY=rangeY(indgood);
                        rangetau=rangetau(indgood);
                        fitlin=polyfit(rangetau,rangeY,1);
                        app.Dmacro=fitlin(1);
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        plot(app.UIAxes,rangetau,rangeY,'o')
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                        text(app.UIAxes,rangetau(1),0.95*max(rangeY),['D_{macro}=' num2str(round(app.Dmacro,3)) ' \mum^{2}s^{-1}'])
                        title(app.UIAxes,'Dtau Macro vs tau')
                        xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                        ylabel(app.UIAxes,'D_{macro}\tau (\mum^{2})','FontSize',14)
                        delY=max(rangeY)-min(rangeY);
                        app.UIAxes.YTick = round([min(rangeY):delY/3:max(rangeY)],3);
                        app.UIAxes.XTick = round([min(rangetau):app.timeFrame:max(rangetau)],3);
                        ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                        xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                        exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_EarlyLinearFitDmacroTau.png'])
                        % 'late linear fit DmacroTau vs tau'
                        rangetau=(app.mintaufit2:app.maxtaufit2)*app.timeFrame;
                        rangeY=app.DtauF(app.mintaufit2:app.maxtaufit2);
                        indgood=zeros(size(rangeY));
                        for i=1:length(rangeY)
                            if isreal(rangeY(i))
                                indgood(i)=1;
                            end
                        end
                        indgood=find(indgood);
                        rangeY=rangeY(indgood);
                        rangetau=rangetau(indgood);
                        fitlin=polyfit(rangetau,rangeY,1);
                        app.Deff=fitlin(1);
                        app.MaxDispMacro=fitlin(2)^0.5;
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        plot(app.UIAxes,rangetau,rangeY,'o')
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                        text(app.UIAxes,rangetau(1),0.85*max(rangeY),['D_{eff}=' num2str(round(app.Deff,3)) ' \mum^{2}s^{-1}'])
                        text(app.UIAxes,rangetau(1),0.95*max(rangeY),['Max. Displacement Macro=' num2str(round(app.MaxDispMacro,2)) ' \mum'])
                        title(app.UIAxes,'Dtau Macro vs tau')
                        xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                        ylabel(app.UIAxes,'D_{macro}\tau (\mum^{2})','FontSize',14)
                        delY=max(rangeY)-min(rangeY);
                        app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                        app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                        ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                        xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                        exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_LateLinearFitDmacroTau.png'])
                        % 'Anomalous Fit To DmacroTau vs tau'
                        rangetau=(app.mintaufit6:app.maxtaufit6)*app.timeFrame;
                        rangeY=app.DtauF(app.mintaufit6:app.maxtaufit6);
                        indgood=zeros(size(rangeY));
                        for i=1:length(rangeY)
                            if isreal(rangeY(i))
                                indgood(i)=1;
                            end
                        end
                        indgood=find(indgood);
                        rangeY=rangeY(indgood);
                        rangetau=rangetau(indgood);
                        rangetau=log(rangetau);
                        rangeY=log(rangeY);
                        fitlin=polyfit(rangetau,rangeY,1);
                        app.Danomalous=exp(fitlin(2));
                        app.alphaAnomalous=fitlin(1);
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        plot(app.UIAxes,rangetau,rangeY,'o')
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                        text(app.UIAxes,rangetau(1),1.15*max(rangeY),['D_{anomalous}=' num2str(round(app.Danomalous,3)) ' \mum^{2}s^{-1}'])
                        text(app.UIAxes,rangetau(1),1.25*max(rangeY),['\alpha_{anomalous}=' num2str(round(app.alphaAnomalous,3))])
                        title(app.UIAxes,'Anomalous Fit of log(Dtau Macro) vs log(tau)')
                        xlabel(app.UIAxes,'log\tau (s)','FontSize',14)
                        ylabel(app.UIAxes,'logD_{macro}\tau (\mum^{2})','FontSize',14)
                        delY=max(rangeY)-min(rangeY);
                        app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                        app.UIAxes.XTick = round([min(rangetau):log(3*app.timeFrame):max(rangetau)],3);
                        ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                        xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                        exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_AnomalousFit.png'])
                        % 'early linear fit DmicroTau vs tau'
                        rangetau=(app.mintaufit3:app.maxtaufit3)*app.timeFrame;
                        rangeY=app.DtauS(app.mintaufit3:app.maxtaufit3);
                        indgood=zeros(size(rangeY));
                        for i=1:length(rangeY)
                            if isreal(rangeY(i))
                                indgood(i)=1;
                            end
                        end
                        indgood=find(indgood);
                        rangeY=rangeY(indgood);
                        rangetau=rangetau(indgood);
                        fitlin=polyfit(rangetau,rangeY,1);
                        app.Dmicro=fitlin(1);
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        plot(app.UIAxes,rangetau,rangeY,'o')
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                        text(app.UIAxes,rangetau(1),0.95*max(rangeY),['D_{micro}=' num2str(round(app.Dmicro,4)) ' \mum^{2}s^{-1}'])
                        title(app.UIAxes,'Dtau Micro vs tau')
                        xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                        ylabel(app.UIAxes,'D_{micro}\tau (\mum^{2})','FontSize',14)
                        delY=max(rangeY)-min(rangeY);
                        app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],5);
                        app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                        ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                        xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                        exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_EarlyLinearFitDmicroTau.png'])
                        % 'late linear fit DmicroTau vs tau'
                        rangetau=(app.mintaufit4:app.maxtaufit4)*app.timeFrame;
                        rangeY=app.DtauS(app.mintaufit4:app.maxtaufit4);
                        indgood=zeros(size(rangeY));
                        for i=1:length(rangeY)
                            if isreal(rangeY(i))
                                indgood(i)=1;
                            end
                        end
                        indgood=find(indgood);
                        rangeY=rangeY(indgood);
                        rangetau=rangetau(indgood);
                        fitlin=polyfit(rangetau,rangeY,1);
                        app.DmicroLate=fitlin(1);
                        app.MaxDispMicro=fitlin(2)^0.5;
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        delY=max(rangeY)-min(rangeY);
                        app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],5);
                        app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                        ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                        xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                        plot(app.UIAxes,rangetau,rangeY,'o')
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                        text(app.UIAxes,rangetau(1),0.85*max(rangeY),['D_{microLate}=' num2str(round(app.DmicroLate,4)) ' \mum^{2}s^{-1}'])
                        text(app.UIAxes,rangetau(1),0.95*max(rangeY),['Max. Displacement Macro=' num2str(round(app.MaxDispMicro,3)) ' \mum'])
                        title(app.UIAxes,'Dtau Micro vs tau')
                        xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                        ylabel(app.UIAxes,'D_{micro}\tau (\mum^{2})','FontSize',14)
                        exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_LateLinearFitDmicroTau.png'])
                        % 'late linear fit AmpMacro/AmpMicro vs tau'
                        rangetau=(app.mintaufit5:app.maxtaufit5)*app.timeFrame;
                        rangeY=log(app.RatAmp(app.mintaufit5:app.maxtaufit5));
                        indgood=zeros(size(rangeY));
                        for i=1:length(rangeY)
                            if isreal(rangeY(i))
                                indgood(i)=1;
                            end
                        end
                        indgood=find(indgood);
                        rangeY=rangeY(indgood);
                        rangetau=rangetau(indgood);
                        fitlin=polyfit(rangetau,rangeY,1);
                        app.k2=fitlin(1);
                        app.ratConc=exp(fitlin(1)*app.maxtaufit5*app.timeFrame+fitlin(2));
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        plot(app.UIAxes,rangetau,rangeY,'o')
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                        text(app.UIAxes,rangetau(1),0.85*max(rangeY),['k_{2}=' num2str(round(app.k2,3)) ' s^{-1}'])
                        text(app.UIAxes,rangetau(1),0.95*max(rangeY),['N_{macro}/N_{micro}=' num2str(round(app.ratConc,3)) ])
                        title(app.UIAxes,'N_{macro}/N_{micro} vs tau')
                        xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                        ylabel(app.UIAxes,'N_{macro}/N_{micro}','FontSize',14)
                        delY=max(rangeY)-min(rangeY);
                        app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                        app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                        ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                        xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                        exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_LateLinearFitRatioAmp.png'])
                    else % if only single component was fitted to CF...
                        app.Dmicro=NaN;
                        app.DmicroLate=NaN;
                        app.MaxDispMicro=NaN;
                        app.k2=NaN;
                        app.ratConc=NaN;
                        % 'early linear fit DmacroTau vs tau'
                        rangetau=(app.mintaufit1:app.maxtaufit1)*app.timeFrame;
                        rangeY=app.DtauF(app.mintaufit1:app.maxtaufit1);
                        indgood=zeros(size(rangeY));
                        for i=1:length(rangeY)
                            if isreal(rangeY(i))
                                indgood(i)=1;
                            end
                        end
                        indgood=find(indgood);
                        rangeY=rangeY(indgood);
                        rangetau=rangetau(indgood);
                        fitlin=polyfit(rangetau,rangeY,1);
                        app.Dmacro=fitlin(1);
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        plot(app.UIAxes,rangetau,rangeY,'o')
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                        text(app.UIAxes,rangetau(1),0.95*max(rangeY),['D_{macro}=' num2str(round(app.Dmacro,3)) ' \mum^{2}s^{-1}'])
                        title(app.UIAxes,'Dtau Macro vs tau')
                        xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                        ylabel(app.UIAxes,'D_{macro}\tau (\mum^{2})','FontSize',14)
                        delY=max(rangeY)-min(rangeY);
                        app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                        app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                        ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                        xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                        exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_EarlyLinearFitDmacroTau.png'])
                        % 'late linear fit DmacroTau vs tau'
                        rangetau=(app.mintaufit2:app.maxtaufit2)*app.timeFrame;
                        rangeY=app.DtauF(app.mintaufit2:app.maxtaufit2);
                        indgood=zeros(size(rangeY));
                        for i=1:length(rangeY)
                            if isreal(rangeY(i))
                                indgood(i)=1;
                            end
                        end
                        indgood=find(indgood);
                        rangeY=rangeY(indgood);
                        rangetau=rangetau(indgood);
                        fitlin=polyfit(rangetau,rangeY,1);
                        app.Deff=fitlin(1);
                        app.MaxDispMacro=fitlin(2)^0.5;
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        plot(app.UIAxes,rangetau,rangeY,'o')
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                        text(app.UIAxes,rangetau(1),0.85*max(rangeY),['D_{eff}=' num2str(round(app.Deff,3)) ' \mum^{2}s^{-1}'])
                        text(app.UIAxes,rangetau(1),0.95*max(rangeY),['Max. Displacement Macro=' num2str(round(app.MaxDispMacro,2)) ' \mum'])
                        title(app.UIAxes,'Dtau Macro vs tau')
                        xlabel(app.UIAxes,'\tau (s)','FontSize',14)
                        ylabel(app.UIAxes,'D_{macro}\tau (\mum^{2})','FontSize',14)
                        delY=max(rangeY)-min(rangeY);
                        app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                        app.UIAxes.XTick = round([min(rangetau):3*app.timeFrame:max(rangetau)],3);
                        ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                        xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                        exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_LateLinearFitDmacroTau.png'])
                        % 'Anomalous Fit To DmacroTau vs tau'
                        rangetau=(app.mintaufit6:app.maxtaufit6)*app.timeFrame;
                        rangeY=app.DtauF(app.mintaufit6:app.maxtaufit6);
                        indgood=zeros(size(rangeY));
                        for i=1:length(rangeY)
                            if isreal(rangeY(i))
                                indgood(i)=1;
                            end
                        end
                        indgood=find(indgood);
                        rangeY=rangeY(indgood);
                        rangetau=rangetau(indgood);
                        rangetau=log(rangetau);
                        rangeY=log(rangeY);
                        fitlin=polyfit(rangetau,rangeY,1);
                        app.Danomalous=exp(fitlin(2));
                        app.alphaAnomalous=fitlin(1);
                        cla(app.UIAxes)
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');
                        app.UIAxes.XTick = '';
                        app.UIAxes.YTick = '';
                        app.UIAxes.YDir='normal';
                        plot(app.UIAxes,rangetau,rangeY,'o')
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,rangetau,fitlin(1)*rangetau+fitlin(2),'k-')
                        text(app.UIAxes,rangetau(1),1.15*max(rangeY),['D_{anomalous}=' num2str(round(app.Danomalous,3)) ' \mum^{2}s^{-1}'])
                        text(app.UIAxes,rangetau(1),1.25*max(rangeY),['\alpha_{anomalous}=' num2str(round(app.alphaAnomalous,3))])
                        title(app.UIAxes,'Anomalous Fit of log(Dtau Macro) vs log(tau)')
                        xlabel(app.UIAxes,'log\tau (s)','FontSize',14)
                        ylabel(app.UIAxes,'logD_{macro}\tau (\mum^{2})','FontSize',14)
                        delY=max(rangeY)-min(rangeY);
                        app.UIAxes.YTick = round([min(rangeY):delY/10:max(rangeY)],3);
                        app.UIAxes.XTick = round([min(rangetau):log(3*app.timeFrame):max(rangetau)],3);
                        ylim(app.UIAxes,[min(rangeY) max(rangeY)])
                        xlim(app.UIAxes,[min(rangetau) max(rangetau)])
                        exportgraphics(app.UIAxes,[app.path files{f}(1:end-4) '_AnomalousFit.png'])
                    end % fit if 2 components were present...
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Export results
                    pixelSize=app.pixelSize;
                    timeFrame=app.timeFrame;
                    kBin=app.kBin;
                    CF=app.CF;
                    kValues=app.kValues;
                    DtauF=app.DtauF;
                    AmpF=app.AmpF;
                    DtauS=app.DtauS;
                    AmpS=app.AmpS;
                    RatAmp=app.RatAmp;
                    Dmacro=app.Dmacro;
                    Deff=app.Deff;
                    MaxDispMacro=app.MaxDispMacro;
                    Dmicro=app.Dmicro;
                    DmicroLate=app.DmicroLate;
                    MaxDispMicro=app.MaxDispMicro;
                    k2=app.k2;
                    ratConc=app.ratConc;
                    alphaAnomalous=app.alphaAnomalous;
                    Danomalous=app.Danomalous;
                    save([app.path files{f}(1:end-4) '_Output.mat'],'pixelSize','timeFrame','kBin','CF','kValues','DtauF','AmpF','DtauS',"AmpS","RatAmp","Dmacro","Deff","MaxDispMacro","Dmicro","DmicroLate","MaxDispMicro","k2","ratConc","alphaAnomalous","Danomalous")
                    T=table(((1:length(DtauF))*app.timeFrame)',DtauF',AmpF',DtauS',AmpS',RatAmp');
                    T.Properties.VariableNames ={'tau','DtauF','AmpF','DtauS','AmpS','RatAmp'};
                    writetable(T,[app.path files{f}(1:end-4) '_ConfinementResults.xlsx'],'Sheet',['MobAndAmp']);
                    T=table(Dmacro',Deff',MaxDispMacro',Dmicro',DmicroLate',MaxDispMicro',k2',ratConc',alphaAnomalous',Danomalous');
                    T.Properties.VariableNames ={'Dmacro','Deff','MaxDispMacro','Dmicro','DmicroLate','MaxDispMicro','k2','ratConc','alphaAnomalous','Danomalous'};
                    writetable(T,[app.path files{f}(1:end-4) '_ConfinementResults.xlsx'],'Sheet',['ConfParams']);
                
              end % end over all files

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.LoadDataAndDefineSpecsPanel = uipanel(app.UIFigure);
            app.LoadDataAndDefineSpecsPanel.Title = 'Load Data And Define Specs';
            app.FrametimesecondsEditFieldLabel = uilabel(app.LoadDataAndDefineSpecsPanel);
            app.FrametimesecondsEditFieldLabel.Text = 'Frame time (seconds)';
            app.FrametimesecondsEditField = uieditfield(app.LoadDataAndDefineSpecsPanel, 'numeric');
            app.FrametimesecondsEditField.Limits = [0 Inf];
            app.FrametimesecondsEditField.ValueChangedFcn = createCallbackFcn(app, @FrametimesecondsEditFieldValueChanged, true);
            app.FrametimesecondsEditField.Value = 0.5;
            app.PixelSizemicrometerEditFieldLabel = uilabel(app.LoadDataAndDefineSpecsPanel);
            app.PixelSizemicrometerEditFieldLabel.Text = 'Pixel Size (micrometer)';
            app.PixelSizemicrometerEditField = uieditfield(app.LoadDataAndDefineSpecsPanel, 'numeric');
            app.PixelSizemicrometerEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizemicrometerEditFieldValueChanged, true);
            app.PixelSizemicrometerEditField.Value = 0.1;
            app.LBXSliderLabel = uilabel(app.LoadDataAndDefineSpecsPanel);
            app.LBXSliderLabel.Text = 'LB X';
            app.LBXSlider = uislider(app.LoadDataAndDefineSpecsPanel);
            app.LBXSlider.Limits = [1 100];
            app.LBXSlider.ValueChangedFcn = createCallbackFcn(app, @LBXSliderValueChanged, true);
            app.LBXSlider.ValueChangingFcn = createCallbackFcn(app, @LBXSliderValueChanging, true);
            app.LBXSlider.Value = 1;
            app.UBXSliderLabel = uilabel(app.LoadDataAndDefineSpecsPanel);
            app.UBXSliderLabel.Text = 'UB X';
            app.UBXSlider = uislider(app.LoadDataAndDefineSpecsPanel);
            app.UBXSlider.Limits = [1 100];
            app.UBXSlider.ValueChangedFcn = createCallbackFcn(app, @UBXSliderValueChanged, true);
            app.UBXSlider.ValueChangingFcn = createCallbackFcn(app, @UBXSliderValueChanging, true);
            app.UBXSlider.Value = 1;
            app.LBYSliderLabel = uilabel(app.LoadDataAndDefineSpecsPanel);
            app.LBYSliderLabel.Text = 'LB Y';
            app.LBYSlider = uislider(app.LoadDataAndDefineSpecsPanel);
            app.LBYSlider.Limits = [1 100];
            app.LBYSlider.ValueChangedFcn = createCallbackFcn(app, @LBYSliderValueChanged, true);
            app.LBYSlider.ValueChangingFcn = createCallbackFcn(app, @LBYSliderValueChanging, true);
            app.LBYSlider.Value = 1;
            app.UBYSliderLabel = uilabel(app.LoadDataAndDefineSpecsPanel);
            app.UBYSliderLabel.Text = 'UB Y';
            app.UBYSlider = uislider(app.LoadDataAndDefineSpecsPanel);
            app.UBYSlider.Limits = [1 100];
            app.UBYSlider.ValueChangedFcn = createCallbackFcn(app, @UBYSliderValueChanged, true);
            app.UBYSlider.ValueChangingFcn = createCallbackFcn(app, @UBYSliderValueChanging, true);
            app.UBYSlider.Value = 1;
            app.LoadMultitiforOMEdatafileButton = uibutton(app.LoadDataAndDefineSpecsPanel, 'push');
            app.LoadMultitiforOMEdatafileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMultitiforOMEdatafileButtonPushed, true);
            app.LoadMultitiforOMEdatafileButton.Text = 'Load Multi-tif or OME data file';
            app.DisplayDataDropDownLabel = uilabel(app.LoadDataAndDefineSpecsPanel);
            app.DisplayDataDropDownLabel.Text = 'Display Data:';
            app.DisplayDataDropDown = uidropdown(app.LoadDataAndDefineSpecsPanel);
            app.DisplayDataDropDown.Items = {'Raw Data', 'Croped Data', 'Define Shape', 'Mean Padded', 'Immobile Filtered', 'Hann Windowed', 'kICS CF'};
            app.DisplayDataDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayDataDropDownValueChanged, true);
            app.DisplayDataDropDown.Value = 'Raw Data';
            app.TemporallyAlignSeriestoTimePoint0Button_2 = uibutton(app.LoadDataAndDefineSpecsPanel, 'state');
            app.TemporallyAlignSeriestoTimePoint0Button_2.ValueChangedFcn = createCallbackFcn(app, @TemporallyAlignSeriestoTimePoint0Button_2ValueChanged, true);
            app.TemporallyAlignSeriestoTimePoint0Button_2.Text = 'Temporally Align Series to Time Point 0';
            app.maxk2EditFieldLabel = uilabel(app.LoadDataAndDefineSpecsPanel);
            app.maxk2EditFieldLabel.Text = 'max k^2';
            app.maxk2EditField = uieditfield(app.LoadDataAndDefineSpecsPanel, 'numeric');
            app.maxk2EditField.ValueChangedFcn = createCallbackFcn(app, @maxk2EditFieldValueChanged, true);
            app.maxk2EditField.Value = 500;
            app.MaxTauFractionEditFieldLabel = uilabel(app.LoadDataAndDefineSpecsPanel);
            app.MaxTauFractionEditFieldLabel.Text = 'Max Tau Fraction ';
            app.MaxTauFractionEditField = uieditfield(app.LoadDataAndDefineSpecsPanel, 'numeric');
            app.MaxTauFractionEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTauFractionEditFieldValueChanged, true);
            app.MaxTauFractionEditField.Value = 0.2;
            app.FramesSlider = uislider(app.UIFigure);
            app.FramesSlider.Limits = [1 100];
            app.FramesSlider.MajorTicks = [];
            app.FramesSlider.ValueChangedFcn = createCallbackFcn(app, @FramesSliderValueChanged, true);
            app.FramesSlider.ValueChangingFcn = createCallbackFcn(app, @FramesSliderValueChanging, true);
            app.FramesSlider.MinorTicks = [];
            app.FramesSlider.Value = 1;
            app.DataFlteringAndDefineCellAreasPanel = uipanel(app.UIFigure);
            app.DataFlteringAndDefineCellAreasPanel.Title = 'Data Fltering And Define Cell Areas';
            app.MorphologicalOperationsonBinaryDropDown = uidropdown(app.DataFlteringAndDefineCellAreasPanel);
            app.MorphologicalOperationsonBinaryDropDown.Items = {'clean', 'clean + open', 'clean+close'};
            app.MorphologicalOperationsonBinaryDropDown.ValueChangedFcn = createCallbackFcn(app, @MorphologicalOperationsonBinaryDropDownValueChanged, true);
            app.MorphologicalOperationsonBinaryDropDown.Value = 'clean';
            app.MorphologicalOperationsButton = uibutton(app.DataFlteringAndDefineCellAreasPanel, 'push');
            app.MorphologicalOperationsButton.ButtonPushedFcn = createCallbackFcn(app, @MorphologicalOperationsButtonPushed, true);
            app.MorphologicalOperationsButton.Text = ' Morphological Operations';
            app.ExtractCellContourandMeanPadIntensitiesButton = uibutton(app.DataFlteringAndDefineCellAreasPanel, 'push');
            app.ExtractCellContourandMeanPadIntensitiesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractCellContourandMeanPadIntensitiesButtonPushed, true);
            app.ExtractCellContourandMeanPadIntensitiesButton.Text = 'Extract Cell Contour and Mean Pad Intensities';
            app.ThresholdCellShapeSliderLabel = uilabel(app.DataFlteringAndDefineCellAreasPanel);
            app.ThresholdCellShapeSliderLabel.Text = 'Threshold Cell Shape';
            app.ThresholdCellShapeSlider = uislider(app.DataFlteringAndDefineCellAreasPanel);
            app.ThresholdCellShapeSlider.Limits = [0 10];
            app.ThresholdCellShapeSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdCellShapeSliderValueChanged, true);
            app.ThresholdCellShapeSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdCellShapeSliderValueChanging, true);
            app.ThresholdCellShapeSlider.Value = 1;
            app.TemporalImmobileFilteringButton_2 = uibutton(app.DataFlteringAndDefineCellAreasPanel, 'state');
            app.TemporalImmobileFilteringButton_2.ValueChangedFcn = createCallbackFcn(app, @TemporalImmobileFilteringButton_2ValueChanged, true);
            app.TemporalImmobileFilteringButton_2.Text = 'Temporal Immobile Filtering ';
            app.SpatialHannWindowingButton_2 = uibutton(app.DataFlteringAndDefineCellAreasPanel, 'state');
            app.SpatialHannWindowingButton_2.ValueChangedFcn = createCallbackFcn(app, @SpatialHannWindowingButton_2ValueChanged, true);
            app.SpatialHannWindowingButton_2.Text = 'Spatial Hann Windowing';
            app.HannPowerEditFieldLabel = uilabel(app.DataFlteringAndDefineCellAreasPanel);
            app.HannPowerEditFieldLabel.Text = 'Hann Power';
            app.HannPowerEditField = uieditfield(app.DataFlteringAndDefineCellAreasPanel, 'numeric');
            app.HannPowerEditField.ValueChangedFcn = createCallbackFcn(app, @HannPowerEditFieldValueChanged, true);
            app.HannPowerEditField.Value = 0.5;
            app.LBCellAreaSliderLabel = uilabel(app.DataFlteringAndDefineCellAreasPanel);
            app.LBCellAreaSliderLabel.Text = 'LB Cell Area';
            app.LBCellAreaSlider = uislider(app.DataFlteringAndDefineCellAreasPanel);
            app.LBCellAreaSlider.Limits = [0 1];
            app.LBCellAreaSlider.ValueChangedFcn = createCallbackFcn(app, @LBCellAreaSliderValueChanged, true);
            app.LBCellAreaSlider.ValueChangingFcn = createCallbackFcn(app, @LBCellAreaSliderValueChanging, true);
            app.LBCellAreaSlider.Value = 0.1;
            app.AnalyseCellEditFieldLabel = uilabel(app.DataFlteringAndDefineCellAreasPanel);
            app.AnalyseCellEditFieldLabel.Text = 'Analyse Cell #';
            app.AnalyseCellEditField = uieditfield(app.DataFlteringAndDefineCellAreasPanel, 'numeric');
            app.AnalyseCellEditField.Limits = [1 Inf];
            app.AnalyseCellEditField.ValueChangedFcn = createCallbackFcn(app, @AnalyseCellEditFieldValueChanged, true);
            app.AnalyseCellEditField.Value = 1;
            app.ApplykICSAndExploreCorrelationFunctionPanel = uipanel(app.UIFigure);
            app.ApplykICSAndExploreCorrelationFunctionPanel.Title = 'Apply kICS And Explore Correlation Function';
            app.CalculatekICSCFButton = uibutton(app.ApplykICSAndExploreCorrelationFunctionPanel, 'push');
            app.CalculatekICSCFButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatekICSCFButtonPushed, true);
            app.CalculatekICSCFButton.Text = 'Calculate kICS CF';
            app.ChooseCFrepresentationDropDownLabel = uilabel(app.ApplykICSAndExploreCorrelationFunctionPanel);
            app.ChooseCFrepresentationDropDownLabel.Text = 'Choose CF representation';
            app.ChooseCFrepresentationDropDown = uidropdown(app.ApplykICSAndExploreCorrelationFunctionPanel);
            app.ChooseCFrepresentationDropDown.Items = {'2D representation', 'G vs k^2 at given tau', 'G vs tau at given k^2', 'log(G) vs k^2 at given tau'};
            app.ChooseCFrepresentationDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseCFrepresentationDropDownValueChanged, true);
            app.ChooseCFrepresentationDropDown.Value = '2D representation';
            app.mintauSliderLabel = uilabel(app.ApplykICSAndExploreCorrelationFunctionPanel);
            app.mintauSliderLabel.Text = 'min tau  ';
            app.mintauSlider = uislider(app.ApplykICSAndExploreCorrelationFunctionPanel);
            app.mintauSlider.Limits = [1 1000];
            app.mintauSlider.ValueChangedFcn = createCallbackFcn(app, @mintauSliderValueChanged, true);
            app.mintauSlider.ValueChangingFcn = createCallbackFcn(app, @mintauSliderValueChanging, true);
            app.mintauSlider.Value = 1;
            app.maxtauSliderLabel = uilabel(app.ApplykICSAndExploreCorrelationFunctionPanel);
            app.maxtauSliderLabel.Text = 'max tau  ';
            app.maxtauSlider = uislider(app.ApplykICSAndExploreCorrelationFunctionPanel);
            app.maxtauSlider.Limits = [1 1000];
            app.maxtauSlider.ValueChangedFcn = createCallbackFcn(app, @maxtauSliderValueChanged, true);
            app.maxtauSlider.ValueChangingFcn = createCallbackFcn(app, @maxtauSliderValueChanging, true);
            app.maxtauSlider.Value = 10;
            app.mink2SliderLabel = uilabel(app.ApplykICSAndExploreCorrelationFunctionPanel);
            app.mink2SliderLabel.Text = 'min k^2  ';
            app.mink2Slider = uislider(app.ApplykICSAndExploreCorrelationFunctionPanel);
            app.mink2Slider.Limits = [0 1500];
            app.mink2Slider.ValueChangedFcn = createCallbackFcn(app, @mink2SliderValueChanged, true);
            app.mink2Slider.ValueChangingFcn = createCallbackFcn(app, @mink2SliderValueChanging, true);
            app.maxk2SliderLabel = uilabel(app.ApplykICSAndExploreCorrelationFunctionPanel);
            app.maxk2SliderLabel.Text = 'max k^2  ';
            app.maxk2Slider = uislider(app.ApplykICSAndExploreCorrelationFunctionPanel);
            app.maxk2Slider.Limits = [0 1500];
            app.maxk2Slider.ValueChangedFcn = createCallbackFcn(app, @maxk2SliderValueChanged, true);
            app.maxk2Slider.ValueChangingFcn = createCallbackFcn(app, @maxk2SliderValueChanging, true);
            app.minGktauSliderLabel = uilabel(app.ApplykICSAndExploreCorrelationFunctionPanel);
            app.minGktauSliderLabel.Text = 'min G(k,tau)  ';
            app.minGktauSlider = uislider(app.ApplykICSAndExploreCorrelationFunctionPanel);
            app.minGktauSlider.Limits = [0 1];
            app.minGktauSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.minGktauSlider.ValueChangedFcn = createCallbackFcn(app, @minGktauSliderValueChanged, true);
            app.minGktauSlider.ValueChangingFcn = createCallbackFcn(app, @minGktauSliderValueChanging, true);
            app.maxGktauSliderLabel = uilabel(app.ApplykICSAndExploreCorrelationFunctionPanel);
            app.maxGktauSliderLabel.Text = 'max G(k,tau)  ';
            app.maxGktauSlider = uislider(app.ApplykICSAndExploreCorrelationFunctionPanel);
            app.maxGktauSlider.Limits = [0 1];
            app.maxGktauSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.maxGktauSlider.ValueChangedFcn = createCallbackFcn(app, @maxGktauSliderValueChanged, true);
            app.maxGktauSlider.ValueChangingFcn = createCallbackFcn(app, @maxGktauSliderValueChanging, true);
            app.maxGktauSlider.Value = 1;
            app.PlotCFButton = uibutton(app.ApplykICSAndExploreCorrelationFunctionPanel, 'push');
            app.PlotCFButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCFButtonPushed, true);
            app.PlotCFButton.Text = 'Plot CF';
            app.SmoothCFAlongk2Button = uibutton(app.ApplykICSAndExploreCorrelationFunctionPanel, 'state');
            app.SmoothCFAlongk2Button.ValueChangedFcn = createCallbackFcn(app, @SmoothCFAlongk2ButtonValueChanged, true);
            app.SmoothCFAlongk2Button.Text = 'Smooth CF Along k^2';
            app.MovingAverageSmoothingWindowEditFieldLabel = uilabel(app.ApplykICSAndExploreCorrelationFunctionPanel);
            app.MovingAverageSmoothingWindowEditFieldLabel.Text = 'Moving Average Smoothing Window';
            app.MovingAverageSmoothingWindowEditField = uieditfield(app.ApplykICSAndExploreCorrelationFunctionPanel, 'numeric');
            app.MovingAverageSmoothingWindowEditField.ValueChangedFcn = createCallbackFcn(app, @MovingAverageSmoothingWindowEditFieldValueChanged, true);
            app.MovingAverageSmoothingWindowEditField.Value = 10;
            app.kICSCFfittingToExtractTimeDependentMobilitiesandFractionsPanel = uipanel(app.UIFigure);
            app.kICSCFfittingToExtractTimeDependentMobilitiesandFractionsPanel.Title = 'kICS CF fitting To Extract Time Dependent Mobilities and Fractions';
            app.ChooseCFFittingOptionsDropDownLabel = uilabel(app.kICSCFfittingToExtractTimeDependentMobilitiesandFractionsPanel);
            app.ChooseCFFittingOptionsDropDownLabel.Text = 'Choose CF Fitting Options';
            app.ChooseCFFittingOptionsDropDown = uidropdown(app.kICSCFfittingToExtractTimeDependentMobilitiesandFractionsPanel);
            app.ChooseCFFittingOptionsDropDown.Items = {'Linear Fit 1 Component', 'Linear Fit 2 Components', 'Non-Linear Fit 1 Component', 'Non-Linear Fit 2 Components'};
            app.ChooseCFFittingOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseCFFittingOptionsDropDownValueChanged, true);
            app.ChooseCFFittingOptionsDropDown.Value = 'Linear Fit 1 Component';
            app.Mink2FitSliderLabel = uilabel(app.kICSCFfittingToExtractTimeDependentMobilitiesandFractionsPanel);
            app.Mink2FitSliderLabel.Text = 'Min k^2 Fit';
            app.Mink2FitSlider = uislider(app.kICSCFfittingToExtractTimeDependentMobilitiesandFractionsPanel);
            app.Mink2FitSlider.Limits = [1 10];
            app.Mink2FitSlider.ValueChangedFcn = createCallbackFcn(app, @Mink2FitSliderValueChanged, true);
            app.Mink2FitSlider.ValueChangingFcn = createCallbackFcn(app, @Mink2FitSliderValueChanging, true);
            app.Mink2FitSlider.Value = 1;
            app.Maxk2FitSliderLabel = uilabel(app.kICSCFfittingToExtractTimeDependentMobilitiesandFractionsPanel);
            app.Maxk2FitSliderLabel.Text = 'Max k^2 Fit';
            app.Maxk2FitSlider = uislider(app.kICSCFfittingToExtractTimeDependentMobilitiesandFractionsPanel);
            app.Maxk2FitSlider.Limits = [1 10];
            app.Maxk2FitSlider.ValueChangedFcn = createCallbackFcn(app, @Maxk2FitSliderValueChanged, true);
            app.Maxk2FitSlider.ValueChangingFcn = createCallbackFcn(app, @Maxk2FitSliderValueChanging, true);
            app.Maxk2FitSlider.Value = 10;
            app.InflectionPointk2fit0ifsingleComponentFitEditFieldLabel = uilabel(app.kICSCFfittingToExtractTimeDependentMobilitiesandFractionsPanel);
            app.InflectionPointk2fit0ifsingleComponentFitEditFieldLabel.Text = 'Inflection Point k^2 fit (0 if single Component Fit)';
            app.InflectionPointk2fit0ifsingleComponentFitEditField = uieditfield(app.kICSCFfittingToExtractTimeDependentMobilitiesandFractionsPanel, 'numeric');
            app.InflectionPointk2fit0ifsingleComponentFitEditField.Limits = [0 Inf];
            app.InflectionPointk2fit0ifsingleComponentFitEditField.ValueChangedFcn = createCallbackFcn(app, @InflectionPointk2fit0ifsingleComponentFitEditFieldValueChanged, true);
            app.InflectionPointk2fit0ifsingleComponentFitEditField.Value = 10;
            app.FitPreviewButton = uibutton(app.kICSCFfittingToExtractTimeDependentMobilitiesandFractionsPanel, 'push');
            app.FitPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @FitPreviewButtonPushed, true);
            app.FitPreviewButton.Text = 'Fit Preview';
            app.FitAllTauButton = uibutton(app.kICSCFfittingToExtractTimeDependentMobilitiesandFractionsPanel, 'push');
            app.FitAllTauButton.ButtonPushedFcn = createCallbackFcn(app, @FitAllTauButtonPushed, true);
            app.FitAllTauButton.Text = 'Fit All Tau';
            app.PreviewFitEveryNthTauEditFieldLabel = uilabel(app.kICSCFfittingToExtractTimeDependentMobilitiesandFractionsPanel);
            app.PreviewFitEveryNthTauEditFieldLabel.Text = 'Preview Fit Every Nth Tau';
            app.PreviewFitEveryNthTauEditField = uieditfield(app.kICSCFfittingToExtractTimeDependentMobilitiesandFractionsPanel, 'numeric');
            app.PreviewFitEveryNthTauEditField.ValueChangedFcn = createCallbackFcn(app, @PreviewFitEveryNthTauEditFieldValueChanged, true);
            app.PreviewFitEveryNthTauEditField.Value = 20;
            app.FitMobilitesandFractionsToObtainConfinementParametersPanel = uipanel(app.UIFigure);
            app.FitMobilitesandFractionsToObtainConfinementParametersPanel.Title = 'Fit Mobilites and Fractions To Obtain Confinement Parameters';
            app.ChoosewhichtrendtoshowLabel = uilabel(app.FitMobilitesandFractionsToObtainConfinementParametersPanel);
            app.ChoosewhichtrendtoshowLabel.Text = 'Choose which trend to show:';
            app.ChoosewhichtrendtoshowDropDown = uidropdown(app.FitMobilitesandFractionsToObtainConfinementParametersPanel);
            app.ChoosewhichtrendtoshowDropDown.Items = {'Ratio Amplitude Macro to Micro Component vs. tau', 'Dtau Macro vs tau', 'Dtau Micro vs tau'};
            app.ChoosewhichtrendtoshowDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosewhichtrendtoshowDropDownValueChanged, true);
            app.ChoosewhichtrendtoshowDropDown.Value = 'Ratio Amplitude Macro to Micro Component vs. tau';
            app.mintaufitSliderLabel = uilabel(app.FitMobilitesandFractionsToObtainConfinementParametersPanel);
            app.mintaufitSliderLabel.Text = 'min tau fit ';
            app.mintaufitSlider = uislider(app.FitMobilitesandFractionsToObtainConfinementParametersPanel);
            app.mintaufitSlider.Limits = [1 10];
            app.mintaufitSlider.ValueChangedFcn = createCallbackFcn(app, @mintaufitSliderValueChanged, true);
            app.mintaufitSlider.ValueChangingFcn = createCallbackFcn(app, @mintaufitSliderValueChanging, true);
            app.mintaufitSlider.Value = 1;
            app.maxtaufitSliderLabel = uilabel(app.FitMobilitesandFractionsToObtainConfinementParametersPanel);
            app.maxtaufitSliderLabel.Text = 'max tau fit  ';
            app.maxtaufitSlider = uislider(app.FitMobilitesandFractionsToObtainConfinementParametersPanel);
            app.maxtaufitSlider.Limits = [1 10];
            app.maxtaufitSlider.ValueChangedFcn = createCallbackFcn(app, @maxtaufitSliderValueChanged, true);
            app.maxtaufitSlider.ValueChangingFcn = createCallbackFcn(app, @maxtaufitSliderValueChanging, true);
            app.maxtaufitSlider.Value = 10;
            app.ChoosetrendfittingoptionDropDownLabel = uilabel(app.FitMobilitesandFractionsToObtainConfinementParametersPanel);
            app.ChoosetrendfittingoptionDropDownLabel.Text = 'Choose trend fitting option: ';
            app.ChoosetrendfittingoptionDropDown = uidropdown(app.FitMobilitesandFractionsToObtainConfinementParametersPanel);
            app.ChoosetrendfittingoptionDropDown.Items = {'early linear fit DmacroTau vs tau', 'late linear fit DmacroTau vs tau', 'early linear fit DmicroTau vs tau', 'late linear fit DmicroTau vs tau', 'late linear fit AmpMacro/AmpMicro vs tau', 'Anomalous Fit To DmacroTau vs tau'};
            app.ChoosetrendfittingoptionDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosetrendfittingoptionDropDownValueChanged, true);
            app.ChoosetrendfittingoptionDropDown.Value = 'early linear fit DmacroTau vs tau';
            app.ApplyAnalysisToEachCellAndExportResultsButton = uibutton(app.FitMobilitesandFractionsToObtainConfinementParametersPanel, 'push');
            app.ApplyAnalysisToEachCellAndExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyAnalysisToEachCellAndExportResultsButtonPushed, true);
            app.ApplyAnalysisToEachCellAndExportResultsButton.Text = 'Apply Analysis To Each Cell And Export Results';
            app.FitButton_2 = uibutton(app.FitMobilitesandFractionsToObtainConfinementParametersPanel, 'push');
            app.FitButton_2.ButtonPushedFcn = createCallbackFcn(app, @FitButton_2Pushed, true);
            app.FitButton_2.Text = 'Fit!';
            app.BatchSeveralFilesUsingSameSettingsButton = uibutton(app.FitMobilitesandFractionsToObtainConfinementParametersPanel, 'push');
            app.BatchSeveralFilesUsingSameSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @BatchSeveralFilesUsingSameSettingsButtonPushed, true);
            app.BatchSeveralFilesUsingSameSettingsButton.Text = 'Batch Several Files Using Same Settings';
            app.SaveAnalysisParametersFileButton = uibutton(app.FitMobilitesandFractionsToObtainConfinementParametersPanel, 'push');
            app.SaveAnalysisParametersFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisParametersFileButtonPushed, true);
            app.SaveAnalysisParametersFileButton.Text = 'Save Analysis Parameters File';
            app.LoadAnalysisParametersFileButton = uibutton(app.FitMobilitesandFractionsToObtainConfinementParametersPanel, 'push');
            app.LoadAnalysisParametersFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAnalysisParametersFileButtonPushed, true);
            app.LoadAnalysisParametersFileButton.Text = 'Load Analysis Parameters File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kICSConfinementGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end